classdef T2mapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        T2mappUIFigure                matlab.ui.Figure
        Hyperlink                     matlab.ui.control.Hyperlink
        TabGroup4                     matlab.ui.container.TabGroup
        SequenceParametersTab         matlab.ui.container.Tab
        SLTViewField                  matlab.ui.control.NumericEditField
        SLTViewFieldLabel             matlab.ui.control.Label
        SlicesViewField               matlab.ui.control.NumericEditField
        SlicesViewFieldLabel          matlab.ui.control.Label
        DimyViewField                 matlab.ui.control.NumericEditField
        DimyViewFieldLabel            matlab.ui.control.Label
        DimxViewField                 matlab.ui.control.NumericEditField
        DimxViewFieldLabel            matlab.ui.control.Label
        FOVViewField                  matlab.ui.control.NumericEditField
        FOVViewFieldLabel             matlab.ui.control.Label
        AspectRatioViewField          matlab.ui.control.NumericEditField
        AspectRatioViewFieldLabel     matlab.ui.control.Label
        DynamicsViewField             matlab.ui.control.NumericEditField
        DynamicsViewFieldLabel        matlab.ui.control.Label
        EchoesViewField               matlab.ui.control.NumericEditField
        EchoesViewFieldLabel          matlab.ui.control.Label
        ESPEditField                  matlab.ui.control.NumericEditField
        ESPEditFieldLabel             matlab.ui.control.Label
        TE0EditField                  matlab.ui.control.NumericEditField
        TE0EditFieldLabel             matlab.ui.control.Label
        TRViewField                   matlab.ui.control.NumericEditField
        TRViewFieldLabel              matlab.ui.control.Label
        DataFileViewField             matlab.ui.control.EditField
        DataFileViewFieldLabel        matlab.ui.control.Label
        TabGroup2                     matlab.ui.container.TabGroup
        RawImagesTab                  matlab.ui.container.Tab
        LevelEditField                matlab.ui.control.NumericEditField
        LevelEditFieldLabel           matlab.ui.control.Label
        WindowEditField               matlab.ui.control.NumericEditField
        WindowEditFieldLabel          matlab.ui.control.Label
        AutoLWButton                  matlab.ui.control.StateButton
        LevelSlider                   matlab.ui.control.Slider
        WindowSlider                  matlab.ui.control.Slider
        RawImage                      matlab.ui.control.Image
        RawImageSelectionPanel        matlab.ui.container.Panel
        DynamicsEditFieldLabel        matlab.ui.control.Label
        EchoesEditFieldLabel          matlab.ui.control.Label
        SlicesEditFieldLabel          matlab.ui.control.Label
        DynamicsSlider                matlab.ui.control.Slider
        EchoesSlider                  matlab.ui.control.Slider
        SlicesSlider                  matlab.ui.control.Slider
        DynamicsEditField             matlab.ui.control.NumericEditField
        EchoesEditField               matlab.ui.control.NumericEditField
        SlicesEditField               matlab.ui.control.NumericEditField
        RawImageLabel                 matlab.ui.control.Label
        RawImageMaskSettingsPanel     matlab.ui.container.Panel
        ThresholdEditField            matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel       matlab.ui.control.Label
        OpenButton                    matlab.ui.control.Button
        CloseButton                   matlab.ui.control.Button
        DilateButton                  matlab.ui.control.Button
        ErodeButton                   matlab.ui.control.Button
        ThresholdSlider               matlab.ui.control.Slider
        RawDataFig                    matlab.ui.control.UIAxes
        RecoParametersTab             matlab.ui.container.Tab
        CSregularizationPanel         matlab.ui.container.Panel
        TVdynEditFieldLabel           matlab.ui.control.Label
        TVteEditFieldLabel            matlab.ui.control.Label
        LLRxyzEditFieldLabel          matlab.ui.control.Label
        TVxyzEditFieldLabel           matlab.ui.control.Label
        WVxyzEditFieldLabel           matlab.ui.control.Label
        TVdynEditField                matlab.ui.control.NumericEditField
        TVteEditField                 matlab.ui.control.NumericEditField
        LLRxyzEditField               matlab.ui.control.NumericEditField
        TVxyzEditField                matlab.ui.control.NumericEditField
        WVxyzEditField                matlab.ui.control.NumericEditField
        FilteringPanel                matlab.ui.container.Panel
        DeRingCheckBox                matlab.ui.control.CheckBox
        DeRingCheckBoxLabel           matlab.ui.control.Label
        DeNoiseWindowEditField        matlab.ui.control.NumericEditField
        DeNoiseCheckBox               matlab.ui.control.CheckBox
        DeNoiseCheckBoxLabel          matlab.ui.control.Label
        FitparametersTab              matlab.ui.container.Tab
        DynamicsSelectionPanel        matlab.ui.container.Panel
        LastDynamicEditField          matlab.ui.control.NumericEditField
        LastDynamicEditFieldLabel     matlab.ui.control.Label
        FirstDynamicEditField         matlab.ui.control.NumericEditField
        FirstDynamicEditFieldLabel    matlab.ui.control.Label
        GoodnessOfFitPanel            matlab.ui.container.Panel
        RsquareEditField              matlab.ui.control.NumericEditField
        RsquareEditFieldLabel         matlab.ui.control.Label
        SliceSelectionPanel           matlab.ui.container.Panel
        LastSliceEditField            matlab.ui.control.NumericEditField
        LastSliceEditFieldLabel       matlab.ui.control.Label
        FirstSliceEditField           matlab.ui.control.NumericEditField
        FirstSliceEditFieldLabel      matlab.ui.control.Label
        EchoSelectionPanel            matlab.ui.container.Panel
        CheckBox_64                   matlab.ui.control.CheckBox
        CheckBox_63                   matlab.ui.control.CheckBox
        CheckBox_62                   matlab.ui.control.CheckBox
        CheckBox_61                   matlab.ui.control.CheckBox
        CheckBox_60                   matlab.ui.control.CheckBox
        CheckBox_59                   matlab.ui.control.CheckBox
        CheckBox_58                   matlab.ui.control.CheckBox
        CheckBox_57                   matlab.ui.control.CheckBox
        CheckBox_56                   matlab.ui.control.CheckBox
        CheckBox_55                   matlab.ui.control.CheckBox
        CheckBox_54                   matlab.ui.control.CheckBox
        CheckBox_53                   matlab.ui.control.CheckBox
        CheckBox_52                   matlab.ui.control.CheckBox
        CheckBox_51                   matlab.ui.control.CheckBox
        CheckBox_50                   matlab.ui.control.CheckBox
        CheckBox_49                   matlab.ui.control.CheckBox
        CheckBox_48                   matlab.ui.control.CheckBox
        CheckBox_47                   matlab.ui.control.CheckBox
        CheckBox_46                   matlab.ui.control.CheckBox
        CheckBox_45                   matlab.ui.control.CheckBox
        CheckBox_44                   matlab.ui.control.CheckBox
        CheckBox_43                   matlab.ui.control.CheckBox
        CheckBox_42                   matlab.ui.control.CheckBox
        CheckBox_41                   matlab.ui.control.CheckBox
        CheckBox_40                   matlab.ui.control.CheckBox
        CheckBox_39                   matlab.ui.control.CheckBox
        CheckBox_38                   matlab.ui.control.CheckBox
        CheckBox_37                   matlab.ui.control.CheckBox
        CheckBox_36                   matlab.ui.control.CheckBox
        CheckBox_35                   matlab.ui.control.CheckBox
        CheckBox_34                   matlab.ui.control.CheckBox
        CheckBox_33                   matlab.ui.control.CheckBox
        CheckBox_32                   matlab.ui.control.CheckBox
        CheckBox_31                   matlab.ui.control.CheckBox
        CheckBox_30                   matlab.ui.control.CheckBox
        CheckBox_29                   matlab.ui.control.CheckBox
        CheckBox_28                   matlab.ui.control.CheckBox
        CheckBox_27                   matlab.ui.control.CheckBox
        CheckBox_26                   matlab.ui.control.CheckBox
        CheckBox_25                   matlab.ui.control.CheckBox
        CheckBox_24                   matlab.ui.control.CheckBox
        CheckBox_23                   matlab.ui.control.CheckBox
        CheckBox_22                   matlab.ui.control.CheckBox
        CheckBox_21                   matlab.ui.control.CheckBox
        CheckBox_20                   matlab.ui.control.CheckBox
        CheckBox_19                   matlab.ui.control.CheckBox
        CheckBox_18                   matlab.ui.control.CheckBox
        CheckBox_17                   matlab.ui.control.CheckBox
        CheckBox_16                   matlab.ui.control.CheckBox
        CheckBox_15                   matlab.ui.control.CheckBox
        CheckBox_14                   matlab.ui.control.CheckBox
        CheckBox_13                   matlab.ui.control.CheckBox
        CheckBox_12                   matlab.ui.control.CheckBox
        CheckBox_11                   matlab.ui.control.CheckBox
        CheckBox_10                   matlab.ui.control.CheckBox
        CheckBox_9                    matlab.ui.control.CheckBox
        CheckBox_8                    matlab.ui.control.CheckBox
        CheckBox_7                    matlab.ui.control.CheckBox
        CheckBox_6                    matlab.ui.control.CheckBox
        CheckBox_5                    matlab.ui.control.CheckBox
        CheckBox_4                    matlab.ui.control.CheckBox
        CheckBox_3                    matlab.ui.control.CheckBox
        CheckBox_2                    matlab.ui.control.CheckBox
        CheckBox_1                    matlab.ui.control.CheckBox
        AppSettingsTab                matlab.ui.container.Tab
        ColorsPanel                   matlab.ui.container.Panel
        ColorsButtonGroup             matlab.ui.container.ButtonGroup
        BlackWhiteButton              matlab.ui.control.RadioButton
        ClassicButton                 matlab.ui.control.RadioButton
        SoundsPanel                   matlab.ui.container.Panel
        AutoSoundsCheckBoxLabel       matlab.ui.control.Label
        AutoSoundsCheckBox            matlab.ui.control.CheckBox
        TabGroup3                     matlab.ui.container.TabGroup
        T2mapTab                      matlab.ui.container.Tab
        LinearLabel                   matlab.ui.control.Label
        LogLabel                      matlab.ui.control.Label
        T2BrushEditField              matlab.ui.control.NumericEditField
        T2BrushEditFieldLabel         matlab.ui.control.Label
        T2ExportRoiDataButton         matlab.ui.control.Button
        T2ROIViewFieldLabel           matlab.ui.control.Label
        T2PixelValueLabel             matlab.ui.control.Label
        T2ROIViewField                matlab.ui.control.EditField
        T2ViewFieldLabel              matlab.ui.control.Label
        T2ViewField                   matlab.ui.control.EditField
        T2ROIEraseButton              matlab.ui.control.Button
        T2ROIbutton                   matlab.ui.control.Button
        T2YEditField                  matlab.ui.control.NumericEditField
        T2YEditFieldLabel             matlab.ui.control.Label
        T2XEditField                  matlab.ui.control.NumericEditField
        T2XEditFieldLabel             matlab.ui.control.Label
        T2Image                       matlab.ui.control.Image
        T2ColormapButtonGroup         matlab.ui.container.ButtonGroup
        T2ScaleAutoButton             matlab.ui.control.Button
        T2ScaleMinEditField           matlab.ui.control.NumericEditField
        T2ScaleMinLabel               matlab.ui.control.Label
        T2ScaleEditField              matlab.ui.control.NumericEditField
        T2ScaleMaxLabel               matlab.ui.control.Label
        naviaButton1                  matlab.ui.control.RadioButton
        parulaButton1                 matlab.ui.control.RadioButton
        winterButton1                 matlab.ui.control.RadioButton
        autumnButton1                 matlab.ui.control.RadioButton
        grayButton1                   matlab.ui.control.RadioButton
        T2ImageSelectionPanel         matlab.ui.container.Panel
        T2MapDynamicsEditFieldLabel   matlab.ui.control.Label
        T2MapSlicesEditFieldLabel     matlab.ui.control.Label
        T2MapDynamicsSlider           matlab.ui.control.Slider
        T2MapDynamicsEditField        matlab.ui.control.NumericEditField
        T2MapSlicesSlider             matlab.ui.control.Slider
        T2MapSlicesEditField          matlab.ui.control.NumericEditField
        T2MapLabel                    matlab.ui.control.Label
        T2linearPlot                  matlab.ui.control.UIAxes
        T2logPlot                     matlab.ui.control.UIAxes
        T2MapFig                      matlab.ui.control.UIAxes
        M0R2Tab                       matlab.ui.container.Tab
        M0BrushEditField              matlab.ui.control.NumericEditField
        M0BrushEditFieldLabel         matlab.ui.control.Label
        M0ROIEraseButton              matlab.ui.control.Button
        M0ROIButton                   matlab.ui.control.Button
        R2Image                       matlab.ui.control.Image
        M0Image                       matlab.ui.control.Image
        R2ColorMapButtonGroup         matlab.ui.container.ButtonGroup
        naviaButton3                  matlab.ui.control.RadioButton
        parulaButton3                 matlab.ui.control.RadioButton
        winterButton3                 matlab.ui.control.RadioButton
        autumnButton3                 matlab.ui.control.RadioButton
        grayButton3                   matlab.ui.control.RadioButton
        M0ColorMapButtonGroup         matlab.ui.container.ButtonGroup
        naviaButton2                  matlab.ui.control.RadioButton
        parulaButton2                 matlab.ui.control.RadioButton
        winterButton2                 matlab.ui.control.RadioButton
        autumnButton2                 matlab.ui.control.RadioButton
        grayButton2                   matlab.ui.control.RadioButton
        M0ImageSelectionPanel         matlab.ui.container.Panel
        M0MapDynamicsEditFieldLabel   matlab.ui.control.Label
        M0MapSlicesEditFieldLabel     matlab.ui.control.Label
        M0MapDynamicsSlider           matlab.ui.control.Slider
        M0MapDynamicsEditField        matlab.ui.control.NumericEditField
        M0MapSlicesSlider             matlab.ui.control.Slider
        M0MapSlicesEditField          matlab.ui.control.NumericEditField
        R2MapLabel                    matlab.ui.control.Label
        M0MapLabel                    matlab.ui.control.Label
        R2MapFig                      matlab.ui.control.UIAxes
        M0MapFig                      matlab.ui.control.UIAxes
        WaterFatTab                   matlab.ui.container.Tab
        WaterImage                    matlab.ui.control.Image
        FatImage                      matlab.ui.control.Image
        FatColorMapButtonGroup        matlab.ui.container.ButtonGroup
        naviaButton5                  matlab.ui.control.RadioButton
        parulaButton5                 matlab.ui.control.RadioButton
        winterButton5                 matlab.ui.control.RadioButton
        autumnButton5                 matlab.ui.control.RadioButton
        grayButton5                   matlab.ui.control.RadioButton
        WaterColorMapButtonGroup      matlab.ui.container.ButtonGroup
        naviaButton4                  matlab.ui.control.RadioButton
        parulaButton4                 matlab.ui.control.RadioButton
        winterButton4                 matlab.ui.control.RadioButton
        autumnButton4                 matlab.ui.control.RadioButton
        grayButton4                   matlab.ui.control.RadioButton
        WaterFatImageSelectionPanel   matlab.ui.container.Panel
        WaterFatMapDynamicsEditFieldLabel  matlab.ui.control.Label
        WaterFatMapSlicesEditFieldLabel  matlab.ui.control.Label
        WaterFatMapDynamicsSlider     matlab.ui.control.Slider
        WaterFatMapDynamicsEditField  matlab.ui.control.NumericEditField
        WaterFatMapSlicesSlider       matlab.ui.control.Slider
        WaterFatMapSlicesEditField    matlab.ui.control.NumericEditField
        FatMapLabel                   matlab.ui.control.Label
        WaterMapLabel                 matlab.ui.control.Label
        FatMapFig                     matlab.ui.control.UIAxes
        WaterMapFig                   matlab.ui.control.UIAxes
        TabGroup1                     matlab.ui.container.TabGroup
        DashboardTab                  matlab.ui.container.Tab
        CSrecoCheckBox                matlab.ui.control.CheckBox
        CSrecoCheckBoxLabel           matlab.ui.control.Label
        MobaCheckBox                  matlab.ui.control.CheckBox
        MobaCheckBoxLabel             matlab.ui.control.Label
        WaterFatCheckBox              matlab.ui.control.CheckBox
        WaterFatCheckBoxLabel         matlab.ui.control.Label
        EstimatedRegTimeViewField     matlab.ui.control.EditField
        RegistrationDropDown          matlab.ui.control.DropDown
        RegAbortButton                matlab.ui.control.Button
        RegProgressGauge              matlab.ui.control.LinearGauge
        RegProgressLabel              matlab.ui.control.Label
        EstimatedFitTimeViewField     matlab.ui.control.EditField
        FitAbortButton                matlab.ui.control.Button
        FitProgressGauge              matlab.ui.control.LinearGauge
        FitProgressLabel              matlab.ui.control.Label
        Lamp7                         matlab.ui.control.Lamp
        Lamp6                         matlab.ui.control.Lamp
        Lamp5                         matlab.ui.control.Lamp
        Lamp4                         matlab.ui.control.Lamp
        Lamp3                         matlab.ui.control.Lamp
        Lamp2                         matlab.ui.control.Lamp
        Lamp1                         matlab.ui.control.Lamp
        ExitButton                    matlab.ui.control.Button
        GIFButton                     matlab.ui.control.Button
        DCMNIFTIButton                matlab.ui.control.Button
        FitT2Button                   matlab.ui.control.Button
        MaskButton                    matlab.ui.control.Button
        RegisterButton                matlab.ui.control.Button
        RecoimagesButton              matlab.ui.control.Button
        LoadMRDfileButton             matlab.ui.control.Button
        MRSImage                      matlab.ui.control.Image
        StatusLabel                   matlab.ui.control.Label
        StatusImage                   matlab.ui.control.Image
        BackgroundBox                 matlab.ui.control.TextArea
        MessageBox                    matlab.ui.control.TextArea
        TulpLogo                      matlab.ui.control.Image
        CreditsLabel                  matlab.ui.control.Label
        Tsub2subsupsupmappingLabel    matlab.ui.control.Label
    end


    % --------------------------------------------------------------------------------------
    %
    %   T2 and T2* mapping tool
    %
    %   Gustav Strijkers
    %   Amsterdam UMC
    %   Sept 10, 2026
    %
    %
    %
    %   Version 1.0; aug 2019
    %   - intial version
    %
    %   Version 1.1
    %   - Bug fixes
    %   - Added image registration option
    %
    %   Version 1.2
    %   - Fitting speed dramatically increased
    %
    %   Version 2.0; nov 2020
    %   - updated version
    %
    %   Version 2.1; nov 2020
    %   - T2* mapping of multi-gradient echo sequences
    %
    %   Version 2.2; nov 2020
    %   - Corrected a bug in image dimensions and non-square aspect ratio
    %
    %   Version 2.3; May 2021
    %   - Multi-coil data
    %
    %   Version 2.4; July 2021
    %   - ROI and cross-hair T2 values
    %
    %   Version 2.6; Jan 2022
    %   - NIFTI ROI import
    %   - Various bug fixes
    %
    %   Version 3.0
    %   - Model-based reconstruction
    %   - Bug fix in dicom export
    %   - Various bug fixes
    %   - b-type scanner MGE import
    %   - Added dynamics
    %   - 3D T2*
    %   - DICOM export bug fix
    %
    %   Version 3.2
    %   - Able to handle data reconstructed with the P2ROUD app
    %   - Tested some b-type scanner data
    %   - DICOM export, scaled output x 100 to prevent discretization of low T2(*) values
    %   - bug fixes with DICOM export
    %   - improved GIF, DICOM export
    %
    %   Version 3.3
    %   - Model based fitting on 2D registered images
    %   - ROI drawing in M0 images
    %   - Circular ROI drawing brush
    %   - Elastix image registration
    %
    %   Version 3.5
    %   - Unring, PCA denoising
    %   - Many bug fixes
    %
    %   Version 3.6
    %   - Added compressed sensing reco parametersd
    %
    %   Version 3.7
    %   - Water fat separation for multi-echo gradient echo
    %   - Updated tooltips
    %   - Mask bug fix
    %
    %   Version 3.9
    %   - Improved version control
    %   - Cleanup some variable names
    %   - GPU bart, if available
    %   - Registration with Bart
    %
    %   Version 4.0
    %   - Some bug fixes, Matlab 2025a version
    %   - Navia color map
    %
    %
    %
    %
    %
    % --------------------------------------------------------------------------------------

    %#ok<*ST2NM>


    properties (Access = public)

        % App version
        appVersion = "4.1.0"        % App version
        bartVersion                 % Bart version

        % File variables
        appFolder                   % Application folder
        oldFolder                   % Starting folder
        oldWarningState             % warning state MATLAB had when the app started
        busyFlag = false            % a step is running: status BUSY until OK or ERROR
        closeRequested = false      % the window was closed while a step was running
        closeTimer                  % timer that closes the app once that step has finished
        mrdImportFolder             % Dicom import folder
        dicomExportFolder           % Dicom export folder
        exportFolder                % Dicom/Nifti export folder
        gifExportFolder             % gif export folder
        roiExportFolder             % roi data export folder
        td                          % the dataset, a t2Data object
        jsonPar = struct()          % settings, from t2mapp.io.readSettingsFile
        logFile = -1                % file id of this session's log, -1 when there is none
        logFileName = ''            % full path of this session's log

        % Flags
        GPUpresentFlag = false      % GPU present true/false
        validDataFlag               % valid data file true/false
        validRecoFlag               % valid reco true/false
        validRegFlag                % successful registration true/false
        validFitFlag                % valid fit true/false
        mrdFlag                     % standard mrd import folder already defined true or false
        bartDetectedFlag            % bart toolbox available true/false
        elastixDetectedFlag         % elastix available true/false
        abortFitFlag                % abort fit false or true
        abortRegFlag                % abort registration false or true
        roiFlag                     % roi drawing true or false

        % Scan properties

        % Images & plots
        txt1                        % legend label
        txt2                        % legend label
        crossT2                     % cross-hair
        crossM0                     % cross-hair
        crossWF                     % cross-hair
        roiPoints                   % roi point coordinates
        roiT2                       % roi drawing object in T2 map
        roiM0                       % roi drawing object in M0 image
        roiWF                       % roi drawing object in water image
        structuringElement2D        % 2D structuring element
        structuringElement3D        % 3D structuring element

        % Fitting
        t2cmap                      % T2 color mapping
        m0cmap                      % M0 color mapping
        r2cmap                      % R2 color mapping
        watercmap                   % Water color map
        fatcmap                     % Fat color map

        % Colors
        brightred                   % red
        red                         % red
        brightgreen                 % green
        green                       % green
        yellow                      % yellow
        orange                      % orange
        blue                        % blue
        lightblue                   % lightblue
        grey                        % grey
        guiBlack                    % app black
        guiGrey                     % app grey
        guiMiddleGrey               % app middle grey
        guiDarkGrey                 % app dark grey
        guiWhite                    % app white
        guiBlackBlue                % app black/blueish

        % Sounds
        warningSound                % warning sound
        errorSound                  % error sound

    end


    methods (Access = private)


        %------------------------------------------------
        %
        %  GUI layout
        %
        %------------------------------------------------


        % Traffic lights
        function SetLights(app,code)

            if code(1) == 0 app.Lamp1.Color = app.red; end %#ok<*SEPEX>
            if code(2) == 0 app.Lamp2.Color = app.red; end
            if code(3) == 0 app.Lamp3.Color = app.red; end
            if code(4) == 0 app.Lamp4.Color = app.red; end
            if code(5) == 0 app.Lamp5.Color = app.red; end
            if code(6) == 0 app.Lamp6.Color = app.red; end
            if code(7) == 0 app.Lamp7.Color = app.red; end

            if code(1) == 1 app.Lamp1.Color = app.green; end
            if code(2) == 1 app.Lamp2.Color = app.green; end
            if code(3) == 1 app.Lamp3.Color = app.green; end
            if code(4) == 1 app.Lamp4.Color = app.green; end
            if code(5) == 1 app.Lamp5.Color = app.green; end
            if code(6) == 1 app.Lamp6.Color = app.green; end
            if code(7) == 1 app.Lamp7.Color = app.green; end

            % Yellow for the step that is running
            if code(1) == 3 app.Lamp1.Color = app.yellow; end
            if code(2) == 3 app.Lamp2.Color = app.yellow; end
            if code(3) == 3 app.Lamp3.Color = app.yellow; end
            if code(4) == 3 app.Lamp4.Color = app.yellow; end
            if code(5) == 3 app.Lamp5.Color = app.yellow; end
            if code(6) == 3 app.Lamp6.Color = app.yellow; end
            if code(7) == 3 app.Lamp7.Color = app.yellow; end

            drawnow;

        end


        % Buttons on/off
        function SetButtons(app,code)

            if code(1) == 0 app.LoadMRDfileButton.Enable = 'off';   else app.LoadMRDfileButton.Enable = 'on';   end
            if code(2) == 0 app.RecoimagesButton.Enable = 'off';    else app.RecoimagesButton.Enable = 'on';    end
            if code(3) == 0 app.RegisterButton.Enable = 'off'; app.RegistrationDropDown.Enable = 'off';         else app.RegisterButton.Enable = 'on'; app.RegistrationDropDown.Enable = 'on'; end
            if code(4) == 0 app.MaskButton.Enable = 'off';          else app.MaskButton.Enable = 'on';          end
            if code(5) == 0 app.FitT2Button.Enable = 'off';         else app.FitT2Button.Enable = 'on';         end
            if code(6) == 0 app.DCMNIFTIButton.Enable = 'off';     else app.DCMNIFTIButton.Enable = 'on';       end
            if code(7) == 0 app.GIFButton.Enable = 'off';           else app.GIFButton.Enable = 'on';           end
            if code(8) == 0 app.ExitButton.Enable = 'off';          else app.ExitButton.Enable = 'on';          end

            drawnow;

        end


        % Set application colors
        function SetAppColorsFcn(app, guiColors)

            backgroundColor = guiColors{1};
            fontColor = guiColors{2};
            % boxColor = guiColors{3};
            gaugeColor = guiColors{4};
            gaugeBackgroundColor = guiColors{5};
            figureColor = guiColors{6};
            movieFigureColor = guiColors{7};
            % gridColor = guiColors{8};

            % Panels

            % Each colour goes to the widgets that take it, one list per pair of
            % property and colour, rather than one line per widget.

            for widget = ["T2mappUIFigure"]
                app.(widget).Color = backgroundColor;
            end

            for widget = ["DashboardTab", "RawImagesTab", "RecoParametersTab", ...
                          "FitparametersTab", "AppSettingsTab", "SequenceParametersTab", ...
                          "T2mapTab", "M0R2Tab", "WaterFatTab", ...
                          "RawImageSelectionPanel", "RawImageMaskSettingsPanel", "FilteringPanel", ...
                          "CSregularizationPanel", "SliceSelectionPanel", "DynamicsSelectionPanel", ...
                          "GoodnessOfFitPanel", "EchoSelectionPanel", "ColorsButtonGroup", ...
                          "T2ImageSelectionPanel", "T2ColormapButtonGroup", "M0ImageSelectionPanel", ...
                          "M0ColorMapButtonGroup", "R2ColorMapButtonGroup", "WaterFatImageSelectionPanel", ...
                          "WaterColorMapButtonGroup", "FatColorMapButtonGroup", "ColorsPanel", ...
                          "SoundsPanel"]
                app.(widget).BackgroundColor = backgroundColor;
            end

            for widget = ["CSrecoCheckBoxLabel", "MobaCheckBoxLabel", "WaterFatCheckBoxLabel", ...
                          "SlicesEditFieldLabel", "EchoesEditFieldLabel", "DynamicsEditFieldLabel", ...
                          "ThresholdEditFieldLabel", "WindowEditFieldLabel", "LevelEditFieldLabel", ...
                          "DeNoiseCheckBoxLabel", "DeRingCheckBoxLabel", "WVxyzEditFieldLabel", ...
                          "TVxyzEditFieldLabel", "LLRxyzEditFieldLabel", "TVdynEditFieldLabel", ...
                          "TVteEditFieldLabel", "FirstSliceEditFieldLabel", "LastSliceEditFieldLabel", ...
                          "FirstDynamicEditFieldLabel", "LastDynamicEditFieldLabel", "RsquareEditFieldLabel", ...
                          "AutoSoundsCheckBoxLabel", "ClassicButton", "BlackWhiteButton", ...
                          "DataFileViewFieldLabel", "TRViewFieldLabel", "TE0EditFieldLabel", ...
                          "EchoesViewFieldLabel", "DynamicsViewFieldLabel", "ESPEditFieldLabel", ...
                          "AspectRatioViewFieldLabel", "FOVViewFieldLabel", "DimxViewFieldLabel", ...
                          "DimyViewFieldLabel", "SlicesViewFieldLabel", "SLTViewFieldLabel", ...
                          "T2MapSlicesEditFieldLabel", "T2MapDynamicsEditFieldLabel", "T2ScaleMaxLabel", "T2ScaleMinLabel", ...
                          "T2BrushEditFieldLabel", "grayButton1", "autumnButton1", ...
                          "winterButton1", "parulaButton1", "naviaButton1", ...
                          "T2XEditFieldLabel", "T2YEditFieldLabel", "T2ViewFieldLabel", ...
                          "T2PixelValueLabel", "T2ROIViewFieldLabel", "M0MapSlicesEditFieldLabel", ...
                          "M0MapDynamicsEditFieldLabel", "grayButton2", "autumnButton2", ...
                          "winterButton2", "parulaButton2", "naviaButton2", ...
                          "M0BrushEditFieldLabel", "grayButton3", "autumnButton3", ...
                          "winterButton3", "parulaButton3", "naviaButton3", ...
                          "WaterFatMapSlicesEditFieldLabel", "WaterFatMapDynamicsEditFieldLabel", "grayButton4", ...
                          "autumnButton4", "winterButton4", "parulaButton4", ...
                          "naviaButton4", "grayButton5", "autumnButton5", ...
                          "winterButton5", "parulaButton5", "naviaButton5", ...
                          "RawImageLabel", "T2MapLabel", "M0MapLabel", ...
                          "R2MapLabel", "WaterMapLabel", "FatMapLabel", ...
                          "LogLabel", "LinearLabel"]
                app.(widget).FontColor = fontColor;
            end

            for widget = ["RegProgressGauge", "FitProgressGauge"]
                app.(widget).FontColor = gaugeColor;
            end

            for widget = ["RegProgressGauge", "FitProgressGauge"]
                app.(widget).BackgroundColor = gaugeBackgroundColor;
            end

            for widget = ["RawDataFig", "T2MapFig", "M0MapFig", ...
                          "R2MapFig", "WaterMapFig", "FatMapFig"]
                app.(widget).Color = movieFigureColor;
            end

            for widget = ["RawDataFig", "T2MapFig", "M0MapFig", ...
                          "R2MapFig", "WaterMapFig", "FatMapFig"]
                app.(widget).XColor = movieFigureColor;
            end

            for widget = ["RawDataFig", "T2MapFig", "M0MapFig", ...
                          "R2MapFig", "WaterMapFig", "FatMapFig"]
                app.(widget).YColor = movieFigureColor;
            end

            for widget = ["T2logPlot", "T2linearPlot"]
                app.(widget).Color = figureColor;
            end

            for widget = ["T2logPlot", "T2linearPlot"]
                app.(widget).XColor = fontColor;
            end

            for widget = ["T2logPlot", "T2linearPlot"]
                app.(widget).YColor = fontColor;
            end

            % One checkbox per possible echo.
            for k = 1:64
                app.("CheckBox_" + k).FontColor = fontColor;
            end

        end % SetAppColorsFcn


        % App version check
        function CheckVersionFnc(app)

            % Whether to look, and how long to wait, are settings, so an isolated
            % console does not wait on it
            if ~app.jsonPar.checkForUpdates
                app.TextMessage('Version check switched off in the settings ...');
                return
            end

            try
                currentVersion = string(app.appVersion);

                % Releases to look for, newest first. Derived from this app's own
                % version rather than written out, so that a release newer than this
                % one is still found without anyone editing a list, and the search
                % starts above the current version so the newest answer comes first.
                cur = sscanf(char(currentVersion),'%d.%d');
                cur(end+1:2) = 0;
                newestVersions = strings(1,0);
                for major = cur(1)+1:-1:3
                    if major > cur(1)
                        minors = 0;
                    elseif major == cur(1)
                        minors = cur(2)+5:-1:0;
                    else
                        minors = 9:-1:0;
                    end
                    for minor = minors
                        newestVersions(end+1) = major + "." + minor; %#ok<AGROW>
                    end
                end

                webTxt = "";
                ver = 0;

                opts = weboptions( ...
                    'Timeout', app.jsonPar.versionCheckTimeout, ...
                    'ContentType', 'text', ...
                    'CharacterEncoding', 'UTF-8');

                while webTxt == "" && ver < numel(newestVersions)
                    ver = ver + 1;

                    newVersionFile = "https://raw.githubusercontent.com/Moby1971/T2mapping-v" + ...
                        newestVersions(ver) + "/master/appversion.txt";

                    try
                        webTxt = strtrim(string(webread(char(newVersionFile), opts)));
                    catch webME
                        webTxt = "";

                        % A missing repository means that release does not exist, so
                        % the next one is worth trying. Anything else -- no network, a
                        % timeout, a proxy refusing the connection -- fails the same way
                        % for every entry, and walking the whole list is what made a
                        % start with no network wait a minute before giving up.
                        if ~contains(webME.identifier,'HTTP404')
                            rethrow(webME)
                        end
                    end
                end

                if webTxt == ""
                    ME = MException('T2mapping:error','Could not retrieve version file ...');
                    throw(ME);
                end

                tok = regexp(webTxt, 't2mapp_version\s*=\s*([0-9]+(?:\.[0-9]+){1,3})', 'tokens', 'once', 'ignorecase');
                if isempty(tok)
                    ME = MException('T2mapping:error','Could not parse version string from file ...');
                    throw(ME);
                end

                newVersion = string(tok{1});

                app.TextMessage("Current version = " + currentVersion);
                app.TextMessage("Newest version = " + newVersion);

                if isVersionNewer(newVersion, currentVersion)
                    app.TextMessage("A new version of the T2 mapping app is available ...");
                    app.TextMessage("Visit https://github.com/Moby1971/ to update ...");
                else
                    app.TextMessage("The T2 mapping app is up to date ...");
                end

            catch ME
                disp(ME.message)
                app.TextMessage("Could not check T2 mapping app version ...");
            end

            function tf = isVersionNewer(newVer, curVer)
                a = sscanf(char(newVer), '%d.%d.%d').';
                b = sscanf(char(curVer), '%d.%d.%d').';
                a(end+1:3) = 0;
                b(end+1:3) = 0;
                tf = false;
                for k = 1:3
                    if a(k) > b(k)
                        tf = true;
                        return
                    elseif a(k) < b(k)
                        return
                    end
                end
            end

        end % CheckVersionFnc


        % Switch fit parameters buttons on/off
        function SetFitButtons(app,code)
            if code == 0
                % Everything this panel owns, in one list rather than one line each.
                for widget = ["FirstSliceEditField", "FirstSliceEditFieldLabel", "LastSliceEditField", ...
                              "LastSliceEditFieldLabel", "FirstDynamicEditField", "FirstDynamicEditFieldLabel", ...
                              "LastDynamicEditField", "LastDynamicEditFieldLabel", "RsquareEditField", ...
                              "RsquareEditFieldLabel", "FitAbortButton", "CSrecoCheckBox", ...
                              "CSrecoCheckBoxLabel", "WaterFatCheckBox", "WaterFatCheckBoxLabel", ...
                              "DeNoiseCheckBox", "DeNoiseCheckBoxLabel", "DeNoiseWindowEditField", ...
                              "DeRingCheckBox", "DeRingCheckBoxLabel", "WVxyzEditField", ...
                              "WVxyzEditFieldLabel", "TVxyzEditField", "TVxyzEditFieldLabel", ...
                              "LLRxyzEditField", "LLRxyzEditFieldLabel", "TVteEditField", ...
                              "TVteEditFieldLabel", "TVdynEditField", "TVdynEditFieldLabel", ...
                              "MobaCheckBox", "MobaCheckBoxLabel", "RegistrationDropDown"]
                    app.(widget).Enable = 'off';
                end

                % One checkbox per possible echo.
                for k = 1:64
                    app.("CheckBox_" + k).Enable = 'off';
                end
            else

                if app.td.ns > 1
                    app.FirstSliceEditField.Enable = 'on';
                    app.FirstSliceEditFieldLabel.Enable = 'on';
                    app.LastSliceEditField.Enable = 'on';
                    app.LastSliceEditFieldLabel.Enable = 'on';
                else
                    app.FirstSliceEditField.Enable = 'off';
                    app.FirstSliceEditFieldLabel.Enable = 'off';
                    app.LastSliceEditField.Enable = 'off';
                    app.LastSliceEditFieldLabel.Enable = 'off';
                end

                if app.td.nd > 1
                    app.FirstDynamicEditField.Enable = 'on';
                    app.FirstDynamicEditFieldLabel.Enable = 'on';
                    app.LastDynamicEditField.Enable = 'on';
                    app.LastDynamicEditFieldLabel.Enable = 'on';
                else
                    app.FirstDynamicEditField.Enable = 'off';
                    app.FirstDynamicEditFieldLabel.Enable = 'off';
                    app.LastDynamicEditField.Enable = 'off';
                    app.LastDynamicEditFieldLabel.Enable = 'off';
                end

                app.RsquareEditField.Enable = 'on';
                app.RsquareEditFieldLabel.Enable = 'on';

                if app.bartDetectedFlag && app.validDataFlag
                    app.CSrecoCheckBox.Enable = 'on';
                    app.CSrecoCheckBoxLabel.Enable = 'on';
                else
                    app.CSrecoCheckBox.Enable = 'off';
                    app.CSrecoCheckBoxLabel.Enable = 'off';
                end

                app.DeNoiseCheckBox.Enable = 'on';
                app.DeNoiseCheckBoxLabel.Enable = 'on';
                app.DeNoiseWindowEditField.Enable = 'on';
                app.DeRingCheckBox.Enable = 'on';
                app.DeRingCheckBoxLabel.Enable = 'on';

                app.WVxyzEditField.Enable = 'on';
                app.WVxyzEditFieldLabel.Enable = 'on';
                app.TVxyzEditField.Enable = 'on';
                app.TVxyzEditFieldLabel.Enable = 'on';
                app.LLRxyzEditField.Enable = 'on';
                app.LLRxyzEditFieldLabel.Enable = 'on';
                app.TVteEditField.Enable = 'on';
                app.TVteEditFieldLabel.Enable = 'on';
                app.TVdynEditField.Enable = 'on';
                app.TVdynEditFieldLabel.Enable = 'on';

                if app.validRecoFlag
                    app.RegistrationDropDown.Enable = 'on';
                else
                    app.RegistrationDropDown.Enable = 'off';
                end

                % Model-based fitting is offered for SPIN-ECHO data only.
                %
                % moba is a regularised model-based RECONSTRUCTION, meant for undersampled
                % data, where the signal model supplies what the sampling does not. The
                % multi-gradient-echo data this app handles is fully sampled, so there is
                % nothing for the model to supply, and its smoothness priors make it far
                % more noise-sensitive than fitting each voxel of an image that is already
                % fully determined.
                %
                % Measured on real placenta data against a brute-force fit to the measured
                % decay: least squares tracked it at r = 0.99, moba at r = 0.01. On
                % noise-free synthetic data of the same geometry moba scored r = 0.99, and
                % one percent of noise was enough to take it to r = 0.00.
                %
                % Unchecked as well as disabled, so a box ticked for a previous spin-echo
                % dataset cannot carry over silently.
                if app.bartDetectedFlag && app.validRecoFlag && ~app.td.gradEchoFlag
                    app.MobaCheckBox.Enable = 'on';
                    app.MobaCheckBoxLabel.Enable = 'on';
                else
                    app.MobaCheckBox.Enable = 'off';
                    app.MobaCheckBoxLabel.Enable = 'off';
                    app.MobaCheckBox.Value = 0;
                end

                if app.validRecoFlag && app.td.gradEchoFlag
                    app.WaterFatCheckBox.Enable = 'on';
                    app.WaterFatCheckBoxLabel.Enable = 'on';
                else
                    app.WaterFatCheckBox.Enable = 'off';
                    app.WaterFatCheckBoxLabel.Enable = 'off';

                end
                    % One checkbox per acquired echo.
                    for k = 1:64
                        if app.td.ne >= k
                            app.("CheckBox_" + k).Enable = 'on';
                        end
                    end

            end

            drawnow;

        end


        % Switch image selection buttons on/off
        function SetImageSelectionButtons(app,code)
            if code == 0
                % Everything this panel owns, in one list rather than one line each.
                for widget = ["SlicesEditField", "SlicesEditFieldLabel", "SlicesSlider", ...
                              "EchoesEditField", "EchoesEditFieldLabel", "EchoesSlider", ...
                              "DynamicsEditField", "DynamicsEditFieldLabel", "DynamicsSlider", ...
                              "WindowEditField", "WindowEditFieldLabel", "WindowSlider", ...
                              "LevelEditField", "LevelEditFieldLabel", "LevelSlider", ...
                              "AutoLWButton"]
                    app.(widget).Enable = 'off';
                end
            else

                if app.td.ns >1
                    app.SlicesEditField.Enable = 'on';
                    app.SlicesEditFieldLabel.Enable = 'on';
                    app.SlicesSlider.Enable = 'on';
                else
                    app.SlicesEditField.Enable = 'off';
                    app.SlicesEditFieldLabel.Enable = 'off';
                    app.SlicesSlider.Enable = 'off';
                end
                if app.td.nd > 1
                    app.DynamicsEditField.Enable = 'on';
                    app.DynamicsEditFieldLabel.Enable = 'on';
                    app.DynamicsSlider.Enable = 'on';
                else
                    app.DynamicsEditField.Enable = 'off';
                    app.DynamicsEditFieldLabel.Enable = 'off';
                    app.DynamicsSlider.Enable = 'off';
                end
                app.EchoesEditField.Enable = 'on';
                app.EchoesEditFieldLabel.Enable = 'on';
                app.EchoesSlider.Enable = 'on';
                app.WindowEditField.Enable = 'on';
                app.WindowEditFieldLabel.Enable = 'on';
                app.WindowSlider.Enable = 'on';
                app.LevelEditField.Enable = 'on';
                app.LevelEditFieldLabel.Enable = 'on';
                app.LevelSlider.Enable = 'on';
                app.AutoLWButton.Enable = 'on';

            end

            drawnow;

        end


        % Switch image segmentation buttons on/off
        function SetSegmentationButtons(app,code)
            if code == 0
                % Everything this panel owns, in one list rather than one line each.
                for widget = ["ThresholdEditField", "ThresholdEditFieldLabel", "ThresholdSlider", ...
                              "ErodeButton", "DilateButton", "CloseButton", ...
                              "OpenButton"]
                    app.(widget).Enable = 'off';
                end
            else
                app.ThresholdEditField.Enable = 'on';
                app.ThresholdEditFieldLabel.Enable = 'on';
                app.ThresholdSlider.Enable = 'on';
                app.ErodeButton.Enable = 'on';
                app.DilateButton.Enable = 'on';
                app.CloseButton.Enable = 'on';
                app.OpenButton.Enable = 'on';
            end

            drawnow;

        end


        % Switch T2 map buttons on/off
        function SetMapButtons(app,code)
            if code == 0
                % Everything this panel owns, in one list rather than one line each.
                for widget = ["T2MapSlicesEditField", "T2MapSlicesEditFieldLabel", "M0MapSlicesEditField", ...
                              "M0MapSlicesEditFieldLabel", "T2MapSlicesSlider", "M0MapSlicesSlider", ...
                              "T2MapDynamicsEditField", "T2MapDynamicsEditFieldLabel", "M0MapDynamicsEditField", ...
                              "M0MapDynamicsEditFieldLabel", "T2MapDynamicsSlider", "M0MapDynamicsSlider", ...
                              "WaterFatMapSlicesEditField", "WaterFatMapSlicesEditFieldLabel", "WaterFatMapSlicesSlider", ...
                              "WaterFatMapDynamicsEditField", "WaterFatMapDynamicsEditFieldLabel", "WaterFatMapDynamicsSlider", ...
                              "T2ScaleEditField", "T2ScaleMaxLabel", "T2ScaleMinEditField", "T2ScaleMinLabel", "T2ScaleAutoButton", ...
                              "grayButton1", "autumnButton1", "winterButton1", ...
                              "parulaButton1", "naviaButton1", "grayButton2", ...
                              "autumnButton2", "winterButton2", "parulaButton2", ...
                              "naviaButton2", "grayButton3", "autumnButton3", ...
                              "winterButton3", "parulaButton3", "naviaButton3", ...
                              "grayButton4", "autumnButton4", "winterButton4", ...
                              "parulaButton4", "naviaButton4", "grayButton5", ...
                              "autumnButton5", "winterButton5", "parulaButton5", ...
                              "naviaButton5", "FitAbortButton", "T2ROIbutton", ...
                              "T2ROIEraseButton", "T2BrushEditField", "T2BrushEditFieldLabel", ...
                              "T2ExportRoiDataButton", "M0ROIButton", "M0ROIEraseButton", ...
                              "M0BrushEditField", "M0BrushEditFieldLabel"]
                    app.(widget).Enable = 'off';
                end
            else

                if app.td.ns > 1
                    app.T2MapSlicesEditField.Enable = 'on';
                    app.T2MapSlicesEditFieldLabel.Enable = 'on';
                    app.T2MapSlicesSlider.Enable = 'on';
                    app.M0MapSlicesEditField.Enable = 'on';
                    app.M0MapSlicesEditFieldLabel.Enable = 'on';
                    app.M0MapSlicesSlider.Enable = 'on';
                    app.WaterFatMapSlicesEditField.Enable = 'on';
                    app.WaterFatMapSlicesEditFieldLabel.Enable = 'on';
                    app.WaterFatMapSlicesSlider.Enable = 'on';
                else
                    app.T2MapSlicesEditField.Enable = 'off';
                    app.T2MapSlicesEditFieldLabel.Enable = 'off';
                    app.T2MapSlicesSlider.Enable = 'off';
                    app.M0MapSlicesEditField.Enable = 'off';
                    app.M0MapSlicesEditFieldLabel.Enable = 'off';
                    app.M0MapSlicesSlider.Enable = 'off';
                    app.WaterFatMapSlicesEditField.Enable = 'off';
                    app.WaterFatMapSlicesEditFieldLabel.Enable = 'off';
                    app.WaterFatMapSlicesSlider.Enable = 'off';
                end
                if app.td.nd > 1
                    app.T2MapDynamicsEditField.Enable = 'on';
                    app.T2MapDynamicsEditFieldLabel.Enable = 'on';
                    app.T2MapDynamicsSlider.Enable = 'on';
                    app.M0MapDynamicsEditField.Enable = 'on';
                    app.M0MapDynamicsEditFieldLabel.Enable = 'on';
                    app.M0MapDynamicsSlider.Enable = 'on';
                    app.WaterFatMapDynamicsEditField.Enable = 'on';
                    app.WaterFatMapDynamicsEditFieldLabel.Enable = 'on';
                    app.WaterFatMapDynamicsSlider.Enable = 'on';
                else
                    app.T2MapDynamicsEditField.Enable = 'off';
                    app.T2MapDynamicsEditFieldLabel.Enable = 'off';
                    app.T2MapDynamicsSlider.Enable = 'off';
                    app.M0MapDynamicsEditField.Enable = 'off';
                    app.M0MapDynamicsEditFieldLabel.Enable = 'off';
                    app.M0MapDynamicsSlider.Enable = 'off';
                    app.WaterFatMapDynamicsEditField.Enable = 'off';
                    app.WaterFatMapDynamicsEditFieldLabel.Enable = 'off';
                    app.WaterFatMapDynamicsSlider.Enable = 'off';
                end
                app.T2ScaleEditField.Enable = 'on';
                app.T2ScaleMaxLabel.Enable = 'on';
                app.T2ScaleMinEditField.Enable = 'on';
                app.T2ScaleMinLabel.Enable = 'on';
                app.T2ScaleAutoButton.Enable = 'on';
                app.grayButton1.Enable = 'on';
                app.autumnButton1.Enable = 'on';
                app.winterButton1.Enable = 'on';
                app.parulaButton1.Enable = 'on';
                app.naviaButton1.Enable = 'on';
                app.grayButton2.Enable = 'on';
                app.autumnButton2.Enable = 'on';
                app.winterButton2.Enable = 'on';
                app.parulaButton2.Enable = 'on';
                app.naviaButton2.Enable = 'on';
                app.grayButton3.Enable = 'on';
                app.autumnButton3.Enable = 'on';
                app.winterButton3.Enable = 'on';
                app.parulaButton3.Enable = 'on';
                app.naviaButton3.Enable = 'on';
                app.grayButton4.Enable = 'on';
                app.autumnButton4.Enable = 'on';
                app.winterButton4.Enable = 'on';
                app.parulaButton4.Enable = 'on';
                app.naviaButton4.Enable = 'on';
                app.grayButton5.Enable = 'on';
                app.autumnButton5.Enable = 'on';
                app.winterButton5.Enable = 'on';
                app.parulaButton5.Enable = 'on';
                app.naviaButton5.Enable = 'on';
                app.T2ROIbutton.Enable = 'on';
                app.T2ROIEraseButton.Enable = 'on';
                app.T2BrushEditField.Enable = 'on';
                app.T2BrushEditFieldLabel.Enable = 'on';
                app.T2ExportRoiDataButton.Enable = 'on';
                app.M0ROIButton.Enable = 'on';
                app.M0ROIEraseButton.Enable = 'on';
                app.M0BrushEditField.Enable = 'on';
                app.M0BrushEditFieldLabel.Enable = 'on';

            end

            drawnow;

        end


        % Change the slice value
        function ChangeMapSlices(app, value)

            app.T2MapSlicesSlider.Value = round(value);
            app.M0MapSlicesSlider.Value = round(value);
            app.WaterFatMapSlicesSlider.Value = round(value);

            app.T2MapSlicesEditField.Value = round(value);
            app.M0MapSlicesEditField.Value = round(value);
            app.WaterFatMapSlicesEditField.Value = round(value);

        end


        % Change the dynamics value
        function ChangeMapDynamics(app, value)

            app.T2MapDynamicsSlider.Value = round(value);
            app.M0MapDynamicsSlider.Value = round(value);
            app.WaterFatMapDynamicsSlider.Value = round(value);

            app.T2MapDynamicsEditField.Value = round(value);
            app.M0MapDynamicsEditField.Value = round(value);
            app.WaterFatMapDynamicsEditField.Value = round(value);

        end


        % Initialize the GUI
        function InitializeUI(app)

            % Message
            app.TextMessage('Initializing UI ...');

            % App version
            app.CreditsLabel.Text = strcat("Gustav Strijkers, Amsterdam UMC, Version ", app.appVersion);

            % Define app colors
            app.brightred = 'Red';
            app.red = '#D95319';
            app.brightgreen = 'Green';
            app.green = '#6CB557';
            app.yellow = 'Yellow';
            app.orange = '#FDA400';
            app.blue = '#0073BE';
            app.lightblue = '#DEE6EB';
            app.grey = '#787878';
            app.guiBlack = [0.15,0.15,0.15];
            app.guiGrey = [0.94,0.94,0.94];
            app.guiMiddleGrey = [0.9,0.9,0.9];
            app.guiDarkGrey = [0.5,0.5,0.5];
            app.guiWhite = [1.0,1.0,1.0];
            app.guiBlackBlue = [0.02,0.13,0.3];

            % Logos
            app.TulpLogo.ImageSource = 'AmsterdamUMC.png';
            app.MRSImage.ImageSource = 'MRSolutionsLogo.gif';

             % Load sounds
            [app.warningSound,~] = audioread('Glass.aiff');
            [app.errorSound,~] = audioread('Ping.aiff');

            % Icons
            app.LoadMRDfileButton.Icon = 'disk.png';
            app.RecoimagesButton.Icon = 'reconstruct.gif';
            app.RegisterButton.Icon = 'sort.gif';
            app.MaskButton.Icon = 'threshold.gif';
            app.FitT2Button.Icon = 'exponential.gif';
            app.DCMNIFTIButton.Icon = 'disk.png';
            app.GIFButton.Icon = 'disk.png';
            app.ExitButton.Icon = 'exit.png';
            app.StatusImage.ImageSource = 'statusokay.gif';
            app.T2Image.ImageSource = 'square.gif';
            app.RawImage.ImageSource = 'square.gif';
            app.M0Image.ImageSource = 'square.gif';
            app.R2Image.ImageSource = 'square.gif';
            app.WaterImage.ImageSource = 'square.gif';
            app.FatImage.ImageSource = 'square.gif';

            % set lights and buttons
            app.SetLights([0,0,0,0,0,0,0]);
            app.SetButtons([0,0,0,0,0,0,0,1]);
            app.SetFitButtons(0);
            app.SetImageSelectionButtons(0);
            app.SetSegmentationButtons(0);
            app.SetMapButtons(0);

            % Set some plot properties
            app.RawDataFig.Toolbar.Visible = 'off';
            app.T2MapFig.Toolbar.Visible = 'off';
            app.M0MapFig.Toolbar.Visible = 'off';
            app.T2logPlot.Toolbar.Visible = 'off';
            app.T2linearPlot.Toolbar.Visible = 'off';

            % Colour maps, sounds and colour scheme, from the settings. The
            % panels are numbered as in the widget names: 1 T2, 2 M0, 3 R2,
            % 4 water, 5 fat.
            maps = {app.jsonPar.display.t2ColorMap, app.jsonPar.display.m0ColorMap, ...
                app.jsonPar.display.r2ColorMap, app.jsonPar.display.waterColorMap, ...
                app.jsonPar.display.fatColorMap};
            for n = 1:numel(maps)
                app.(string(maps{n}) + "Button" + n).Value = 1;
            end
            app.t2cmap = app.ColorMapFromButtonsFcn(1, gray);
            app.m0cmap = app.ColorMapFromButtonsFcn(2, gray);
            app.r2cmap = app.ColorMapFromButtonsFcn(3, gray);
            app.watercmap = app.ColorMapFromButtonsFcn(4, gray);
            app.fatcmap = app.ColorMapFromButtonsFcn(5, gray);
            app.AutoSoundsCheckBox.Value = logical(app.jsonPar.sounds);
            if strcmp(app.jsonPar.colorScheme, 'classic')
                app.ClassicButton.Value = true;
            else
                app.BlackWhiteButton.Value = true;
            end

            % Switch off plot interactivity
            app.RawDataFig.Interactions = [];
            app.T2MapFig.Interactions = [];
            app.M0MapFig.Interactions = [];
            app.T2logPlot.Interactions = [];
            app.T2linearPlot.Interactions = [];

            % ROis and cross-hair
            app.roiFlag = false;
            app.roiPoints = [];
            delete(app.roiT2);
            delete(app.roiM0);

            % Reconstruction, registration and fit fields from the settings, whose
            % own defaults are the shipped parameter defaults
            app.SetRecoFieldsFromSettingsFcn;
            app.RegistrationDropDown.Value = app.jsonPar.registration.method;
            app.RsquareEditField.Value = app.jsonPar.fitting.rSquare;

            app.SetStatus(3);

            drawnow;

        end


        %------------------------------------------------
        %
        %  Figures and Plots
        %
        %------------------------------------------------


        % Displays the mask in the raw image data
        function PlotImageFig(app)

            cla(app.RawDataFig);
            hold(app.RawDataFig,'on');

            % Define the colormap
            % Since it is not possible to have 2 colormaps in 1 figure, the
            % colormap is split in a grayscale part [0 1] and a red-color overlay part [1 2]
            cmap1 = [linspace(0,1,128)',linspace(0,1,128)',linspace(0,1,128)'];
            cmap2 = [linspace(0,1,128)', zeros(128,2)];
            cmap = [cmap1;cmap2];
            cmap(cmap > 1) = 1;

            % Background anatomy image
            im = squeeze(app.td.images(app.EchoesEditField.Value,:,:,app.SlicesEditField.Value,app.DynamicsEditField.Value));
            if app.td.parameters.PHASE_ORIENTATION
                im = rot90(im,-1);
            end

            % Scale
            if app.AutoLWButton.Value == 1
                maxIm = max(im(:));
                if ~(maxIm > 0)
                    maxIm = 1;
                end
                app.WindowEditField.Value = maxIm;
                app.WindowSlider.Value = maxIm;
                app.LevelEditField.Value = maxIm/2;
                app.LevelSlider.Value = maxIm/2;
            end

            % Window and Level
            im = (1/app.WindowEditField.Value)*(im - app.LevelEditField.Value + app.WindowEditField.Value/2);
            im(im<0) = 0;
            im(im>0.9999) = 0.9999;

            % Plot the background image
            xlim(app.RawDataFig,[0, size(im,1) + 1]);
            ylim(app.RawDataFig,[0, size(im,2) + 1]);
            background = imshow(rot90(im),[0,2],'Colormap',cmap,'InitialMagnification','fit','Parent', app.RawDataFig); %#ok<NASGU>

            % Overlay image with the mask
            imo = squeeze(2-app.td.mask(:,:,app.SlicesEditField.Value,app.DynamicsEditField.Value));
            alpha = squeeze(0.7-0.7*app.td.mask(:,:,app.SlicesEditField.Value,app.DynamicsEditField.Value));
            if app.td.parameters.PHASE_ORIENTATION
                imo = rot90(imo,-1);
                alpha = rot90(alpha,-1);
            end
            overlay = imshow(rot90(imo),[0 2],'Colormap',cmap,'InitialMagnification','fit','Parent', app.RawDataFig);
            set(overlay,'AlphaData',rot90(alpha));

            % Image aspect ratio
            if app.td.parameters.PHASE_ORIENTATION
                aratio = app.AspectRatioViewField.Value*size(im,2)/size(im,1);
                daspect(app.RawDataFig,[1 aratio 1]);
            else
                aratio = app.AspectRatioViewField.Value*size(im,1)/size(im,2);
                daspect(app.RawDataFig,[aratio 1 1]);
            end


            hold(app.RawDataFig,'off');
            drawnow;

            % Threshold limits from the first echo of the whole dataset, the image the
            % threshold is applied to. A typed threshold applies to all slices, so limits
            % from the shown slice alone would clamp it whenever a darker slice is shown
            maxim = app.td.images(1,:,:,:,:);
            app.ThresholdEditField.Value = 0;
            app.ThresholdSlider.Value = 0;
            app.ThresholdEditField.Limits = [0 max(maxim(:))+1];
            app.ThresholdSlider.Limits = [0 max(maxim(:))+1];
            thr = app.td.threshold(app.SlicesEditField.Value,app.DynamicsEditField.Value);
            thr(thr<0)=0;
            thr(thr>max(maxim(:)))= max(maxim(:));
            app.ThresholdEditField.Value = round(thr);
            app.ThresholdSlider.Value = thr;

        end % PlotImageFig


        % Plots the maps for the current slice
        function PlotMaps(app)

            if app.validFitFlag

                hold(app.T2MapFig,'on');
                hold(app.M0MapFig,'on');
                hold(app.R2MapFig,'on');
                hold(app.WaterMapFig,'on');
                hold(app.FatMapFig,'on');

                % A redraw replaces the previous images and labels: with hold on, imshow
                % and text would otherwise stack a new copy on the axes at every redraw
                delete(findobj([app.T2MapFig, app.M0MapFig, app.R2MapFig, app.WaterMapFig, app.FatMapFig],'Type','image'));
                delete(findobj([app.WaterMapFig, app.FatMapFig],'Type','text'));

                % Plot the T2 map
                im = squeeze(app.td.t2map(:,:,app.T2MapSlicesEditField.Value,app.T2MapDynamicsEditField.Value));
                if app.td.parameters.PHASE_ORIENTATION
                    im = rot90(im,-1);
                end
                xlim(app.T2MapFig,[0, size(im,1) + 1]);
                ylim(app.T2MapFig,[0, size(im,2) + 1]);
                imshow(rot90(im),[app.T2ScaleMinEditField.Value app.T2ScaleEditField.Value],'Colormap',app.t2cmap,'InitialMagnification','fit','Parent', app.T2MapFig);
                if app.td.parameters.PHASE_ORIENTATION
                    aratio = app.AspectRatioViewField.Value*size(im,2)/size(im,1);
                    daspect(app.T2MapFig,[1 aratio 1]);
                else
                    aratio = app.AspectRatioViewField.Value*size(im,1)/size(im,2);
                    daspect(app.T2MapFig,[aratio 1 1]);
                end
                colorbar(app.T2MapFig,'east','Ticks',[]);

                % Plot the M0 map
                im = squeeze(app.td.m0map(:,:,app.T2MapSlicesEditField.Value,app.M0MapDynamicsEditField.Value));
                if app.td.parameters.PHASE_ORIENTATION
                    im = rot90(im,-1);
                end
                % Up to a high percentile of the whole M0 map rather than the
                % slice maximum: a few outlying voxels would otherwise push the
                % rest of the image into the bottom of the grey range, and one
                % scale for all slices keeps them comparable
                m0Max = t2mapp.util.robustDisplayMax(app.td.m0map, t2mapp.Constants.M0_DISPLAY_PERCENTILE);
                im = im/m0Max;
                im(im<0) = 0;
                im(im>0.9999) = 0.9999;
                xlim(app.M0MapFig,[0, size(im,1) + 1]);
                ylim(app.M0MapFig,[0, size(im,2) + 1]);
                imshow(rot90(im),[0 1],'Colormap',app.m0cmap,'InitialMagnification','fit','Parent', app.M0MapFig);
                if app.td.parameters.PHASE_ORIENTATION
                    daspect(app.M0MapFig,[1 aratio 1]);
                else
                    daspect(app.M0MapFig,[aratio 1 1]);
                end

                % Plot the R2 map
                im = squeeze(app.td.r2map(:,:,app.T2MapSlicesEditField.Value,app.M0MapDynamicsEditField.Value));
                if app.td.parameters.PHASE_ORIENTATION
                    im = rot90(im,-1);
                end
                xlim(app.R2MapFig,[0, size(im,1) + 1]);
                ylim(app.R2MapFig,[0, size(im,2) + 1]);
                imshow(rot90(im),[app.RsquareEditField.Value-0.01 1],'Colormap',app.r2cmap,'InitialMagnification','fit','Parent', app.R2MapFig);
                if app.td.parameters.PHASE_ORIENTATION
                    daspect(app.R2MapFig,[1 aratio 1]);
                else
                    daspect(app.R2MapFig,[aratio 1 1]);
                end
                colorbar(app.R2MapFig,'east','Ticks',[app.RsquareEditField.Value-0.01, 1]);
                delete(app.txt1);
                delete(app.txt2);
                app.txt1 = text(app.R2MapFig,0.85,0.95,'1.0','Units','normalized','Color',app.grey,'FontSize',14);
                app.txt2 = text(app.R2MapFig,0.85,0.05,num2str(app.RsquareEditField.Value),'Units','normalized','Color',app.grey,'FontSize',14);

                % Plot the water image
                im = squeeze(app.td.watermap(:,:,app.T2MapSlicesEditField.Value,app.M0MapDynamicsEditField.Value));
                if app.td.parameters.PHASE_ORIENTATION
                    im = rot90(im,-1);
                end
                maxIm = 100;
                window = maxIm;
                level = maxIm/2;
                im = (1/window)*(im - level + window/2);
                im(im<0) = 0;
                im(im>0.9999) = 0.9999;
                xlim(app.WaterMapFig,[0, size(im,1) + 1]);
                ylim(app.WaterMapFig,[0, size(im,2) + 1]);
                imshow(rot90(im),[0 1],'Colormap',app.watercmap,'InitialMagnification','fit','Parent', app.WaterMapFig);
                if app.td.parameters.PHASE_ORIENTATION
                    daspect(app.WaterMapFig,[1 aratio 1]);
                else
                    daspect(app.WaterMapFig,[aratio 1 1]);
                end
                colorbar(app.WaterMapFig,'east','Ticks',[0, 100]);
                text(app.WaterMapFig,0.82,0.95,'100','Units','normalized','Color',app.grey,'FontSize',14);
                text(app.WaterMapFig,0.85,0.05,'0','Units','normalized','Color',app.grey,'FontSize',14);

                % Plot the fat image
                im = squeeze(app.td.fatmap(:,:,app.T2MapSlicesEditField.Value,app.M0MapDynamicsEditField.Value));
                if app.td.parameters.PHASE_ORIENTATION
                    im = rot90(im,-1);
                end
                maxIm = 100;
                window = maxIm;
                level = maxIm/2;
                im = (1/window)*(im - level + window/2);
                im(im<0) = 0;
                im(im>0.9999) = 0.9999;
                xlim(app.FatMapFig,[0, size(im,1) + 1]);
                ylim(app.FatMapFig,[0, size(im,2) + 1]);
                imshow(rot90(im),[0 1],'Colormap',app.fatcmap,'InitialMagnification','fit','Parent', app.FatMapFig);
                if app.td.parameters.PHASE_ORIENTATION
                    daspect(app.FatMapFig,[1 aratio 1]);
                else
                    daspect(app.FatMapFig,[aratio 1 1]);
                end
                colorbar(app.FatMapFig,'east','Ticks',[0, 100]);
                text(app.FatMapFig,0.82,0.95,'100','Units','normalized','Color',app.grey,'FontSize',14);
                text(app.FatMapFig,0.85,0.05,'0','Units','normalized','Color',app.grey,'FontSize',14);

                % ROI or cross-hair display if present, over the new images
                if app.td.parameters.PHASE_ORIENTATION
                    ysize = app.td.dimx;
                else
                    ysize = app.td.dimy;
                end
                if app.roiFlag && ~isempty(app.roiPoints)
                    app.roiPoints = unique(app.roiPoints,'rows');
                    zColumn = app.roiPoints(:,3);
                    plotPoints = app.roiPoints(zColumn==app.T2MapSlicesEditField.Value, :);
                    app.PlotPoint(plotPoints(:,1),plotPoints(:,2),ysize);
                elseif ~app.roiFlag && size(app.roiPoints,1) == 1
                    % the cross-hair only on the slice it was placed on
                    if app.roiPoints(3) == app.T2MapSlicesEditField.Value
                        app.PlotCross(app.roiPoints(1),app.roiPoints(2),ysize);
                    else
                        delete(app.crossT2);
                        delete(app.crossM0);
                        delete(app.crossWF);
                    end
                end

                hold(app.T2MapFig,'off');
                hold(app.M0MapFig,'off');
                hold(app.R2MapFig,'off');
                hold(app.WaterMapFig,'off');
                hold(app.FatMapFig,'off');
                drawnow;

            end

        end % PlotMaps


        % Draw a cross-hair on the T2 map
        function PlotCross(app,xcor,ycor,ysize)

            delete(app.crossT2);
            delete(app.crossM0);
            delete(app.crossWF);

            clr1 = 'r';
            if app.autumnButton1.Value == 1 || app.naviaButton1.Value == 1
                clr1 = 'g';
            end

            clr2 = 'r';
            if app.autumnButton2.Value == 1 || app.naviaButton2.Value == 1
                clr2 = 'g';
            end

            clr3 = 'r';
            if app.autumnButton4.Value == 1 || app.naviaButton4.Value == 1
                clr3 = 'g';
            end

            app.crossT2 = drawcrosshair(app.T2MapFig, 'Position', [xcor ysize-ycor+1], 'Color', clr1, 'LineWidth', 1);
            app.crossM0 = drawcrosshair(app.M0MapFig, 'Position', [xcor ysize-ycor+1], 'Color', clr2, 'LineWidth', 1);
            app.crossWF = drawcrosshair(app.WaterMapFig, 'Position', [xcor ysize-ycor+1], 'Color', clr3, 'LineWidth', 1);

        end % PlotCross


        % Draw points on the T2 map
        function PlotPoint(app,xcor,ycor,ysize)

            clr1 = 'r';
            if app.autumnButton1.Value == 1 || app.naviaButton1.Value == 1
                clr1 = 'g';
            end

            clr2 = 'r';
            if app.autumnButton2.Value == 1 || app.naviaButton2.Value == 1
                clr2 = 'g';
            end

            clr3 = 'r';
            if app.autumnButton4.Value == 1 || app.naviaButton4.Value == 1
                clr3 = 'g';
            end

            hold(app.T2MapFig,'on');
            delete(app.roiT2);
            app.roiT2 = plot(app.T2MapFig, xcor, ysize-ycor+1, 's', 'MarkerSize', 4, 'Color', clr1, 'MarkerFaceColor', clr1);
            hold(app.T2MapFig,'off');

            hold(app.M0MapFig,'on');
            delete(app.roiM0);
            app.roiM0 = plot(app.M0MapFig, xcor, ysize-ycor+1, 's', 'MarkerSize', 4, 'Color', clr2, 'MarkerFaceColor', clr2);
            hold(app.M0MapFig,'off');

            hold(app.WaterMapFig,'on');
            delete(app.roiWF);
            app.roiWF = plot(app.WaterMapFig, xcor, ysize-ycor+1, 's', 'MarkerSize', 4, 'Color', clr3, 'MarkerFaceColor', clr3);
            hold(app.WaterMapFig,'off');

        end % PlotPoint


        % Calculate and show T2 values of ROI
        function CalculateRoiT2(app)

            % Purpose:
            %   Shows the T2 of the pixel or ROI under the cursor, and the echo series with
            %   the fit through it.
            %
            % How it works:
            %   Only the points inside the mask count. When none is, the graphs and the T2
            %   fields are emptied and there is no series to export: a series from
            %   background noise says nothing about the tissue.
            %
            %   The pixel value is the mean of R2 over the fitted points, inverted, with the
            %   spread propagated. The series of the fitted points are averaged, or of all
            %   points inside the mask when the fit rejected every one of them; those show
            %   their data but no T2.

            if ~app.validFitFlag || isempty(app.roiPoints)
                return
            end

            try

                % ysize needed
                if app.td.parameters.PHASE_ORIENTATION
                    ysize = app.td.dimx;
                else
                    ysize = app.td.dimy;
                end

                dynamic = app.T2MapDynamicsEditField.Value;
                nR = size(app.roiPoints,1);
                t2values = zeros(1,nR);
                inMask = false(1,nR);
                rawdatas = zeros(nR,size(app.td.images,1));

                for i = 1:nR
                    if app.td.parameters.PHASE_ORIENTATION
                        r = ysize-app.roiPoints(i,2)+1;
                        c = app.roiPoints(i,1);
                    else
                        r = app.roiPoints(i,1);
                        c = app.roiPoints(i,2);
                    end
                    z = app.roiPoints(i,3);
                    t2values(i) = app.td.t2map(r,c,z,dynamic);
                    inMask(i) = app.td.mask(r,c,z,dynamic) > 0;
                    rawdatas(i,:) = app.td.images(:,r,c,z,dynamic)';
                end

                % Outside the mask: empty graphs, no T2 and no series
                if ~any(inMask)
                    cla(app.T2linearPlot);
                    cla(app.T2logPlot);
                    xlim(app.T2linearPlot,[0 1]);
                    ylim(app.T2linearPlot,[0 1]);
                    xlim(app.T2logPlot,[0 1]);
                    ylim(app.T2logPlot,[0 1]);
                    app.T2ViewField.Value = " ";
                    app.T2ROIViewField.Value = " ";
                    app.td.roiIntData = [];
                    return
                end

                % Averages over the fitted points in the ROI, or the cross-hair value
                fitted = inMask & t2values > 0 & isfinite(t2values);
                if any(fitted)
                    r2values = 1./t2values(fitted);
                    t2value = 1/mean(r2values);                         % T2 = 1 / mean(R2 values in pixels)
                    stdt2value = t2value*std(r2values)/mean(r2values);  % Error propagation with division
                    app.td.roiIntData = mean(rawdatas(fitted,:),1);
                else
                    t2value = 0;
                    stdt2value = 0;
                    app.td.roiIntData = mean(rawdatas(inMask,:),1);
                end

                % TE selection
                app.MakeTeArray;

                nrTEs = length(find(app.td.teSelection(1:app.td.ne) == 1));

                % Do a fit of the ROI-averaged pixel intensities to determine M0; with
                % fewer than two echoes selected there is nothing to fit
                m0roi = 0;
                if nrTEs>1
                     [m0roi,~,~] = t2mapp.fit.dotheT2fitSingle(app.td.roiIntData, app.td.tes, app.RsquareEditField.Value, app.td.teSelection);
                end

                % Plot the results of the fits in the chosen pixel
                app.PlotFits(app.td.tes, app.td.roiIntData, t2value, t2value, stdt2value, m0roi);

            catch ME

                app.TextMessage(ME.message);
                app.TextMessage('ERROR: the T2 of the selected pixels could not be determined ...');

            end

        end % CalculateRoiT2


        % Plots the fit results in the two fit windows
        function PlotFits(app, echotimes, data, t2, t2roi, stdt2, m0)

          if app.validRecoFlag

            cla(app.T2linearPlot);
            cla(app.T2logPlot);
            hold(app.T2linearPlot,'on');
            hold(app.T2logPlot,'on');

            % Colormap for datapoints
            cmap = [t2mapp.util.hex2rgb(app.red) ; t2mapp.util.hex2rgb(app.blue)];
            tecolorflag = cmap(app.td.teSelection + 1,:);
            tecolorflag = tecolorflag(1:app.td.ne,:);

            % The data points, also when the fit rejected them; the logarithm of the
            % positive intensities only, since a zero gives -Inf and a negative a
            % complex number
            data = data(:)';
            logData = nan(size(data));
            logData(data > 0) = log(data(data > 0));
            scatter(app.T2linearPlot,app.td.tes,data,[],tecolorflag,'filled');
            scatter(app.T2logPlot,app.td.tes,logData,[],tecolorflag,'filled');

            % The fit, when there is one
            s = [];
            if (t2roi > 0) && (m0 > 0)

                a = 0 : 0.25: max(echotimes)+5;
                s = m0*exp(-a./t2roi);
                plot(app.T2linearPlot,a,s);
                plot(app.T2logPlot,a,log(s));

                % show T2 value in app
                if stdt2 > 0
                    app.T2ViewField.Value = [num2str(t2,'% 10.1f'),' ',char(177),' ',num2str(stdt2,'% 10.1f')];
                else
                    app.T2ViewField.Value = num2str(t2,'% 10.1f');
                end

                app.T2ROIViewField.Value = num2str(t2roi,'% 10.1f');

            else

                app.T2ViewField.Value = " ";
                app.T2ROIViewField.Value = " ";

            end

            % Limits from the finite values shown, so that zero, negative or missing
            % intensities cannot give an empty or reversed range
            xlim(app.T2linearPlot,[0, max(echotimes)+5]);
            xlim(app.T2logPlot,[0, max(echotimes)+5]);
            linValues = [data(isfinite(data)), s(isfinite(s))];
            top = max([linValues, 0]);
            if top <= 0
                top = 1;
            end
            ylim(app.T2linearPlot,[min([linValues, 0]), 1.1*top]);
            logValues = [logData(isfinite(logData)), log(s(s > 0))];
            logValues = logValues(isfinite(logValues));
            if ~isempty(logValues)
                pad = max(0.05*(max(logValues) - min(logValues)), 0.1);
                ylim(app.T2logPlot,[min(logValues) - pad, max(logValues) + pad]);
            end

            hold(app.T2linearPlot,'off');
            hold(app.T2logPlot,'off');

          end

        end % PlotFits


        %------------------------------------------------
        %
        %  Parameters and settings
        %
        %------------------------------------------------


        % Sets global parameters
        function SetParameters(app)

            % A new dataset: read it, size the widgets to it, and clear what the
            % last one left behind. The mirror then shows what was read.
            app.ReadAcquisitionFcn;
            app.SetWidgetLimitsFcn;
            app.ResetForNewDataFcn;
            % Everything above has been set; show it
            app.MirrorDataOutputsFcn;

        end % SetParameters


        % Write the animations, and photograph the window beside them
        function ExportGifsFcn(app, gifExportBaseFolder)

            % Purpose:
            %   Makes the numbered output folder for this export, puts a
            %   picture of the app's own window in it, and writes the
            %   animations.
            %
            % How it works:
            %   The photographs are the reason this lives here rather than in
            %   exportGifT2: switching tabs and calling exportapp is GUI work,
            %   and nothing below the app can do it. exportGifT2 is then given
            %   the finished folder and needs to know nothing about widgets.

            % Create new folder
            ready = false;
            cnt = 1;
            while ~ready
                gifOutputFolder = strcat(gifExportBaseFolder,app.td.tag,'T2',filesep,num2str(cnt),filesep);
                if ~exist(gifOutputFolder, 'dir')
                    mkdir(gifOutputFolder);
                    ready = true;
                end
                cnt = cnt + 1;
            end

            % A picture of the window, on the tabs the maps are shown in
            app.TabGroup2.SelectedTab = app.RawImagesTab;
            app.TabGroup3.SelectedTab = app.M0R2Tab;
            exportapp(app.T2mappUIFigure,strcat(gifOutputFolder,filesep,"M0map",app.td.tag,".png"));
            app.TabGroup3.SelectedTab = app.T2mapTab;
            exportapp(app.T2mappUIFigure,strcat(gifOutputFolder,filesep,"T2map",app.td.tag,".png"));

            % The animations themselves
            t2mapp.io.exportGifT2(app.td, gatherFitParams(app), gatherDisplayParams(app), gifOutputFolder);

        end % ExportGifsFcn


        % Which colour map one panel's radio buttons are set to
        function cmap = ColorMapFromButtonsFcn(app, n, cmap)

            % Purpose:
            %   Reads the five colour map radio buttons of one panel and gives
            %   back the map they name.
            %
            % How it works:
            %   The panels are numbered in the widget names: 1 T2, 2 M0, 3 R2,
            %   4 water, 5 fat. The current map is passed in and returned
            %   unchanged if none of the buttons is set, which a radio group
            %   does not allow but which keeps this faithful to the five
            %   callbacks it replaces.
            %
            %   The first entry is forced to black so that the background of a
            %   map stays black whatever the colour map does at its bottom end.

            if app.("grayButton" + n).Value == 1
                cmap = gray;
            end

            if app.("autumnButton" + n).Value == 1
                cmap = autumn;
            end

            if app.("winterButton" + n).Value == 1
                cmap = winter;
            end

            if app.("parulaButton" + n).Value == 1
                cmap = parula;
            end

            if app.("naviaButton" + n).Value == 1
                cmap = t2mapp.util.naviaColorMap();
            end

            % Background should always be black
            cmap(1,:) = [0 0 0];

        end % ColorMapFromButtonsFcn


        % Read the acquisition into the model
        function ReadAcquisitionFcn(app)

            % Purpose:
            %   Takes the sequence parameters the importer produced and puts them on the
            %   model as named values, then reports the ones a user checks.
            %
            % How it works:
            %   The echo times are built from the first echo and the spacing, except on
            %   the Bruker path where the file lists them; the slice thickness is the
            %   slab over the partitions for 3D and the excitation thickness for 2D.

            % Message
            app.TextMessage('Setting scan parameters ...');

            % sequence parameters
            app.td.ne = app.td.parameters.NO_ECHOES;
            app.td.dimx = app.td.parameters.NO_SAMPLES;
            app.td.dimy = app.td.parameters.NO_VIEWS;
            app.td.fov = app.td.parameters.FOV;
            app.td.aspectRatio = app.td.parameters.FOVf/8;
            if app.td.data3dFlag
                app.td.ns = app.td.parameters.NO_VIEWS_2;
            else
                app.td.ns = app.td.parameters.NO_SLICES;
            end
            if isfield(app.td.parameters,"EXPERIMENT_ARRAY")
                app.td.nd = app.td.parameters.EXPERIMENT_ARRAY;
            else
                app.td.nd = 1;
            end
            app.td.tr = app.td.parameters.tr;
            app.td.te = app.td.parameters.te;
            app.td.esp = app.td.parameters.te;
            if app.td.data3dFlag
                app.td.sl = app.td.parameters.SLICE_THICKNESS/app.td.ns;
            else
                app.td.sl = app.td.parameters.SLICE_THICKNESS;
            end
            app.td.tes = app.td.te + app.td.esp*linspace(0,app.td.ne-1,app.td.ne)'; % echo times
            if strcmp(app.td.parameters.scanner,"b-type")
                app.td.tes = app.td.parameters.echotimes;
                app.td.esp = app.td.parameters.echotimes(2)-app.td.parameters.echotimes(1);
            end

            % MR Solutions does not record the echo times of a multi-echo
            % gradient-echo scan anywhere in the raw data: te, te_us and
            % TE_array_size are all zero, so te and esp come out zero here and
            % the fit refuses to start. The scanner's own DICOM export does
            % carry them, when one was made. Read them from there rather than
            % making the user type them in.
            %
            % The export is preferred whenever it exists, also over a TE the raw data does
            % carry, since it holds the echo times that were played out. Without one the
            % spacing is taken as the TE, as for a spin-echo train with echoes at TE, 2TE,
            % 3TE, ..., and a scan without a TE gets its spacing estimated. Both fields stay
            % editable, and an edit recomputes the echo times.
            if app.td.ne > 1 && ~strcmp(app.td.parameters.scanner,"b-type")
                [dcmTE0, dcmESP] = t2mapp.io.scannerEchoTimes( ...
                    app.mrdImportFolder, app.td.ne, AppReporter(app));
                if ~isempty(dcmTE0) && ~isempty(dcmESP) && dcmTE0 > 0 && dcmESP > 0
                    app.td.te = dcmTE0;
                    app.td.esp = dcmESP;
                    app.td.tes = app.td.te + app.td.esp*linspace(0,app.td.ne-1,app.td.ne)';
                elseif app.td.te > 0

                    % No DICOM export: the spacing stays equal to the TE, and TE0 and ESP can
                    % be corrected by hand
                    app.TextMessage("INFO: echo spacing taken equal to TE = " + num2str(app.td.te) + " ms; edit TE0 and ESP if they differ ...");

                else

                    % No DICOM export to read. The spacing can still be worked out from
                    % the sequence timing -- the readout, its two ramps and the
                    % inter-echo delay -- which reproduces both known cases exactly.
                    % It is an ESTIMATE from a model of the sequence, not a number the
                    % scanner reported, so it is announced as one.
                    %
                    % TE0 cannot be had this way. The spacing fixes the SLOPE of the
                    % decay and so fixes T2*, but TE0 sets the intercept and is needed
                    % for the water-fat separation, for M0, and for the model-based fit,
                    % which unlike the least-squares fit is sensitive to it. So esp is
                    % filled in and te is deliberately left alone: the fit guard keeps
                    % stopping until the user supplies TE0.
                    [estESP, estDetail] = t2mapp.io.estimatedEchoSpacing(app.td.parameters);
                    if ~isempty(estESP) && estESP > 0
                        app.td.esp = estESP;
                        app.td.tes = app.td.te + app.td.esp*linspace(0,app.td.ne-1,app.td.ne)';
                        app.TextMessage("WARNING: no scanner DICOM found; echo spacing ESTIMATED as " + ...
                            num2str(estESP,"%.4f") + " ms from the sequence timing ...");
                        app.TextMessage(strcat("INFO: ", estDetail, " ..."));
                        app.TextMessage("WARNING: TE0 must still be entered before fitting ...");
                    end

                end
            end

            tetxt = num2str(app.td.tes');
            for i = 1:10
                tetxt = strrep(tetxt,"  "," ");
            end
            tetxt = strrep(tetxt," "," : ");
            app.TextMessage(strcat("Echo times = ",tetxt," ms ..."));

            % Total acquisition time in seconds
            if isfield(app.td.rprPars,"TotalAcquisitionTime")
                app.td.parameters.TotalAcquisitionTime = app.td.rprPars.TotalAcquisitionTime;
            else
                % Estimated from the sequence when there is no RPR file; the averages may
                % be missing from the parameters, and the dynamics were read above
                averages = 1;
                if isfield(app.td.parameters,"NO_AVERAGES")
                    averages = app.td.parameters.NO_AVERAGES;
                end
                app.td.parameters.TotalAcquisitionTime = (app.td.parameters.tr*app.td.parameters.NO_VIEWS*averages*app.td.nd)/1000;
            end

            % Orientation
            if isfield(app.td.parameters,'PHASE_ORIENTATION')
                if app.td.parameters.PHASE_ORIENTATION
                    app.TextMessage('INFO: phase orientation = 1 / horizontal ... ');
                else
                    app.TextMessage('INFO: phase orientation = 0 / vertical ... ')
                end
            end

        end % ReadAcquisitionFcn


        % Set the widget limits and starting values
        function SetWidgetLimitsFcn(app)

            % Purpose:
            %   Puts every slider and range field within the bounds this dataset allows,
            %   and starts them at the first slice and dynamic.
            %
            % How it works:
            %   A dimension of one still needs a range of two, since a slider cannot have
            %   equal limits; the widget is disabled elsewhere in that case.

            % show parameters & set limits
            app.SlicesEditField.Value = 1;
            app.SlicesSlider.Value = 1;
            app.T2MapSlicesEditField.Value = 1;
            app.T2MapSlicesSlider.Value = 1;
            app.M0MapSlicesEditField.Value = 1;
            app.M0MapSlicesSlider.Value = 1;
            app.WaterFatMapSlicesEditField.Value = 1;
            app.WaterFatMapSlicesSlider.Value = 1;

            if app.td.ns > 1
                app.SlicesEditField.Limits = [1, app.td.ns];
                app.SlicesSlider.Limits = [1, app.td.ns];
                app.T2MapSlicesEditField.Limits = [1, app.td.ns];
                app.T2MapSlicesSlider.Limits = [1, app.td.ns];
                app.M0MapSlicesEditField.Limits = [1, app.td.ns];
                app.M0MapSlicesSlider.Limits = [1, app.td.ns];
                app.WaterFatMapSlicesEditField.Limits = [1, app.td.ns];
                app.WaterFatMapSlicesSlider.Limits = [1, app.td.ns];
            else
                app.SlicesEditField.Limits = [1, 2];
                app.SlicesSlider.Limits = [1, 2];
                app.T2MapSlicesEditField.Limits = [1, 2];
                app.T2MapSlicesSlider.Limits = [1, 2];
                app.M0MapSlicesEditField.Limits = [1, 2];
                app.M0MapSlicesSlider.Limits = [1, 2];
                app.WaterFatMapSlicesEditField.Limits = [1, 2];
                app.WaterFatMapSlicesSlider.Limits = [1, 2];
            end

            app.DynamicsEditField.Value = 1;
            app.T2MapDynamicsEditField.Value = 1;
            app.M0MapDynamicsEditField.Value = 1;
            app.WaterFatMapDynamicsEditField.Value = 1;
            app.DynamicsSlider.Value = 1;
            app.T2MapDynamicsSlider.Value = 1;
            app.M0MapDynamicsSlider.Value = 1;
            app.WaterFatMapDynamicsSlider.Value = 1;

            if app.td.nd > 1
                app.DynamicsEditField.Limits  = [1, app.td.nd];
                app.T2MapDynamicsEditField.Limits  = [1, app.td.nd];
                app.M0MapDynamicsEditField.Limits  = [1, app.td.nd];
                app.WaterFatMapDynamicsEditField.Limits  = [1, app.td.nd];
                app.DynamicsSlider.Limits  = [1, app.td.nd];
                app.T2MapDynamicsSlider.Limits  = [1, app.td.nd];
                app.M0MapDynamicsSlider.Limits  = [1, app.td.nd];
                app.WaterFatMapDynamicsSlider.Limits  = [1, app.td.nd];
            else
                app.DynamicsEditField.Limits  = [1, 2];
                app.T2MapDynamicsEditField.Limits  = [1, 2];
                app.M0MapDynamicsEditField.Limits  = [1, 2];
                app.WaterFatMapDynamicsEditField.Limits  = [1, 2];
                app.DynamicsSlider.Limits  = [1, 2];
                app.T2MapDynamicsSlider.Limits  = [1, 2];
                app.M0MapDynamicsSlider.Limits  = [1, 2];
                app.WaterFatMapDynamicsSlider.Limits  = [1, 2];
            end

            % A single echo still needs a range of two, as for the slices and dynamics
            app.EchoesEditField.Value = 1;
            app.EchoesSlider.Value = 1;
            app.EchoesEditField.Limits = [1, max(2, app.td.ne)];
            app.EchoesSlider.Limits = [1, max(2, app.td.ne)];
            app.LastSliceEditField.Value = 1;
            app.FirstDynamicEditField.Value = 1;
            app.LastDynamicEditField.Value = 1;
            app.FirstSliceEditField.Value = 1;

            if app.td.ns > 1
                app.FirstSliceEditField.Limits = [1 app.td.ns];
                app.LastSliceEditField.Limits = [1 app.td.ns];
            else
                app.FirstSliceEditField.Limits = [1 2];
                app.LastSliceEditField.Limits = [1 2];
            end

            if app.td.nd > 1
                app.FirstDynamicEditField.Limits = [1 app.td.nd];
                app.LastDynamicEditField.Limits = [1 app.td.nd];
            else
                app.FirstDynamicEditField.Limits = [1 2];
                app.LastDynamicEditField.Limits = [1 2];
            end

            app.LastSliceEditField.Value = app.td.ns;
            app.LastDynamicEditField.Value = app.td.nd;
            app.abortFitFlag = false;
            app.abortRegFlag = false;

        end % SetWidgetLimitsFcn


        % Reset everything a previous dataset left behind
        function ResetForNewDataFcn(app)

            % Purpose:
            %   Clears the maps, the mask, the thresholds and the options so that nothing
            %   from the last dataset survives into this one.
            %
            % How it works:
            %   The maps are allocated at the size this dataset needs rather than being
            %   emptied, so the fitting can write into them slice by slice.

            % File tag
            [~,app.td.tag,~] = fileparts(app.td.dataFile);

            % Uncheck/reset some options
            app.CSrecoCheckBox.Value = 0;
            app.DeNoiseCheckBox.Value = logical(app.jsonPar.reconstruction.denoise);
            app.DeNoiseWindowEditField.Value = app.jsonPar.reconstruction.denoiseWindow;
            app.DeRingCheckBox.Value = logical(app.jsonPar.reconstruction.deRing);
            app.MobaCheckBox.Value = 0;

            % Window and level
            app.WindowEditField.Value = 32767;
            app.LevelEditField.Value = 16384;
            app.WindowSlider.Value = 32767;
            app.LevelSlider.Value = 16384;
            app.AutoLWButton.Value = 1;

            % Segmentation
            app.structuringElement2D = strel("disk",1);
            app.structuringElement3D = strel("sphere",1);
            app.roiPoints = [];

            % reset images
            app.td.m0map = zeros(app.td.dimx,app.td.dimy,app.td.ns,app.td.nd);
            app.td.t2map = zeros(app.td.dimx,app.td.dimy,app.td.ns,app.td.nd);
            app.td.r2map = zeros(app.td.dimx,app.td.dimy,app.td.ns,app.td.nd);
            app.td.watermap = zeros(app.td.dimx,app.td.dimy,app.td.ns,app.td.nd);
            app.td.fatmap = zeros(app.td.dimx,app.td.dimy,app.td.ns,app.td.nd);
            app.td.mask = ones(app.td.dimx,app.td.dimy,app.td.ns,app.td.nd);
            app.td.images = zeros(app.td.ne,app.td.dimx,app.td.dimy,app.td.ns,app.td.nd);

            % reset treshold values
            app.td.threshold = zeros(app.td.ns,app.td.nd);

            % The maps, fits and ROI of the previous dataset off the screen
            delete(app.roiT2);
            delete(app.roiM0);
            delete(app.roiWF);
            delete(app.crossT2);
            delete(app.crossM0);
            delete(app.crossWF);
            cla(app.T2MapFig);
            cla(app.M0MapFig);
            cla(app.R2MapFig);
            cla(app.WaterMapFig);
            cla(app.FatMapFig);
            cla(app.T2linearPlot);
            cla(app.T2logPlot);
            app.T2ViewField.Value = " ";
            app.T2ROIViewField.Value = " ";

        end % ResetForNewDataFcn

        % Model-based T2 or T2* fitting
        function FitModelBasedFcn(app, fitParams)

            % Purpose:
            %   Fits the maps straight from k-space with BART, for the slices and
            %   dynamics the user selected.
            %
            % How it works:
            %   The 3D path fits a whole volume per dynamic; the 2D path fits slice by
            %   slice. Which of T2 or T2* is fitted follows the sequence type.
            %
            %   The loops check the abort flag between fits, and the maps are plotted
            %   as they fill in so that a long fit shows its progress.

            % -------------------------------------------------------
            %     Model-based fitting
            % -------------------------------------------------------

            app.TextMessage("Model based fitting ...");

            if app.td.data3dFlag

                % Progress counter reset
                cnt = 1;
                totalNumberOfSteps = (app.LastDynamicEditField.Value-app.FirstDynamicEditField.Value+1);

                % Timing parameters
                app.EstimatedFitTimeViewField.Value = 'Calculating ...';
                elapsedTime = 0;
                app.FitProgressGauge.Value = 0;

                % For selected dynamics
                dynamic = app.FirstDynamicEditField.Value - 1;

                while dynamic < app.LastDynamicEditField.Value && ~app.abortFitFlag

                    dynamic = dynamic + 1;

                    tic;

                    app.TextMessage(sprintf("Fitting dynamic %d of %d ...",dynamic-app.FirstDynamicEditField.Value+1,app.LastDynamicEditField.Value-app.FirstDynamicEditField.Value+1));
                    drawnow;

                    % Do the T2 or T2* fitting
                    switch app.td.dataType

                        case "spin-echo"

                            [app.td.m0map(:,:,:,dynamic),app.td.t2map(:,:,:,dynamic)] = t2mapp.fit.dothemobaT2fit3D(app.td,fitParams,dynamic,AppReporter(app));
                            app.td.r2map(:,:,:,dynamic) = zeros(size(squeeze(app.td.r2map(:,:,:,dynamic))));

                        case "grad-echo"

                            [app.td.m0map(:,:,:,dynamic),app.td.t2map(:,:,:,dynamic)] = t2mapp.fit.dothemobaT2starfit3D(app.td,fitParams,dynamic,AppReporter(app));
                            app.td.r2map(:,:,:,dynamic) = zeros(size(squeeze(app.td.r2map(:,:,:,dynamic))));

                    end

                    % Plot T2 map thus far with auto scale
                    try
                        scale = round(2.5*mean(nonzeros(abs(app.td.t2map(:).*app.td.mask(:)))));
                        scale(scale<5)=5;
                    catch
                        scale = 100;

                    end
                    app.SetT2ScaleFcn(scale);

                    app.validFitFlag = true;
                    app.PlotMaps;
                    app.validFitFlag = false;

                    % Update progresss bar
                    app.FitProgressGauge.Value = round(100 * cnt/totalNumberOfSteps);
                    drawnow;

                    % Update the timing indicator
                    elapsedTime = elapsedTime + toc;
                    estimatedtotaltime = elapsedTime * totalNumberOfSteps / cnt;
                    timeRemaining = estimatedtotaltime * (totalNumberOfSteps - cnt) / totalNumberOfSteps;
                    timeRemaining(timeRemaining<0) = 0;
                    app.EstimatedFitTimeViewField.Value = strcat(datestr(seconds(timeRemaining),'MM:SS')," min:sec"); %#ok<*DATST>
                    drawnow;

                    cnt = cnt + 1;

                end % Loop over dynamics

            else

                % Progress counter reset
                cnt = 1;
                totalNumberOfSteps = (app.LastDynamicEditField.Value-app.FirstDynamicEditField.Value+1)*...
                    (app.LastSliceEditField.Value-app.FirstSliceEditField.Value+1);

                % Timing parameters
                app.EstimatedFitTimeViewField.Value = 'Calculating ...';
                elapsedTime = 0;
                app.FitProgressGauge.Value = 0;

                % For selected dynamics
                dynamic = app.FirstDynamicEditField.Value - 1;

                while dynamic < app.LastDynamicEditField.Value && ~app.abortFitFlag

                    dynamic = dynamic + 1;

                    % for selected slices
                    slice = app.FirstSliceEditField.Value - 1;

                    while slice < app.LastSliceEditField.Value && ~app.abortFitFlag

                        slice = slice + 1;

                        tic;

                        app.TextMessage(sprintf("Fitting slice %d of %d ...",slice-app.FirstSliceEditField.Value+1,app.LastSliceEditField.Value-app.FirstSliceEditField.Value+1));
                        app.TextMessage(sprintf("Fitting dynamic %d of %d ...",dynamic-app.FirstDynamicEditField.Value+1,app.LastDynamicEditField.Value-app.FirstDynamicEditField.Value+1));
                        drawnow;

                        % Do the T2 or T2* fitting
                        switch app.td.dataType

                            case "spin-echo"

                                [app.td.m0map(:,:,slice,dynamic),app.td.t2map(:,:,slice,dynamic)] = t2mapp.fit.dothemobaT2fit2D(app.td,fitParams,slice,dynamic,AppReporter(app));
                                app.td.r2map(:,:,slice,dynamic) = zeros(size(squeeze(app.td.r2map(:,:,slice,dynamic))));

                            case "grad-echo"

                                [app.td.m0map(:,:,slice,dynamic),app.td.t2map(:,:,slice,dynamic)] = t2mapp.fit.dothemobaT2starfit2D(app.td,fitParams,slice,dynamic,AppReporter(app));
                                app.td.r2map(:,:,slice,dynamic) = zeros(size(squeeze(app.td.r2map(:,:,slice,dynamic))));

                        end

                        % Plot T2 map thus far with auto scale
                        try
                            scale = round(2.5*mean(nonzeros(abs(app.td.t2map(:).*app.td.mask(:)))));
                            scale(scale<5)=5;
                            app.SetT2ScaleFcn(scale);
                        catch
                            scale = 100;
                            app.SetT2ScaleFcn(scale);
                        end

                        app.validFitFlag = true;
                        app.PlotMaps;
                        app.validFitFlag = false;

                        % Update progresss bar
                        app.FitProgressGauge.Value = round(100 * cnt/totalNumberOfSteps );

                        % Update the timing indicator
                        elapsedTime = elapsedTime + toc;
                        estimatedtotaltime = elapsedTime * totalNumberOfSteps / cnt;
                        timeRemaining = estimatedtotaltime * (totalNumberOfSteps - cnt) / totalNumberOfSteps;
                        timeRemaining(timeRemaining<0) = 0;
                        app.EstimatedFitTimeViewField.Value = strcat(datestr(seconds(timeRemaining),'MM:SS')," min:sec"); %#ok<*DATST>
                        drawnow;

                        cnt = cnt + 1;

                    end % Loop over slices

                end % Loop over dynamics

            end % 3D or 2D

        end % FitModelBasedFcn


        % Least-squares T2 fitting, pixel by pixel
        function FitPixelwiseFcn(app, fitParams)

            % Purpose:
            %   Fits a mono-exponential decay to every pixel of the selected slices and
            %   dynamics.
            %
            % How it works:
            %   One slice at a time, so that the maps can be plotted as they fill in
            %   and the abort flag can be checked between slices.

            % ---------------------------------------
            %     Normal pixel-wise fitting
            % ---------------------------------------

            % Progress counter reset
            cnt = 1;
            totalNumberOfSteps = (app.LastDynamicEditField.Value-app.FirstDynamicEditField.Value+1)*...
                (app.LastSliceEditField.Value-app.FirstSliceEditField.Value+1);

            % Timing parameters
            app.EstimatedFitTimeViewField.Value = 'Calculating ...';
            elapsedTime = 0;
            app.FitProgressGauge.Value = 0;

            % For selected dynamics
            dynamic = app.FirstDynamicEditField.Value - 1;

            while dynamic < app.LastDynamicEditField.Value && ~app.abortFitFlag

                dynamic = dynamic + 1;

                % for selected slices
                slice = app.FirstSliceEditField.Value - 1;

                while slice < app.LastSliceEditField.Value && ~app.abortFitFlag

                    slice = slice + 1;

                    tic;

                    app.TextMessage(sprintf("Fitting slice %d of %d ...",slice-app.FirstSliceEditField.Value+1,app.LastSliceEditField.Value-app.FirstSliceEditField.Value+1));
                    app.TextMessage(sprintf("Fitting dynamic %d of %d ...",dynamic-app.FirstDynamicEditField.Value+1,app.LastDynamicEditField.Value-app.FirstDynamicEditField.Value+1));
                    drawnow;

                    % Do the fit for 1 slice
                    [app.td.m0map(:,:,slice,dynamic),app.td.t2map(:,:,slice,dynamic),app.td.r2map(:,:,slice,dynamic)] = t2mapp.fit.dotheT2fit2D(app.td,fitParams,slice,dynamic);

                    % Plot T2 map thus far with auto scale
                    try
                        scale = round(2.5*mean(nonzeros(abs(app.td.t2map(:).*app.td.mask(:)))));
                        scale(scale<5)=5;
                        app.SetT2ScaleFcn(scale);
                    catch
                        scale = 100;
                        app.SetT2ScaleFcn(scale);
                    end

                    app.validFitFlag = true;
                    app.PlotMaps;
                    app.validFitFlag = false;

                    % Update progresss bar
                    app.FitProgressGauge.Value = round(100 * cnt/totalNumberOfSteps);

                    % Update the timing indicator
                    elapsedTime = elapsedTime + toc;
                    estimatedtotaltime = elapsedTime * totalNumberOfSteps / cnt;
                    timeRemaining = estimatedtotaltime * (totalNumberOfSteps - cnt) / totalNumberOfSteps;
                    timeRemaining(timeRemaining<0) = 0;
                    app.EstimatedFitTimeViewField.Value = strcat(datestr(seconds(timeRemaining),'MM:SS')," min:sec"); %#ok<*DATST>
                    drawnow;

                    cnt = cnt + 1;

                end % Loop over slices

            end % Loop over dynamics

        end % FitPixelwiseFcn


        % Water and fat separation
        function FitWaterFatFcn(app, fitParams)

            % Purpose:
            %   Produces the water and fat fraction maps for the selected slices and
            %   dynamics.
            %
            % How it works:
            %   One slice at a time, from the complex images the reconstruction kept.
            %   validWaterFatFlag is raised as soon as one map exists, so an export
            %   knows there is something to write even if the run was aborted.

            % Progress counter reset
            cnt = 1;
            totalNumberOfSteps = (app.LastDynamicEditField.Value-app.FirstDynamicEditField.Value+1)*...
                (app.LastSliceEditField.Value-app.FirstSliceEditField.Value+1);

            % Timing parameters
            app.EstimatedFitTimeViewField.Value = 'Calculating ...';
            elapsedTime = 0;
            app.FitProgressGauge.Value = 0;

            % For selected dynamics
            dynamic = app.FirstDynamicEditField.Value - 1;

            while dynamic < app.LastDynamicEditField.Value && ~app.abortFitFlag

                dynamic = dynamic + 1;

                % for selected slices
                slice = app.FirstSliceEditField.Value - 1;

                while slice < app.LastSliceEditField.Value && ~app.abortFitFlag

                    slice = slice + 1;

                    tic;

                    app.TextMessage(sprintf("Water/fat fitting slice %d of %d ...",slice-app.FirstSliceEditField.Value+1,app.LastSliceEditField.Value-app.FirstSliceEditField.Value+1));
                    app.TextMessage(sprintf("Water/fat fitting dynamic %d of %d ...",dynamic-app.FirstDynamicEditField.Value+1,app.LastDynamicEditField.Value-app.FirstDynamicEditField.Value+1));
                    drawnow;

                    % Do the water and fat map for 1 slice
                    [app.td.watermap(:,:,slice,dynamic),app.td.fatmap(:,:,slice,dynamic)] = ...
                        t2mapp.fit.waterFatImagesMethod1(app.td, fitParams, slice, dynamic, AppReporter(app));

                    % Plot the images so far
                    app.validFitFlag = true;
                    app.PlotMaps;
                    app.validFitFlag = false;

                    % Update progresss bar
                    app.FitProgressGauge.Value = round(100 * cnt/totalNumberOfSteps);

                    % Update the timing indicator
                    elapsedTime = elapsedTime + toc;
                    estimatedtotaltime = elapsedTime * totalNumberOfSteps / cnt;
                    timeRemaining = estimatedtotaltime * (totalNumberOfSteps - cnt) / totalNumberOfSteps;
                    timeRemaining(timeRemaining<0) = 0;
                    app.EstimatedFitTimeViewField.Value = strcat(datestr(seconds(timeRemaining),'MM:SS')," min:sec"); %#ok<*DATST>
                    drawnow;

                    % At least 1 water-fat image made
                    app.td.validWaterFatFlag = true;

                    cnt = cnt + 1;

                end % Loop over slices

            end % Loop over dynamics

            app.TextMessage('Finished ... ');
            app.EstimatedFitTimeViewField.Value = 'Finished ...';

        end % FitWaterFatFcn


        % Copy the acquisition outputs to the fields that display them
        function MirrorDataOutputsFcn(app)

            % Purpose:
            %   Puts every acquisition value the app shows into its display
            %   field, in one place.
            %
            % How it works:
            %   These fields are read-only mirrors of data the app already
            %   holds, so nothing below the app writes them and nothing reads
            %   them back for a decision. SetParameters seeds every property
            %   named here before calling this, which is the invariant that
            %   keeps a value from a previous dataset on screen; tests/tMirrors
            %   checks it.

            app.DataFileViewField.Value = char(app.td.dataFileName);
            app.TRViewField.Value = app.td.tr;
            app.TE0EditField.Value = app.td.te;
            app.ESPEditField.Value = app.td.esp;
            app.EchoesViewField.Value = app.td.ne;
            app.DynamicsViewField.Value = app.td.nd;
            app.DimxViewField.Value = app.td.dimx;
            app.DimyViewField.Value = app.td.dimy;
            app.SlicesViewField.Value = app.td.ns;
            app.SLTViewField.Value = app.td.sl;
            app.FOVViewField.Value = app.td.fov;
            app.AspectRatioViewField.Value = app.td.aspectRatio;

        end % MirrorDataOutputsFcn


        % Copy the reconstruction outputs to the fields that display them
        function MirrorRecoOutputsFcn(app)

            % Purpose:
            %   Puts the values a reconstruction can change into their display
            %   fields.
            %
            % How it works:
            %   Removing readout oversampling shortens the readout, so the
            %   matrix on screen is not the acquired one. Everything else a
            %   reconstruction leaves alone, and this runs after the crop.

            app.DimxViewField.Value = app.td.dimx;
            app.DimyViewField.Value = app.td.dimy;

        end % MirrorRecoOutputsFcn


        % Make the TE array
        function MakeTeArray(app)

            app.td.teSelection = [...
                app.CheckBox_1.Value  app.CheckBox_2.Value  app.CheckBox_3.Value  app.CheckBox_4.Value ...
                app.CheckBox_5.Value  app.CheckBox_6.Value  app.CheckBox_7.Value  app.CheckBox_8.Value ...
                app.CheckBox_9.Value  app.CheckBox_10.Value app.CheckBox_11.Value app.CheckBox_12.Value ...
                app.CheckBox_13.Value app.CheckBox_14.Value app.CheckBox_15.Value app.CheckBox_16.Value ...
                app.CheckBox_17.Value app.CheckBox_18.Value app.CheckBox_19.Value app.CheckBox_20.Value ...
                app.CheckBox_21.Value app.CheckBox_22.Value app.CheckBox_23.Value app.CheckBox_24.Value ...
                app.CheckBox_25.Value app.CheckBox_26.Value app.CheckBox_27.Value app.CheckBox_28.Value ...
                app.CheckBox_29.Value app.CheckBox_30.Value app.CheckBox_31.Value app.CheckBox_32.Value ...
                app.CheckBox_33.Value app.CheckBox_34.Value app.CheckBox_35.Value app.CheckBox_36.Value ...
                app.CheckBox_37.Value app.CheckBox_38.Value app.CheckBox_39.Value app.CheckBox_40.Value ...
                app.CheckBox_41.Value app.CheckBox_42.Value app.CheckBox_43.Value app.CheckBox_44.Value ...
                app.CheckBox_45.Value app.CheckBox_46.Value app.CheckBox_47.Value app.CheckBox_48.Value ...
                app.CheckBox_49.Value app.CheckBox_50.Value app.CheckBox_51.Value app.CheckBox_52.Value ...
                app.CheckBox_53.Value app.CheckBox_54.Value app.CheckBox_55.Value app.CheckBox_56.Value ...
                app.CheckBox_57.Value app.CheckBox_58.Value app.CheckBox_59.Value app.CheckBox_60.Value ...
                app.CheckBox_61.Value app.CheckBox_62.Value app.CheckBox_63.Value app.CheckBox_64.Value ...
                ];
            app.td.teSelection = [app.td.teSelection ones(1,1024)];

        end


        % Set default values for which echoes to include in fitting for spin-echo multi-echo
        function SetSpinEcho(app)

                app.CheckBox_1.Value = 0;  app.CheckBox_2.Value = 1;  app.CheckBox_3.Value = 1;  app.CheckBox_4.Value = 1; ...
                app.CheckBox_5.Value = 1;  app.CheckBox_6.Value = 1;  app.CheckBox_7.Value = 1;  app.CheckBox_8.Value = 1; ...
                app.CheckBox_9.Value = 1;  app.CheckBox_10.Value = 1; app.CheckBox_11.Value = 1; app.CheckBox_12.Value = 1; ...
                app.CheckBox_13.Value = 1; app.CheckBox_14.Value = 1; app.CheckBox_15.Value = 1; app.CheckBox_16.Value = 1; ...
                app.CheckBox_17.Value = 1; app.CheckBox_18.Value = 1; app.CheckBox_19.Value = 1; app.CheckBox_20.Value = 1; ...
                app.CheckBox_21.Value = 1; app.CheckBox_22.Value = 1; app.CheckBox_23.Value = 1; app.CheckBox_24.Value = 1; ...
                app.CheckBox_25.Value = 1; app.CheckBox_26.Value = 1; app.CheckBox_27.Value = 1; app.CheckBox_28.Value = 1; ...
                app.CheckBox_29.Value = 1; app.CheckBox_30.Value = 1; app.CheckBox_31.Value = 1; app.CheckBox_32.Value = 1; ...
                app.CheckBox_33.Value = 1; app.CheckBox_34.Value = 1; app.CheckBox_35.Value = 1; app.CheckBox_36.Value = 1; ...
                app.CheckBox_37.Value = 1; app.CheckBox_38.Value = 1; app.CheckBox_39.Value = 1; app.CheckBox_40.Value = 1; ...
                app.CheckBox_41.Value = 1; app.CheckBox_42.Value = 1; app.CheckBox_43.Value = 1; app.CheckBox_44.Value = 1; ...
                app.CheckBox_45.Value = 1; app.CheckBox_46.Value = 1; app.CheckBox_47.Value = 1; app.CheckBox_48.Value = 1; ...
                app.CheckBox_49.Value = 1; app.CheckBox_50.Value = 1; app.CheckBox_51.Value = 1; app.CheckBox_52.Value = 1; ...
                app.CheckBox_53.Value = 1; app.CheckBox_54.Value = 1; app.CheckBox_55.Value = 1; app.CheckBox_56.Value = 1; ...
                app.CheckBox_57.Value = 1; app.CheckBox_58.Value = 1; app.CheckBox_59.Value = 1; app.CheckBox_60.Value = 1; ...
                app.CheckBox_61.Value = 1; app.CheckBox_62.Value = 1; app.CheckBox_63.Value = 1; app.CheckBox_64.Value = 1;

                app.T2ViewFieldLabel.Text = 'T2 (ms)';
                app.T2MapLabel.Text = 'T2 map';

                app.td.gradEchoFlag = false;

        end


        % Set default values for which echoes to include in fitting for gradient-echo multi-echo
        function SetGradientEcho(app)

                app.SetSpinEcho;
                app.CheckBox_1.Value = 1;
                app.td.gradEchoFlag = true;

                app.T2ViewFieldLabel.Text = 'T2* (ms)';
                app.T2MapLabel.Text = 'T2* map';

        end


        % An automatic T2 scale
        function SetT2ScaleFcn(app, scale)

            % Purpose:
            %   Sets the T2 scale to run from 0 to the given maximum, as every
            %   automatic scaling does: during a fit and with the Auto button. The
            %   minimum goes back to 0 so the two ends are always in order.

            app.T2ScaleMinEditField.Value = 0;
            app.T2ScaleEditField.Value = scale;

        end % SetT2ScaleFcn


        % The reconstruction fields as the settings give them
        function SetRecoFieldsFromSettingsFcn(app)

            % Purpose:
            %   Puts the reconstruction fields back to the values the settings
            %   start them with: at start, and for every new dataset.

            recoDefaults = app.jsonPar.reconstruction;
            app.WVxyzEditField.Value = recoDefaults.wavelet;
            app.TVxyzEditField.Value = recoDefaults.tvSpatial;
            app.LLRxyzEditField.Value = recoDefaults.lowRank;
            app.TVteEditField.Value = recoDefaults.tvEcho;
            app.TVdynEditField.Value = recoDefaults.tvDynamic;
            app.DeNoiseCheckBox.Value = logical(recoDefaults.denoise);
            app.DeNoiseWindowEditField.Value = recoDefaults.denoiseWindow;
            app.DeRingCheckBox.Value = logical(recoDefaults.deRing);

        end % SetRecoFieldsFromSettingsFcn


        % Write a parameter set to the log
        function LogParamsFcn(app, label, params)

            % Purpose:
            %   Records exactly which parameters a reconstruction, a registration
            %   or a fit ran with, so that a result can be traced back to what
            %   produced it.
            %
            % How it works:
            %   One line of JSON, to the log only; the message pane is not the
            %   place for it.

            try
                if app.logFile > 2
                    fprintf(app.logFile, '%s  %s parameters: %s\n', char(datetime('now','Format','HH:mm:ss')), ...
                        label, jsonencode(params));
                end
            catch
            end

        end % LogParamsFcn


        % Shut the app down
        function CloseAppFcn(app)

            % Purpose:
            %   Shut the app down, whether the exit button or the window's close box was
            %   used, also while the app is still busy, and leave the MATLAB session as it
            %   was found.
            %
            % How it works:
            %   While app code is still running -- the startup, a step or a loop -- deleting
            %   the app would make that code fail on a deleted object. The close is then
            %   deferred: the window is hidden, what runs is asked to stop, and a timer
            %   finishes the close once no app code runs any more (DeferredCloseFcn).
            %
            %   Otherwise each step is guarded on its own, so a failure in one cannot leave
            %   the window open or the log unclosed: the log is closed, and the path, the
            %   folder and the warning state MATLAB had are put back.
            %
            %   The figure is deleted before the app. Deleting only the app from the close
            %   box left the figure registered as the running app, and the app could then
            %   not be started again. Both go before the app's folders leave the search
            %   path: deleting the figure runs the app's destructor, which MATLAB cannot
            %   find once they are gone. What the restoring needs is kept first.
            %
            % Inputs:
            %   app - the running app
            %
            % Output:
            %   none - the app is closed, or its close is scheduled

            % Still busy: stop the fit or registration and close once it has finished
            if app.AppCodeRunningFcn

                if ~app.closeRequested
                    app.closeRequested = true;
                    app.abortFitFlag = true;
                    app.abortRegFlag = true;
                    app.TextMessage('Closing when the current step has finished ...');
                    app.T2mappUIFigure.Visible = 'off';
                    app.closeTimer = timer('Name', 'T2mappCloseTimer', ...
                        'ExecutionMode', 'fixedSpacing', 'Period', 0.5, 'StartDelay', 0.5, ...
                        'TasksToExecute', 7200, ...
                        'TimerFcn', @(t,~) isvalid(app) && app.DeferredCloseFcn(t));
                    start(app.closeTimer);
                end

                return

            end

            % Close the log file
            try
                app.TextMessage('Closing T2mapp ...');
                if app.logFile > 2
                    fclose(app.logFile);
                end
                app.logFile = -1;
            catch
            end

            % Keep what the restoring needs; the app is deleted before it is used
            appRoot = app.appFolder;
            startFolder = app.oldFolder;
            startWarnings = app.oldWarningState;

            % Delete the figure, then the app, while the app folders are still on the
            % search path: deleting the figure runs the app's destructor
            try
                delete(app.T2mappUIFigure);
            catch
            end
            try
                delete(app);
            catch
            end

            % Remove the app folders from the search path, and return to the folder MATLAB
            % was in
            if ~isdeployed
                try
                    rmpath(genpath(appRoot));
                    cd(startFolder);
                catch
                end
            end

            % Restore the warning state MATLAB had
            try
                if ~isempty(startWarnings)
                    warning(startWarnings);
                end
            catch
            end

        end % CloseAppFcn


        % Finish a close that had to wait
        function done = DeferredCloseFcn(app, t)

            % Purpose:
            %   Complete a close that was requested while app code was still running.
            %
            % How it works:
            %   The close timer calls this twice a second. While app code still runs
            %   nothing else happens. Once it has stopped, one more tick is waited, so
            %   that the callback that ended the step has returned as well, and the app is
            %   then closed the normal way.
            %
            % Inputs:
            %   app - the app being closed
            %   t   - the close timer
            %
            % Output:
            %   done - always true, since the timer's function handle needs a value

            done = true;

            try

                % Keep the window hidden; a dialog that returned may have raised it
                app.T2mappUIFigure.Visible = 'off';

                % Still running: wait for the next tick
                if app.AppCodeRunningFcn
                    t.UserData = 0;
                    return
                end

                % Idle for the first time: wait one more tick
                if isempty(t.UserData) || t.UserData < 1
                    t.UserData = 1;
                    return
                end

                % Idle twice in a row: close
                stop(t);
                delete(t);
                app.closeTimer = [];
                app.CloseAppFcn;

            catch

                % A timer that fails is removed rather than left firing
                try
                    stop(t);
                    delete(t);
                catch
                end

            end

        end % DeferredCloseFcn


        % Check whether app code is running
        function running = AppCodeRunningFcn(app)

            % Purpose:
            %   Tell whether closing the app now would break code that is still running.
            %
            % How it works:
            %   Any method of the app still on the call stack -- a callback, the startup,
            %   a loop -- is running underneath the close request, or underneath the timer
            %   that asks. The closing methods themselves do not count, and neither do
            %   anonymous functions, whose names carry the method that created them.
            %
            %   Where the stack cannot be read, the BUSY status that every step sets is
            %   used instead.
            %
            % Inputs:
            %   app - the running app
            %
            % Output:
            %   running - true while app code other than the closing methods runs

            running = app.busyFlag;

            % The methods that do the closing
            closing = ["CloseAppFcn", "DeferredCloseFcn", "AppCodeRunningFcn", ...
                "ExitButtonPushed", "UIFigureCloseRequest"];

            % The app's own methods on the call stack
            try
                names = string({dbstack.name});
                mine = startsWith(names, "T2mapp.");
                if any(mine)
                    running = any(mine & ~contains(names, "@(") & ~contains(names, closing));
                end
            catch
            end

        end % AppCodeRunningFcn


    end % methods


    % ----------------------------------------------
    %
    % Public functions, available outside the app
    %
    % ----------------------------------------------


    methods (Access = public)


        % Text messages in the app
        function TextMessage(app, message)

            % Every message goes to the log as well, with the time of day, in its
            % own guard so that it reaches the file even when the pane cannot take
            % it, as while the interface is being torn down
            try
                if app.logFile > 2
                    fprintf(app.logFile, '%s  %s\n', char(datetime('now','Format','HH:mm:ss')), ...
                        char(strjoin(string(message), ' ')));
                end
            catch
            end

            try
                app.MessageBox.Value = [string(message) ; string(app.MessageBox.Value)];
                app.MessageBox.Value = app.MessageBox.Value(1:min(50,end),1);
                drawnow;
            catch
            end

        end % TextMessage


        % Status icon
        function SetStatus(app, status)

            % BUSY marks a running step until OK or ERROR ends it; a WARNING leaves the
            % flag as it was. A close request waits for the flag to clear
            if status == 3
                app.busyFlag = true;
            elseif status == 0 || status == 2
                app.busyFlag = false;
            end

            switch status

                case 0
                    app.StatusImage.ImageSource = 'statusokay.gif';
                    app.StatusLabel.FontColor = app.green;
                    app.StatusLabel.FontWeight = 'bold';
                    app.StatusLabel.FontSize = 14;
                    app.StatusLabel.Text = 'OK';

                case 1
                    app.StatusImage.ImageSource = 'statuswarning.gif';
                    app.StatusLabel.FontColor = app.orange;
                    if app.AutoSoundsCheckBox.Value
                        sound(app.warningSound,48000);
                    end
                    app.StatusLabel.FontWeight = 'bold';
                    app.StatusLabel.FontSize = 11;
                    app.StatusLabel.Text = 'WARNING';

                case 2
                    app.StatusImage.ImageSource = 'statuserror.gif';
                    app.StatusLabel.FontColor = app.brightred;
                    if app.AutoSoundsCheckBox.Value
                        sound(app.errorSound,48000);
                    end
                    app.StatusLabel.FontWeight = 'bold';
                    app.StatusLabel.FontSize = 12;
                    app.StatusLabel.Text = 'ERROR';

                case 3
                    app.StatusImage.ImageSource = 'busy.gif';
                    app.StatusLabel.FontColor = app.blue;
                    app.StatusLabel.FontWeight = 'bold';
                    app.StatusLabel.FontSize = 12;
                    app.StatusLabel.Text = 'BUSY';

            end

            drawnow;

        end


    end % methods (Access = public)


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app)

            % The window is already showing, so closing it during the startup waits for
            % the startup to finish, as it does during any other step
            app.busyFlag = true;
            app.T2mappUIFigure.CloseRequestFcn = @(~,~) app.CloseAppFcn;

            % Disable unnecessary warnings
            clc;
            app.oldWarningState = warning;   % put back when the app closes
            warning off;

            % Set app theme to light
            theme(app.T2mappUIFigure,"light")

            % Main window noname
            app.T2mappUIFigure.Name = '';

            % Empty file import and export folders
            app.mrdImportFolder = '';
            app.dicomExportFolder = '';
            app.gifExportFolder = '';
            app.roiExportFolder = '';
            app.mrdFlag = true;

            % Add subfolders to search folder
            if ~isdeployed
                app.oldFolder = pwd;
                info = dir(which('T2mapp.mlapp'));
                app.appFolder = info.folder;
                cd(app.appFolder);
                addpath(genpath(pwd));
                addpath(genpath(strcat(pwd,filesep,"functions")));
                addpath(genpath(strcat(pwd,filesep,"gui")));

                % Retired copies live in backup/ and must not be reachable.
                % genpath walks folders in alphabetical order, so backup comes
                % before functions and an old .p there would be found first --
                % the app would silently run code it no longer holds a source
                % for.
                if isfolder(fullfile(pwd,'backup'))
                    rmpath(genpath(fullfile(pwd,'backup')));
                end
            end

            % The dataset this session works on. Created here rather than
            % earlier because @t2Data sits beside the app, so the class is only
            % reachable once the paths above are set.
            app.td = t2Data;
            app.td.data3dFlag = false;
            app.td.p2roudFlag = false;
            app.td.gradEchoFlag = false;

            % The log first, so that anything that goes wrong from here on is
            % recorded, then the settings the interface starts from. Neither
            % raises: without a log the session is not logged, and a damaged
            % settings file is repaired or replaced.
            [app.logFileName, app.logFile] = t2mapp.io.makeLogFile(app.appVersion, AppReporter(app));
            app.TextMessage(strcat("T2mapp ", app.appVersion, ", log file = ", app.logFileName, " ..."));
            app.jsonPar = t2mapp.io.readSettingsFile(app.appVersion, AppReporter(app));
            app.mrdImportFolder = app.jsonPar.mrdImportPath;
            app.dicomExportFolder = app.jsonPar.defaultExportPath;
            app.gifExportFolder = app.jsonPar.defaultExportPath;
            app.roiExportFolder = app.jsonPar.defaultExportPath;

            % Initialize user interface
            app.InitializeUI;

            % Set app colors
            app.ColorsButtonGroupSelectionChanged;

            % PC or MAC
            try
                if ismac()
                    [~,result] = system('uname -v');
                    is_m1_mac = any(strfind(result,'ARM64'));
                    if is_m1_mac
                        app.TextMessage('Apple arm64 version');
                    else
                        app.TextMessage('Apple Intel version');
                    end
                else
                    app.TextMessage('PC Intel version');
                end
            catch ME
                app.TextMessage(ME.message);
            end

            % Elastix
            %
            % The binary is located with t2mapp.util.resourcePath rather than which(),
            % because a COMPILED APP HAS NO MATLAB PATH and which() returns empty there.
            % The separator is pathsep, not a hard-coded semicolon, which was wrong on
            % mac and linux.
            %
            % On mac and linux /usr/local/bin is still added, because the shipped binary
            % cannot be used from its own folder: macOS strips DYLD_LIBRARY_PATH from
            % processes it spawns, so elastix cannot find its libraries unless they are
            % installed. See install.txt beside the binaries.
            try
                if ismac || isunix
                    result = getenv('PATH');
                    for extra = {'/usr/local/bin','/usr/local/lib'}
                        if ~contains(result, extra{1})
                            result = [result pathsep extra{1}];
                        end
                    end
                    setenv('PATH', result);
                else
                    elastixExe = t2mapp.util.resourcePath('elastix.exe');
                    if ~isempty(elastixExe)
                        elastixFolder = fileparts(elastixExe);
                        result = getenv('PATH');
                        if ~contains(result, elastixFolder)
                            setenv('PATH', [result pathsep elastixFolder]);
                        end
                    end
                end
            catch ME
                app.TextMessage(ME.message);
            end

            % Report once, at startup, whether elastix can actually be run. It used to
            % be discovered per slice inside a catch block during a registration, which
            % told the user only after they had waited for it.
            app.elastixDetectedFlag = false;
            try
                % Through useBundledElastix, so the copy that ships with the app is
                % found. On macOS it cannot be reached by putting its folder on the
                % PATH: SIP strips DYLD_* when /bin/sh is executed, so the library
                % path has to be set inside the command string itself.
                probe = t2mapp.util.useBundledElastix('elastix --version');
                [elastixStatus, elastixReply] = system(probe);
                app.elastixDetectedFlag = elastixStatus == 0 && contains(elastixReply,'version');
            catch
            end

            if app.elastixDetectedFlag
                app.TextMessage('Elastix detected ...');
            else
                app.TextMessage('WARNING: elastix not available; registration will use Matlab imregister ...');
            end

            % Determine if BART is available by requesting its version number
            app.bartDetectedFlag = false;
            try
                % strtrim because bart returns the version with a trailing
                % newline, which would otherwise break the message line and
                % show up in anything that echoes the version back.
                app.bartVersion = strtrim(bart(AppReporter(app),'version'));
                if ~contains(app.bartVersion,'none')
                    app.bartDetectedFlag = true;
                end
            catch ME
                app.TextMessage(ME.message);
            end

            if app.bartDetectedFlag
                app.TextMessage('BART toolbox detected, ' + string(app.bartVersion) + ' ...');

                % The model-based fits need a BART new enough that moba -T means
                % the Fast Spin Echo T2 model. On 0.9.00 -T is a damping float and
                % the same command line quietly fits something else, so an old
                % BART is refused rather than trusted.
                if ~t2mapp.util.bartVersionAtLeast(app.bartVersion)
                    app.bartDetectedFlag = false;
                    app.TextMessage("WARNING: BART " + string(app.bartVersion) + ...
                        " is older than the required " + t2mapp.Constants.MIN_BART_VERSION + " ...");
                    app.TextMessage('WARNING: model-based fitting is disabled ...');
                    app.SetStatus(1);
                end

            else
                app.TextMessage('WARNING: BART toolbox not available ...');
                app.SetStatus(1);
            end

            % Check if Bart was compiled with GPU support
            app.GPUpresentFlag = false;
            if app.bartDetectedFlag
                try
                    rec = bart(AppReporter(app),'pics -l2 -g ',[0 1 0 ; 1 1 1 ; 0 1 0],[1 1 1 ; 1 1 1 ; 1 1 1]); %#ok<NASGU>
                    app.TextMessage('BART compiled with GPU support ...');
                    app.GPUpresentFlag = true;
                catch
                    app.TextMessage('BART compiled without GPU support ...');
                end
            end

            % Old logs, now that the number to keep is known
            t2mapp.io.pruneLogFiles(app.logFileName, app.jsonPar.logFilesToKeep, AppReporter(app));

            % Check version
            app.CheckVersionFnc;

            % Start the parallel pool if it is not running already
            try
                numCores = feature('numcores');
                p = gcp('nocreate');
                if isempty(p) && app.jsonPar.parallelPool
                    app.TextMessage("Starting the parallel pool ...");
                    if numCores >= 8
                        parpool(8);
                    else
                        parpool(numCores);
                    end
                end
            catch ME
                app.TextMessage(ME.message);
            end

            % OK
            app.SetStatus(0);
            app.TextMessage("Ready ...");
            app.SetButtons([1,0,0,0,0,0,0,1]);

        end

        % Selection changed function: ColorsButtonGroup
        function ColorsButtonGroupSelectionChanged(app, event)

            selectedButton = app.ColorsButtonGroup.SelectedObject;

            switch lower(selectedButton.Text)

                % COLORS:           backgroundColor, fontColor, boxColor, gaugeColor, gaugeBackgroundColor, figureColor, movieFigureColor, gridColor, autoRecoColor, manualRecoColor

                case "classic"

                    guiColors = {app.guiGrey, app.guiBlack, app.guiDarkGrey, app.blue, app.guiWhite, app.guiWhite, app.guiMiddleGrey, app.guiDarkGrey, app.lightblue, app.guiGrey};

                case "black/white"

                    guiColors = {app.guiBlack, app.guiWhite, app.guiDarkGrey, app.blue, app.guiDarkGrey, app.guiGrey, app.guiDarkGrey, app.guiDarkGrey, app.guiBlackBlue, app.guiBlack};

            end

            app.SetAppColorsFcn(guiColors);

        end

        % Button pushed function: LoadMRDfileButton
        function LoadMRDfileButtonPushed(app, event)

            % Load the T2 or T2* data file

            % What a cancelled file dialog puts back: the lamps and the dashboard
            % buttons as they were
            previous.lamps = {app.Lamp1.Color, app.Lamp2.Color, app.Lamp3.Color, app.Lamp4.Color, ...
                app.Lamp5.Color, app.Lamp6.Color, app.Lamp7.Color};
            previous.buttons = double(cellfun(@(b) b.Enable == 'on', {app.LoadMRDfileButton, app.RecoimagesButton, ...
                app.RegisterButton, app.MaskButton, app.FitT2Button, app.DCMNIFTIButton, app.GIFButton, app.ExitButton}));

            % Indicate busy
            app.SetLights([3,0,0,0,0,0,0]);
            app.SetButtons([0,0,0,0,0,0,0,0]);
            app.SetFitButtons(0);
            app.SetImageSelectionButtons(0);
            app.SetSegmentationButtons(0);
            app.SetMapButtons(0);
            app.SetStatus(3);

            % check standard smis folder first if platform is pc, only 1x per session
            if ispc
                try
                    folderName = winqueryreg('HKEY_LOCAL_MACHINE','Software\MRS\MRSClinicalUI\Scan','MrdDirectory');
                    folderName = [folderName,filesep];
                    app.TextMessage('MRD folder found in Windows registry ...');
                    app.TextMessage(strcat("MRD folder = ",folderName," ..."));
                catch
                    app.TextMessage('Trying standard MRD folder ...');
                    folderName = 'c:\smis\dev\MRD\';
                end
                if exist(folderName, 'dir') && app.mrdFlag && isempty(app.jsonPar.mrdImportPath)
                    app.mrdImportFolder = folderName;
                    app.mrdFlag = false;
                end
            end

            try

                % Select a data file, before anything is reset, so that cancelling the
                % dialog leaves the current dataset as it is
                f = figure('CloseRequestFcn','','Position',[-100 -100 0 0]); % Needed to bring uigetfile in front
                [mrdFile, mrdFolder] = uigetfile(strcat(app.mrdImportFolder,filesep,{'*.mrd;*.job0;*.*'}));
                delete(f);
                if ~app.closeRequested
                    figure(app.T2mappUIFigure);
                end

                if ~ischar(mrdFile)
                    ME = MException('T2mapp:UserCancel','User cancel ...');
                    throw(ME);
                end
                app.td.mrdFile = mrdFile;
                app.mrdImportFolder = mrdFolder;

                % Reset flags
                app.validDataFlag = false;
                app.validRecoFlag = false;
                app.validFitFlag = false;
                app.td.validWaterFatFlag = false;
                app.td.data3dFlag = false;
                app.td.p2roudFlag = false;
                app.WaterFatCheckBox.Value = 0;

                if contains(app.td.mrdFile,"MRD")

                    % MR Solutions data
                    app.TextMessage('Loading MRD k-space data ...');
                    validfile = app.td.importMRD(app.mrdImportFolder, AppReporter(app));
                    app.td.dataFileName = app.td.mrdFile;

                    if ~validfile
                        ME = MException('T2mapp:InvalidFile','Not a valid MRD file ...');
                        throw(ME);
                    end

                    switch app.td.dataType
                        case "grad-echo"
                            app.SetGradientEcho;
                        case "spin-echo"
                            app.SetSpinEcho;
                    end

                elseif contains(app.td.mrdFile,"fid")

                    % B-type scanner data
                    app.TextMessage('Loading b-type k-space data ...');
                    app.td.importBruker(app.mrdImportFolder);

                    if strcmp(app.td.dataType,"grad-echo")
                        app.SetGradientEcho;
                    end

                else

                    ME = MException('T2mapp:InvalidFile','Not a valid data file ...');
                    throw(ME);

                end

                app.SetParameters;

                % Reset buttons
                app.validDataFlag = true;
                app.SetFitButtons(1);
                app.SetLights([1,0,0,0,0,0,0]);
                app.SetButtons([1,1,0,0,0,0,0,1]);
                app.SetStatus(0);

                % Do a fast FFT reconstruction
                app.CSrecoCheckBox.Value = 0;
                app.RecoImagesButtonPushed;

                % Show the images
                PlotImageFig(app);

            catch ME

                if strcmpi(ME.identifier, 'T2mapp:UserCancel')

                    % Cancelling the file dialog changes nothing: the dataset, its flags,
                    % the lamps and the buttons are as they were
                    app.TextMessage('User cancel ...');
                    for k = 1:numel(previous.lamps)
                        app.("Lamp" + k).Color = previous.lamps{k};
                    end
                    app.SetButtons(previous.buttons);
                    app.SetFitButtons(double(app.validDataFlag));
                    app.SetImageSelectionButtons(double(app.validRecoFlag));
                    app.SetSegmentationButtons(double(app.validRecoFlag));
                    app.SetMapButtons(double(app.validFitFlag));
                    app.SetStatus(0);

                else

                    % Something went wrong during loading of the data
                    app.TextMessage(ME.message);
                    app.TextMessage('ERROR: Something went wrong while loading the data ...');
                    app.validDataFlag = false;
                    app.validRecoFlag = false;
                    app.validRegFlag = false;
                    app.validFitFlag = false;
                    app.SetFitButtons(0);
                    app.SetLights([0,0,0,0,0,0,0]);
                    app.SetButtons([1,0,0,0,0,0,0,1]);
                    app.SetStatus(2);

                end

            end

        end

        % Value changed function: ESPEditField, TE0EditField
        function TEViewFieldValueChanged(app, event)

            app.td.te = app.TE0EditField.Value;
            app.td.parameters.te = app.TE0EditField.Value;
            app.td.esp = app.ESPEditField.Value;
            app.td.tes = app.td.te + app.td.esp*linspace(0,app.td.ne-1,app.td.ne)'; % echo times
            tetxt = num2str(app.td.tes');
            for i = 1:10
                tetxt = strrep(tetxt,"  "," ");
            end
            tetxt = strrep(tetxt," "," : ");
            app.TextMessage(strcat("Echo times = ",tetxt," ms ..."));

            % Maps fitted with the previous echo times no longer match them
            if app.validFitFlag
                app.validFitFlag = false;
                app.td.validWaterFatFlag = false;
                app.TextMessage('WARNING: the echo times changed, fit the maps again ...');
                app.SetLights([2,2,2,2,0,0,0]);
                app.DCMNIFTIButton.Enable = 'off';
                app.GIFButton.Enable = 'off';
                app.SetMapButtons(0);
            end

        end

        % Button pushed function: RecoimagesButton
        function RecoImagesButtonPushed(app, event)

            if app.validDataFlag

                % What a failure puts back: the images, the readout length and the maps
                % the reconstruction resets, the flags that describe them, and the lamps
                previous.images = app.td.images;
                previous.complexImages = app.td.complexImages;
                previous.dimx = app.td.dimx;
                previous.m0map = app.td.m0map;
                previous.t2map = app.td.t2map;
                previous.r2map = app.td.r2map;
                previous.watermap = app.td.watermap;
                previous.fatmap = app.td.fatmap;
                previous.mask = app.td.mask;
                previous.validWaterFatFlag = app.td.validWaterFatFlag;
                previous.flags = [app.validRecoFlag, app.validRegFlag, app.validFitFlag];
                previous.lamps = {app.Lamp1.Color, app.Lamp2.Color, app.Lamp3.Color, app.Lamp4.Color, ...
                    app.Lamp5.Color, app.Lamp6.Color, app.Lamp7.Color};

                % Indicate busy
                app.SetLights([1,3,0,0,0,0,0]);
                app.SetButtons([0,0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.SetMapButtons(0);
                app.SetStatus(3);
                app.TabGroup2.SelectedTab = app.RawImagesTab;

                try

                    % reset some parameters
                    app.SlicesEditField.Value = 1;
                    app.SlicesSlider.Value = 1;
                    app.EchoesEditField.Value = 1;
                    app.EchoesSlider.Value = 1;
                    app.ThresholdEditField.Value = 1;
                    app.ThresholdSlider.Value = 1;
                    app.T2MapSlicesEditField.Value = 1;
                    app.T2MapSlicesSlider.Value = 1;
                    app.M0MapSlicesEditField.Value = 1;
                    app.M0MapSlicesSlider.Value = 1;
                    app.DynamicsEditField.Value = 1;
                    app.T2MapDynamicsEditField.Value = 1;
                    app.M0MapDynamicsEditField.Value = 1;
                    app.DynamicsSlider.Value = 1;
                    app.T2MapDynamicsSlider.Value = 1;
                    app.M0MapDynamicsSlider.Value = 1;

                    % The reconstruction works on the acquired readout length, which a
                    % previous run may have shortened when it removed oversampling.
                    app.td.dimx = app.td.parameters.NO_SAMPLES;

                    % reset m0 and t2map
                    app.td.m0map = zeros(app.td.dimx,app.td.dimy,app.td.ns,app.td.nd);
                    app.td.t2map = zeros(app.td.dimx,app.td.dimy,app.td.ns,app.td.nd);
                    app.td.r2map = zeros(app.td.dimx,app.td.dimy,app.td.ns,app.td.nd);
                    app.td.mask = ones(app.td.dimx,app.td.dimy,app.td.ns,app.td.nd);

                    % One struct describes this reconstruction, gathered once, so that
                    % nothing below the app reads a widget.
                    recoParams = gatherRecoParams(app);
                    app.LogParamsFcn('reconstruction', recoParams);

                    if recoParams.csReco

                        % Perform the BART compressed sensing reconstruction
                        app.TextMessage('CS reconstruction ...');
                        [app.td.images, app.td.complexImages] = t2mapp.reco.CSreco(app.td, recoParams, AppReporter(app));

                    else

                        % Perform the FFT reconstruction
                        app.TextMessage('FFT reconstruction ...');
                        [app.td.images, app.td.complexImages] = t2mapp.reco.fftReco(app.td);

                    end

                    % Readout oversampling. The MRD carries this as ":VAR oversample",
                    % which the PPR parser puts straight into the parameter struct.
                    % Everything sized from the readout follows the crop: complexImages
                    % feeds the water-fat separation, and the maps and the mask were
                    % laid out from the acquired length before the reconstruction ran.
                    if isfield(app.td.parameters,"oversample")
                        if app.td.parameters.oversample == 1
                            app.TextMessage('Readout oversampling ...');
                            os = round(size(app.td.images,2)/4);
                            app.td.images = app.td.images(:,os+1:end-os,:,:,:);
                            if ~isempty(app.td.complexImages)
                                app.td.complexImages = app.td.complexImages(:,os+1:end-os,:,:,:,:);
                            end
                            app.td.dimx = size(app.td.images,2);
                            app.MirrorRecoOutputsFcn;
                            app.td.m0map = zeros(app.td.dimx,app.td.dimy,app.td.ns,app.td.nd);
                            app.td.t2map = zeros(app.td.dimx,app.td.dimy,app.td.ns,app.td.nd);
                            app.td.r2map = zeros(app.td.dimx,app.td.dimy,app.td.ns,app.td.nd);
                            app.td.watermap = zeros(app.td.dimx,app.td.dimy,app.td.ns,app.td.nd);
                            app.td.fatmap = zeros(app.td.dimx,app.td.dimy,app.td.ns,app.td.nd);
                            app.td.mask = ones(app.td.dimx,app.td.dimy,app.td.ns,app.td.nd);
                        end
                    end

                    % Denoising
                    if recoParams.denoise
                        app.TextMessage('PCA denoising ...');
                        app.td.images = t2mapp.reco.PCAdenoise(app.td.images,recoParams.denoiseWindow);
                    end

                    % Gibbs ringing suppression
                    if recoParams.deRing
                        app.TextMessage('Gibbs ringing suppression ...');
                        app.td.images = unRing(app.td.images);
                    end

                    % Normalize to convenient range
                    app.td.images = app.td.images*32767/max(app.td.images(:));

                    % Success
                    app.TextMessage('Ready ...');
                    app.validRecoFlag = true;
                    app.validRegFlag = false;
                    app.validFitFlag = false;

                    % Show the images
                    app.SlicesEditField.Value = round(app.td.ns/2);
                    app.SlicesSlider.Value = round(app.td.ns/2);
                    PlotImageFig(app);
                    app.PlotMaps;

                    % Reset buttons
                    app.SetLights([1,1,0,0,0,0,0]);
                    app.SetButtons([1,1,1,1,1,0,0,1]);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetFitButtons(1);
                    app.SetStatus(0);

                catch ME

                    % Failure: everything back as it was before the step, so an earlier
                    % reconstruction, registration and fit stay usable
                    app.TextMessage(ME.message);
                    app.TextMessage('ERROR: Something went wrong during the reconstruction ...');
                    app.td.images = previous.images;
                    app.td.complexImages = previous.complexImages;
                    app.td.dimx = previous.dimx;
                    app.td.m0map = previous.m0map;
                    app.td.t2map = previous.t2map;
                    app.td.r2map = previous.r2map;
                    app.td.watermap = previous.watermap;
                    app.td.fatmap = previous.fatmap;
                    app.td.mask = previous.mask;
                    app.td.validWaterFatFlag = previous.validWaterFatFlag;
                    app.validRecoFlag = previous.flags(1);
                    app.validRegFlag = previous.flags(2);
                    app.validFitFlag = previous.flags(3);
                    for k = 1:numel(previous.lamps)
                        app.("Lamp" + k).Color = previous.lamps{k};
                    end
                    app.Lamp2.Color = app.red;

                    try
                        app.MirrorRecoOutputsFcn;
                        if app.validRecoFlag
                            PlotImageFig(app);
                        end
                        app.PlotMaps;
                    catch
                    end

                    reco = double(app.validRecoFlag);
                    fitted = double(app.validFitFlag);
                    app.SetButtons([1,1,reco,reco,reco,fitted,fitted,1]);
                    app.SetImageSelectionButtons(reco);
                    app.SetSegmentationButtons(reco);
                    app.SetFitButtons(1);
                    app.SetMapButtons(fitted);
                    app.SetStatus(2);


                end

            end

        end

        % Value changed function: DynamicsEditField, EchoesEditField, 
        % ...and 1 other component
        function ImageSelectionEditFieldValueChanged(app, event)

            % Adjust the slider
            app.SlicesSlider.Value = app.SlicesEditField.Value;
            app.EchoesSlider.Value = app.EchoesEditField.Value;
            app.DynamicsSlider.Value = app.DynamicsEditField.Value;

            % Plot the raw image
            PlotImageFig(app);

        end

        % Value changed function: DynamicsSlider, EchoesSlider, 
        % ...and 1 other component
        function ImageSelectionSliderValueChanged(app, event)

            % Adjust the slices edit field value
            app.SlicesEditField.Value = round(app.SlicesSlider.Value);
            app.EchoesEditField.Value = round(app.EchoesSlider.Value);
            app.DynamicsEditField.Value = round(app.DynamicsSlider.Value);

            % Plot the raw image
            PlotImageFig(app);

        end

        % Button pushed function: RegisterButton
        function RegisterButtonPushed(app, event)

            if app.validRecoFlag

                % What a failure puts back: the images and everything made from them,
                % the flags that describe them, and the lamps
                previous.images = app.td.images;
                previous.complexImages = app.td.complexImages;
                previous.m0map = app.td.m0map;
                previous.t2map = app.td.t2map;
                previous.r2map = app.td.r2map;
                previous.mask = app.td.mask;
                previous.threshold = app.td.threshold;
                previous.validRegFlag = app.validRegFlag;
                previous.validFitFlag = app.validFitFlag;
                previous.lamps = {app.Lamp1.Color, app.Lamp2.Color, app.Lamp3.Color, app.Lamp4.Color, ...
                    app.Lamp5.Color, app.Lamp6.Color, app.Lamp7.Color};

                app.validRegFlag = false;
                app.validFitFlag = false;

                try

                    % Indicate busy
                    app.SetLights([1,1,3,0,0,0,0]);
                    app.SetButtons([0,0,0,0,0,0,0,0]);
                    app.SetFitButtons(0);
                    app.SetImageSelectionButtons(0);
                    app.SetSegmentationButtons(0);
                    app.SetMapButtons(0);
                    app.SetStatus(3);
                    app.abortRegFlag = false;
                    app.RegAbortButton.Enable = 'on';

                    % Register all slices to 1st echo time image
                    regParams = gatherRegParams(app);
                    app.LogParamsFcn('registration', regParams);

                    % The BART registration has been removed: it was never reachable -- this
                    % dispatch was commented out -- and it carried two defects of its own, a
                    % B-Spline that silently ran as affine and a fallback that masked the
                    % moving image with the fixed image's support.
                    %
                    % usedFallback says whether elastix actually did the work, so the message
                    % below names the transform that RAN rather than the one that was asked for.
                    % complexImages goes in as well, so it is moved by the SAME transform.
                    % Registration used to replace the magnitudes only, while the water-fat
                    % separation reads complexImages -- so a water-fat map made after a
                    % registration was computed on unregistered data.
                    [app.td.images, usedFallback, registeredComplex] = t2mapp.reco.registerImages( ...
                        app.td.images, regParams, AppReporter(app,'registration'), app.td.complexImages);

                    if ~isempty(registeredComplex)
                        app.td.complexImages = registeredComplex;
                    end

                    if usedFallback
                        app.TextMessage("WARNING: elastix was not available; registration used Matlab imregister ...");
                    end

                    % reset m0, t2map, threshold
                    app.td.m0map = zeros(app.td.dimx,app.td.dimy,app.td.ns,app.td.nd);
                    app.td.t2map = zeros(app.td.dimx,app.td.dimy,app.td.ns,app.td.nd);
                    app.td.r2map = zeros(app.td.dimx,app.td.dimy,app.td.ns,app.td.nd);
                    app.td.mask = ones(app.td.dimx,app.td.dimy,app.td.ns,app.td.nd);
                    app.td.threshold = zeros(app.td.ns,app.td.nd);
                    app.validRegFlag = true;

                    % replot the images
                    app.PlotImageFig;
                    app.PlotMaps;

                    % Reset buttons
                    app.SetImageSelectionButtons(1);
                    app.SetFitButtons(1);
                    app.RegAbortButton.Enable = 'off';
                    app.SetLights([1,1,1,0,0,0,0]);
                    app.SetButtons([1,1,1,1,1,0,0,1]);
                    app.SetStatus(0);

                catch ME

                    app.TextMessage(ME.message);
                    app.TextMessage('ERROR: Something went wrong during registration ...');

                    % Everything back as it was before the step, so a registration that
                    % failed halfway leaves nothing half done, and the steps whose results
                    % are intact stay usable
                    app.td.images = previous.images;
                    app.td.complexImages = previous.complexImages;
                    app.td.m0map = previous.m0map;
                    app.td.t2map = previous.t2map;
                    app.td.r2map = previous.r2map;
                    app.td.mask = previous.mask;
                    app.td.threshold = previous.threshold;
                    app.validRegFlag = previous.validRegFlag;
                    app.validFitFlag = previous.validFitFlag;
                    for k = 1:numel(previous.lamps)
                        app.("Lamp" + k).Color = previous.lamps{k};
                    end
                    app.Lamp3.Color = app.red;

                    try
                        app.PlotImageFig;
                        app.PlotMaps;
                    catch
                    end

                    fitted = double(app.validFitFlag);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetFitButtons(1);
                    app.SetMapButtons(fitted);
                    app.RegAbortButton.Enable = 'off';
                    app.SetButtons([1,1,1,1,1,fitted,fitted,1]);
                    app.SetStatus(2);

                end

            end

        end

        % Button pushed function: RegAbortButton
        function RegAbortButtonPushed(app, event)

            app.abortRegFlag = true;
            drawnow;

        end

        % Button pushed function: MaskButton
        function MaskButtonPushed(app, event)

            if app.validRecoFlag

                % Indicate busy
                app.SetLights([1,1,2,3,0,0,0]);
                app.SetButtons([0,0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.SetMapButtons(0);
                app.SetStatus(3);
                app.TextMessage('Thresholding images ....');

                % A failure halfway would leave a mask that no longer matches its
                % thresholds, so both are put back as they were
                previousMask = app.td.mask;
                previousThreshold = app.td.threshold;

                try

                    % Determine automatic threshold using the Otsu method for each slice
                    for dynamic = 1:app.td.nd
                        for slice = 1:app.td.ns
                            maxim = squeeze(app.td.images(1,:,:,slice,dynamic));
                            app.td.threshold(slice,dynamic) = graythresh(mat2gray(app.td.images(1,:,:,slice,dynamic))) * max(maxim(:));
                        end
                    end

                    % Set the automatic mask for all slices
                    app.td.mask = ones(app.td.dimx,app.td.dimy,app.td.ns);
                    for dynamic = 1:app.td.nd
                        for slice = 1:app.td.ns
                            app.td.mask(:,:,slice,dynamic) = t2mapp.fit.apply_threshold_t2(squeeze(app.td.images(1,:,:,slice,dynamic)),app.td.threshold(slice,dynamic));
                        end
                    end

                    % Display the threshold value for the current slice
                    app.ThresholdEditField.Value = app.td.threshold(app.SlicesEditField.Value,app.DynamicsEditField.Value);

                    % Display the mask in the image
                    PlotImageFig(app);

                    % Indicate busy
                    app.SetLights([1,1,2,1,0,0,0]);
                    app.SetButtons([1,1,1,1,1,0,0,1]);
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetMapButtons(0);
                    app.SetStatus(0);

                catch ME

                    % The images and any registration are kept: the mask and
                    % thresholds go back to what they were and the step reports
                    % the failure, instead of the reconstruction being redone
                    app.td.mask = previousMask;
                    app.td.threshold = previousThreshold;
                    app.TextMessage(ME.message);
                    app.TextMessage('ERROR: Something went wrong while thresholding the images ...');
                    app.SetLights([1,1,2,0,0,0,0]);
                    app.SetButtons([1,1,1,1,1,0,0,1]);
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetMapButtons(double(app.validFitFlag));
                    app.SetStatus(2);

                end

            end

        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)

            % A failure halfway would leave a mask that is partly changed, so the mask
            % and thresholds are put back as they were
            previousMask = app.td.mask;
            previousThreshold = app.td.threshold;

            try

                % Change the value of the threshold for all slices and update the slider
                for slice = 1:app.td.ns
                    app.td.threshold(slice,:) = app.ThresholdEditField.Value;
                end
                app.ThresholdSlider.Value = app.ThresholdEditField.Value;

                % Apply the new threshold
                for dynamic = 1:app.td.nd
                    for slice = 1:app.td.ns
                        app.td.mask(:,:,slice,dynamic) = t2mapp.fit.apply_threshold_t2(squeeze(app.td.images(1,:,:,slice,dynamic)),app.td.threshold(slice,dynamic));
                    end
                end

                % Display the result;
                PlotImageFig(app)

            catch ME

                app.td.mask = previousMask;
                app.td.threshold = previousThreshold;
                app.TextMessage(ME.message);
                app.TextMessage('ERROR: the mask could not be changed ...');
                app.SetStatus(2);

            end

        end

        % Value changed function: ThresholdSlider
        function ThresholdSliderValueChanged(app, event)

            % Threshold slider changed
            % Adjust Threshold and call threshold change button
            app.ThresholdEditField.Value = app.ThresholdSlider.Value;
            ThresholdEditFieldValueChanged(app, event);

        end

        % Button pushed function: ErodeButton
        function ErodeButtonPushed(app, event)

            % A failure halfway would leave a mask that is partly changed, so the mask
            % and thresholds are put back as they were
            previousMask = app.td.mask;
            previousThreshold = app.td.threshold;

            try

                % Morphological erosion on current slice
                for dynamic = 1:app.td.nd
                    if app.td.data3dFlag
                        app.td.mask(:,:,:,dynamic) = 1-imerode(1-squeeze(app.td.mask(:,:,:,dynamic)),app.structuringElement3D);
                    else
                        for slice = 1:app.td.ns
                            app.td.mask(:,:,slice,dynamic) = 1-imerode(1-squeeze(app.td.mask(:,:,slice,dynamic)),app.structuringElement2D);
                        end
                    end
                end

                % Display the result;
                PlotImageFig(app);

            catch ME

                app.td.mask = previousMask;
                app.td.threshold = previousThreshold;
                app.TextMessage(ME.message);
                app.TextMessage('ERROR: the mask could not be changed ...');
                app.SetStatus(2);

            end

        end

        % Button pushed function: DilateButton
        function DilateButtonPushed(app, event)

            % A failure halfway would leave a mask that is partly changed, so the mask
            % and thresholds are put back as they were
            previousMask = app.td.mask;
            previousThreshold = app.td.threshold;

            try

                % Morphological dilation on current slice
                for dynamic = 1:app.td.nd
                    if app.td.data3dFlag
                        app.td.mask(:,:,:,dynamic) = 1-imdilate(1-squeeze(app.td.mask(:,:,:,dynamic)),app.structuringElement3D);
                    else
                        for slice = 1:app.td.ns
                        app.td.mask(:,:,slice,dynamic) = 1-imdilate(1-squeeze(app.td.mask(:,:,slice,dynamic)),app.structuringElement2D);
                        end
                    end
                end

                % Display the result;
                PlotImageFig(app);

            catch ME

                app.td.mask = previousMask;
                app.td.threshold = previousThreshold;
                app.TextMessage(ME.message);
                app.TextMessage('ERROR: the mask could not be changed ...');
                app.SetStatus(2);

            end

        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)

            % A failure halfway would leave a mask that is partly changed, so the mask
            % and thresholds are put back as they were
            previousMask = app.td.mask;
            previousThreshold = app.td.threshold;

            try

                % Morphological closing on current slice
                for dynamic = 1:app.td.nd
                    if app.td.data3dFlag
                        app.td.mask(:,:,:,dynamic) = 1-imdilate(1-squeeze(app.td.mask(:,:,:,dynamic)),app.structuringElement3D);
                        app.td.mask(:,:,:,dynamic) = 1-imerode(1-squeeze(app.td.mask(:,:,:,dynamic)),app.structuringElement3D);
                    else
                        for slice = 1:app.td.ns
                        app.td.mask(:,:,slice,dynamic) = 1-imdilate(1-squeeze(app.td.mask(:,:,slice,dynamic)),app.structuringElement2D);
                        app.td.mask(:,:,slice,dynamic) = 1-imerode(1-squeeze(app.td.mask(:,:,slice,dynamic)),app.structuringElement2D);
                        end
                    end
                end

                % Display the result;
                PlotImageFig(app);

            catch ME

                app.td.mask = previousMask;
                app.td.threshold = previousThreshold;
                app.TextMessage(ME.message);
                app.TextMessage('ERROR: the mask could not be changed ...');
                app.SetStatus(2);

            end

        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)

            % A failure halfway would leave a mask that is partly changed, so the mask
            % and thresholds are put back as they were
            previousMask = app.td.mask;
            previousThreshold = app.td.threshold;

            try

                % Morphological open on current slice
                for dynamic = 1:app.td.nd
                    if app.td.data3dFlag
                        app.td.mask(:,:,:,dynamic) = 1-imopen(1-squeeze(app.td.mask(:,:,:,dynamic)),app.structuringElement3D);
                    else
                        for slice = 1:app.td.ns
                        app.td.mask(:,:,slice,dynamic) = 1-imopen(1-squeeze(app.td.mask(:,:,slice,dynamic)),app.structuringElement2D);
                        end
                    end
                end

                % Display the result;
                PlotImageFig(app);

            catch ME

                app.td.mask = previousMask;
                app.td.threshold = previousThreshold;
                app.TextMessage(ME.message);
                app.TextMessage('ERROR: the mask could not be changed ...');
                app.SetStatus(2);

            end

        end

        % Value changed function: WindowEditField
        function WindowEditFieldValueChanged(app, event)

            app.WindowSlider.Value = app.WindowEditField.Value;
            app.AutoLWButton.Value = 0;
            app.PlotImageFig;

        end

        % Value changed function: WindowSlider
        function WindowSliderValueChanged(app, event)

            app.WindowEditField.Value = round(app.WindowSlider.Value);
            app.AutoLWButton.Value = 0;
            app.PlotImageFig;

        end

        % Value changed function: LevelEditField
        function LevelEditFieldValueChanged(app, event)

            app.LevelSlider.Value = app.LevelEditField.Value;
            app.AutoLWButton.Value = 0;
            app.PlotImageFig;

        end

        % Value changed function: LevelSlider
        function LevelSliderValueChanged(app, event)

            app.LevelEditField.Value = round(app.LevelSlider.Value);
            app.AutoLWButton.Value = 0;
            app.PlotImageFig;

        end

        % Value changed function: AutoLWButton
        function AutoLWButtonValueChanged(app, event)

            if app.AutoLWButton.Value == 1
                app.PlotImageFig;
            end

        end

        % Button pushed function: FitT2Button
        function FitT2ButtonPushed(app, event)

            if app.validRecoFlag

                % What a failure puts back: the maps a partial fit overwrites, the water
                % and fat maps, the flags that describe them, the scale the fit sets, and
                % the lamps
                previous.m0map = app.td.m0map;
                previous.t2map = app.td.t2map;
                previous.r2map = app.td.r2map;
                previous.watermap = app.td.watermap;
                previous.fatmap = app.td.fatmap;
                previous.validWaterFatFlag = app.td.validWaterFatFlag;
                previous.validFitFlag = app.validFitFlag;
                previous.scale = [app.T2ScaleMinEditField.Value, app.T2ScaleEditField.Value];
                previous.lamps = {app.Lamp1.Color, app.Lamp2.Color, app.Lamp3.Color, app.Lamp4.Color, ...
                    app.Lamp5.Color, app.Lamp6.Color, app.Lamp7.Color};

                app.SetLights([2,2,2,2,3,0,0]);
                app.SetButtons([0,0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.SetMapButtons(1);
                app.SetStatus(3);
                app.TextMessage('Fitting T2 maps ....');
                app.validFitFlag = false;
                app.td.validWaterFatFlag = false;
                app.abortFitFlag = false;
                app.FitAbortButton.Enable = 'on';
                drawnow;

                try

                    % One struct describes this fit, gathered once, so that nothing
                    % below the app reads a widget.
                    fitParams = gatherFitParams(app);
                    app.LogParamsFcn('fit', fitParams);

                    % TE selection
                    app.MakeTeArray;
                    nr_tes = length(find(app.td.teSelection(1:app.td.ne) == 1));

                    % Some checks
                    if (app.td.te <= 0) || (app.td.esp <= 0)
                        ME = MException('T2mapp:TEerror','ERROR: TE0 and ESP should be > 0 for fitting ...');
                        throw(ME);
                    end

                    if nr_tes == 0
                        ME = MException('T2mapp:EchoError','ERROR: Number of echoes should be greater than 1 ...');
                        throw(ME);
                    end

                    if fitParams.moba
                        app.FitModelBasedFcn(fitParams);
                    else
                        app.FitPixelwiseFcn(fitParams);
                    end

                    app.TextMessage('Finished ... ');
                    app.EstimatedFitTimeViewField.Value = 'Finished ...';


                    % ---------------------------------------
                    %     Water - Fat imaging
                    % ---------------------------------------

                    if fitParams.waterFat
                        app.FitWaterFatFcn(fitParams);
                    end

                    % Reset buttons
                    app.SetLights([2,2,2,2,1,0,0]);
                    app.SetButtons([1,1,1,1,1,1,1,1]);
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetMapButtons(1);
                    app.SetStatus(0);
                    app.validFitFlag = true;
                    app.FitAbortButton.Enable = 'off';

                    % Show middle slice
                    app.ChangeMapSlices(round(app.td.ns/2));
                    app.PlotMaps;

               catch ME

                    app.TextMessage(ME.message);
                    app.TextMessage("ERROR: Something went wrong during fitting ...");
                    app.EstimatedFitTimeViewField.Value = 'Failed ...';

                    % The maps back as they were before the step, the water and fat maps
                    % included, so a fit that failed halfway leaves no mix of new and old
                    % slices, and an earlier fit that was complete stays usable
                    app.td.m0map = previous.m0map;
                    app.td.t2map = previous.t2map;
                    app.td.r2map = previous.r2map;
                    app.td.watermap = previous.watermap;
                    app.td.fatmap = previous.fatmap;
                    app.td.validWaterFatFlag = previous.validWaterFatFlag;
                    app.validFitFlag = previous.validFitFlag;
                    app.T2ScaleMinEditField.Value = previous.scale(1);
                    app.T2ScaleEditField.Value = previous.scale(2);
                    for k = 1:numel(previous.lamps)
                        app.("Lamp" + k).Color = previous.lamps{k};
                    end
                    app.Lamp5.Color = app.red;

                    try
                        if app.validFitFlag
                            app.PlotMaps;
                        else
                            cla(app.T2MapFig);
                            cla(app.M0MapFig);
                            cla(app.R2MapFig);
                            cla(app.WaterMapFig);
                            cla(app.FatMapFig);
                        end
                    catch
                    end

                    fitted = double(app.validFitFlag);
                    app.FitAbortButton.Enable = 'off';
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetMapButtons(fitted);
                    app.SetButtons([1,1,1,1,1,fitted,fitted,1]);
                    app.SetStatus(2);

               end

            end

        end

        % Button pushed function: FitAbortButton
        function FitAbortButtonPushed(app, event)

            app.abortFitFlag = true;
            drawnow;

        end

        % Value changed function: FirstSliceEditField, LastSliceEditField
        function SliceRangeEditFieldValueChanged(app, event)

            app.FirstSliceEditField.Value(app.FirstSliceEditField.Value > app.td.ns) = app.td.ns;
            app.LastSliceEditField.Value(app.LastSliceEditField.Value > app.td.ns) = app.td.ns;

            if app.FirstSliceEditField.Value > app.LastSliceEditField.Value
                app.LastSliceEditField.Value = app.FirstSliceEditField.Value;
            end

        end

        % Value changed function: FirstDynamicEditField, 
        % ...and 1 other component
        function DynamicRangeEditFieldValueChanged(app, event)

            app.LastDynamicEditField.Value(app.LastDynamicEditField.Value > app.td.nd) = app.td.nd;
            app.FirstDynamicEditField.Value(app.FirstDynamicEditField.Value > app.td.nd) = app.td.nd;

            if app.FirstDynamicEditField.Value > app.LastDynamicEditField.Value
                app.LastDynamicEditField.Value = app.FirstDynamicEditField.Value;
            end

        end

        % Value changed function: T2ScaleEditField
        function T2ScaleEditFieldValueChanged(app, event)

            % The maximum has to stay above the minimum
            if app.T2ScaleEditField.Value <= app.T2ScaleMinEditField.Value
                app.T2ScaleEditField.Value = event.PreviousValue;
                app.TextMessage('WARNING: the T2 scale maximum has to lie above the minimum ...');
                return
            end

            % Plot the T2 map with the new scale
            app.PlotMaps;

        end

        % Value changed function: T2ScaleMinEditField
        function T2ScaleMinEditFieldValueChanged(app, event)

            % The minimum has to stay below the maximum
            if app.T2ScaleMinEditField.Value >= app.T2ScaleEditField.Value
                app.T2ScaleMinEditField.Value = event.PreviousValue;
                app.TextMessage('WARNING: the T2 scale minimum has to lie below the maximum ...');
                return
            end

            % Plot the T2 map with the new scale
            app.PlotMaps;

        end

        % Button pushed function: T2ScaleAutoButton
        function T2ScaleAutoButtonPushed(app, event)

            % Estimate a suitable scale for the T2 map
            % Auto-scale = 2 x mean of the fitted T2 values in the map on screen,
            % the slice and dynamic the sliders are set to
            im = app.td.t2map(:,:,app.T2MapSlicesEditField.Value,app.T2MapDynamicsEditField.Value);
            im = im(isfinite(im) & im ~= 0);
            scale = round(2*mean(im));
            scale(isnan(scale))=100;
            scale(scale<5)=5;
            app.SetT2ScaleFcn(scale);

            % Plot the map
            app.PlotMaps;

        end

        % Window scroll wheel function: T2mappUIFigure
        function T2MappUIFigureWindowScrollWheel(app, event)

            if app.td.ns > 1

                verticalScrollCount = event.VerticalScrollCount;

                % Get the Position of the GUI and the position of the mouse
                set(0,'units','pixels');
                guiposition = get(app.T2mappUIFigure,'Position');
                cursorposition = get(0,'PointerLocation');
                Xpos = cursorposition(1)-guiposition(1);

                if Xpos < 820

                    if app.validRecoFlag

                        if verticalScrollCount > 0
                            newslice = app.SlicesEditField.Value + 1;
                            newslice(newslice>app.SlicesEditField.Limits(2)) = app.SlicesEditField.Limits(2);
                        else
                            newslice = app.SlicesEditField.Value - 1;
                            newslice(newslice<1) = 1;
                        end

                        app.SlicesEditField.Value = newslice;
                        app.ImageSelectionEditFieldValueChanged;

                    end

                else

                    if ~app.roiFlag

                        if app.validFitFlag

                            if verticalScrollCount > 0
                                newslice = app.T2MapSlicesEditField.Value + 1;
                                newslice(newslice>app.T2MapSlicesEditField.Limits(2)) = app.T2MapSlicesEditField.Limits(2);
                            else
                                newslice = app.T2MapSlicesEditField.Value - 1;
                                newslice(newslice<1) = 1;
                            end

                            app.T2MapSlicesEditField.Value = newslice;
                            app.M0MapSlicesEditField.Value = newslice;
                            app.T2MapSlicesEditFieldValueChanged;

                        end

                    end

                end

                drawnow;

            end

        end

        % Selection changed function: T2ColormapButtonGroup
        function T2ColormapButtonGroup1SelectionChanged(app, event)

            app.t2cmap = app.ColorMapFromButtonsFcn(1, app.t2cmap);

            % Plot the maps with the new colormap
            app.PlotMaps;

        end

        % Selection changed function: M0ColorMapButtonGroup
        function M0ColorMapButtonGroupSelectionChanged(app, event)

            app.m0cmap = app.ColorMapFromButtonsFcn(2, app.m0cmap);

            % Plot the maps with the new colormap
            app.PlotMaps;

        end

        % Selection changed function: R2ColorMapButtonGroup
        function R2ColorMapButtonGroupSelectionChanged(app, event)

            app.r2cmap = app.ColorMapFromButtonsFcn(3, app.r2cmap);

            % Plot the maps with the new colormap
            app.PlotMaps;

        end

        % Selection changed function: WaterColorMapButtonGroup
        function WaterColorMapButtonGroupSelectionChanged(app, event)

            app.watercmap = app.ColorMapFromButtonsFcn(4, app.watercmap);

            % Plot the maps with the new colormap
            app.PlotMaps;

        end

        % Selection changed function: FatColorMapButtonGroup
        function FatColorMapButtonGroupSelectionChanged(app, event)

            app.fatcmap = app.ColorMapFromButtonsFcn(5, app.fatcmap);

            % Plot the maps with the new colormap
            app.PlotMaps;

        end

        % Image clicked function: M0Image, T2Image, WaterImage
        function T2ImageClicked(app, event)

            if app.validFitFlag

                % Get the Position of the GUI and the position of the mouse click
                set(0,'units','pixels');
                guiposition = get(app.T2mappUIFigure,'Position');
                cursorposition = get(0,'PointerLocation');

                % Position of the cursor click in the image window
                imagepos = [862,412]; % position of bottom-left corner of image in the app (862,412)
                Xpos = cursorposition(1)-guiposition(1)-imagepos(1);
                Ypos = cursorposition(2)-guiposition(2)-imagepos(2);

                % Image aspect ratio
                if app.td.parameters.PHASE_ORIENTATION
                    aratio = app.AspectRatioViewField.Value;
                    xsize = app.td.dimy;
                    ysize = app.td.dimx;
                else
                    aratio = 1/(app.AspectRatioViewField.Value);
                    xsize = app.td.dimx;
                    ysize = app.td.dimy;
                end

                % Correct for non-square image
                imsize = 313;
                if aratio >= 1
                    xcor = (Xpos/imsize) * xsize;
                    ycor = (Ypos/imsize) * ysize * aratio - (0.5 * (aratio-1) * ysize);
                else
                    xcor = (Xpos/imsize) * xsize * (1/aratio) - (0.5 * ((1/aratio)-1) * xsize);
                    ycor = (Ypos/imsize) * ysize;
                end

                % Round to nearest integer
                xcor = round(xcor);
                ycor = round(ycor);

                % Current slice
                zcor = app.T2MapSlicesEditField.Value;

                % Keep the values within matrix boundaries
                xcor(xcor<1) = 1;
                ycor(ycor<1) = 1;
                xcor(xcor>xsize) = xsize;
                ycor(ycor>ysize) = ysize;
                app.T2XEditField.Value = xcor;
                app.T2YEditField.Value = ycor;

                % ROI or cross-hair display
                if app.roiFlag

                    % Include points within Radius
                    se = strel("disk",app.T2BrushEditField.Value-1);
                    [nzrow,nzcol] = find(se.Neighborhood==1);
                    nzc = [nzrow,nzcol] - [app.T2BrushEditField.Value-1,app.T2BrushEditField.Value-1];

                    % Paint ROI
                    for i = 1:size(nzc,1)
                        if (xcor+nzc(i,1)>0) && (ycor+nzc(i,2)>0) && (xcor+nzc(i,1)<=xsize) && (ycor+nzc(i,2)<=ysize)
                            app.roiPoints = [app.roiPoints; [xcor+nzc(i,1) ycor+nzc(i,2) zcor]];
                        end
                    end
                    app.roiPoints = unique(app.roiPoints,'rows');
                    zColumn = app.roiPoints(:,3);
                    plotPoints = app.roiPoints(zColumn==zcor, :);
                    app.PlotPoint(plotPoints(:,1),plotPoints(:,2),ysize);

                else

                    % Plot a cross
                    app.roiPoints = [xcor ycor zcor];
                    app.PlotCross(app.roiPoints(1),app.roiPoints(2),ysize);

                end

                % Show the resulting T2 values and fits
                app.CalculateRoiT2;

            end

        end

        % Button pushed function: M0ROIButton, T2ROIbutton
        function ROIbuttonButtonPushed(app, event)

            % Toggle between ROI drawing and cross-hair drawing

             if strcmp(app.T2ROIbutton.Text,'ROI')

                 app.T2ROIbutton.Text = 'Cross';
                 app.M0ROIButton.Text = 'Cross';
                 app.TextMessage('ROI drawing selected ...');
                 app.T2ROIEraseButton.Visible = 'on';
                 app.M0ROIEraseButton.Visible = 'on';
                 app.T2ROIViewField.Visible = 'on';
                 app.T2PixelValueLabel.Text = 'Pixel averaged';
                 app.T2ROIViewFieldLabel.Visible = 'on';
                 app.T2BrushEditField.Visible = 'on';
                 app.T2BrushEditFieldLabel.Visible = 'on';
                 app.M0BrushEditField.Visible = 'on';
                 app.M0BrushEditFieldLabel.Visible = 'on';
                 app.roiFlag = true;

             else

                 app.T2ROIbutton.Text = 'ROI';
                 app.M0ROIButton.Text = 'ROI';
                 app.TextMessage('Cross-hair selected ...');
                 app.T2ROIEraseButton.Visible = 'off';
                 app.M0ROIEraseButton.Visible = 'off';
                 app.T2ROIViewField.Visible = 'off';
                 app.T2PixelValueLabel.Text = 'Pixel value';
                 app.T2ROIViewFieldLabel.Visible = 'off';
                 app.T2BrushEditField.Visible = 'off';
                 app.T2BrushEditFieldLabel.Visible = 'off';
                 app.M0BrushEditField.Visible = 'off';
                 app.M0BrushEditFieldLabel.Visible = 'off';
                 app.roiFlag = false;

             end

             app.roiPoints = [];
             delete(app.roiT2);
             delete(app.roiM0);
             delete(app.crossT2);
             delete(app.crossM0);
             delete(app.roiWF);
             delete(app.crossWF);
             app.PlotMaps;

        end

        % Value changed function: T2BrushEditField
        function T2BrushEditFieldValueChanged(app, event)

            app.M0BrushEditField.Value = app.T2BrushEditField.Value;

        end

        % Value changed function: M0BrushEditField
        function M0BrushEditFieldValueChanged(app, event)

            app.T2BrushEditField.Value = app.M0BrushEditField.Value;

        end

        % Button pushed function: M0ROIEraseButton, T2ROIEraseButton
        function EraseButtonPushed(app, event)

            app.roiPoints = [];
            delete(app.roiT2);
            delete(app.roiM0);
            delete(app.roiWF);
            app.PlotMaps;

        end

        % Button pushed function: T2ExportRoiDataButton
        function T2ExportRoiDataButtonPushed(app, event)

            % Writes the ROI series -- the echo times and the averaged intensities -- as a
            % CSV file named after the dataset

            if ~app.validFitFlag || isempty(app.roiPoints) || isempty(app.td.roiIntData)
                app.TextMessage('There is no ROI data to export: click in a map first ...');
                return
            end

            try

                app.T2mappUIFigure.Visible = 'off';
                folder = uigetdir(app.roiExportFolder,'ROI data export folder ...');
                if ~app.closeRequested
                    app.T2mappUIFigure.Visible = 'on';
                end

                if ~ischar(folder)
                    app.TextMessage('User cancel ...');
                    return
                end

                app.roiExportFolder = folder;
                rdata = [app.td.tes(:), app.td.roiIntData(:)];
                fileName = fullfile(app.roiExportFolder, strcat('roiData-', app.td.tag, '.csv'));
                writematrix(rdata, fileName);
                app.TextMessage(strcat("ROI data exported to ", fileName, " ..."));
                app.SetStatus(0);

            catch ME

                if ~app.closeRequested
                    app.T2mappUIFigure.Visible = 'on';
                end
                app.TextMessage(ME.message);
                app.TextMessage('ERROR: the ROI data could not be exported ...');
                app.SetStatus(2);

            end

        end

        % Value changed function: WaterFatMapSlicesEditField
        function WFmapSlicesEditFieldValueChanged(app, event)

            value = app.WaterFatMapSlicesEditField.Value;

            % Change the slider value according to the edit number
            app.ChangeMapSlices(value);

            % Plot the T2-map for the new slice and dynamic
            app.PlotMaps;

        end

        % Value changed function: WaterFatMapSlicesSlider
        function WFmapSlicesSliderValueChange(app, event)

            value = app.WaterFatMapSlicesSlider.Value;

            % Change the slider value according to the edit number
            app.ChangeMapSlices(value);

            % Plot the T2-map for the new slice and dynamic
            app.PlotMaps;

        end

        % Value changed function: WaterFatMapDynamicsEditField
        function WFmapDynamicsEditFieldValueChanged(app, event)

            value = app.WaterFatMapDynamicsEditField.Value;

            % Change the slider value according to the edit number
            app.ChangeMapDynamics(value);

            % Plot the T2-map for the new slice and dynamic
            app.PlotMaps;

        end

        % Value changed function: WaterFatMapDynamicsSlider
        function WFmapDynamicsSliderValueChanged(app, event)

            value = app.WaterFatMapDynamicsSlider.Value;

            % Change the slider value according to the edit number
            app.ChangeMapDynamics(value);

            % Plot the T2-map for the new slice and dynamic
            app.PlotMaps;

        end

        % Value changed function: T2MapSlicesEditField
        function T2MapSlicesEditFieldValueChanged(app, event)

            value = app.T2MapSlicesEditField.Value;

            % Change the slider value according to the edit number
            app.ChangeMapSlices(value);

            % Plot the T2-map for the new slice and dynamic
            app.PlotMaps;

        end

        % Value changed function: T2MapSlicesSlider
        function T2MapSlicesSliderValueChanged(app, event)

            value = app.T2MapSlicesSlider.Value;

            % Change the slider value according to the edit number
            app.ChangeMapSlices(value);

            % Plot the T2-map for the new slice and dynamic
            app.PlotMaps;

        end

        % Value changed function: T2MapDynamicsEditField
        function T2MapDynamicsEditFieldValueChanged(app, event)

            value = app.T2MapDynamicsEditField.Value;

            % Change the slider value according to the edit number
            app.ChangeMapDynamics(value);

            % Plot the T2-map for the new slice and dynamic
            app.PlotMaps;

        end

        % Value changed function: T2MapDynamicsSlider
        function T2MapDynamicsSliderValueChanged(app, event)

            value = app.T2MapDynamicsSlider.Value;

            % Change the slider value according to the edit number
            app.ChangeMapDynamics(value);

            % Plot the T2-map for the new slice and dynamic
            app.PlotMaps;

        end

        % Value changed function: M0MapSlicesEditField
        function M0MapSlicesEditFieldValueChanged(app, event)

            value = app.M0MapSlicesEditField.Value;

            % Change the slider value according to the edit number
            app.ChangeMapSlices(value);

            % Plot the T2-map for the new slice and dynamic
            app.PlotMaps;

        end

        % Value changed function: M0MapSlicesSlider
        function M0MapSlicesSliderValueChanged(app, event)

            value = app.M0MapSlicesSlider.Value;

            % Change the slider value according to the edit number
            app.ChangeMapSlices(value);

            % Plot the T2-map for the new slice and dynamic
            app.PlotMaps;

        end

        % Value changed function: M0MapDynamicsEditField
        function M0MapDynamicsEditFieldValueChanged(app, event)

            value = app.M0MapDynamicsEditField.Value;

            % Change the slider value according to the edit number
            app.ChangeMapDynamics(value);

            % Plot the T2-map for the new slice and dynamic
            app.PlotMaps;

        end

        % Value changed function: M0MapDynamicsSlider
        function M0MapDynamicsSliderValueChanged(app, event)

            value = app.M0MapDynamicsSlider.Value;

            % Change the slider value according to the edit number
            app.ChangeMapDynamics(value);

            % Plot the T2-map for the new slice and dynamic
            app.PlotMaps;

        end

        % Button pushed function: DCMNIFTIButton
        function ExportDCMNIFTIButtonPushed(app, event)

            if app.validFitFlag

                % What a cancelled dialog or a failure puts back: the export folder, which
                % the settings may have set, and the lamp of this step
                previousFolder = app.dicomExportFolder;
                previousLamp = app.Lamp6.Color;

                % Indicate busy
                app.SetLights([2,2,2,2,2,3,2]);
                app.SetButtons([0,0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.SetMapButtons(0);
                app.SetStatus(3);

                try

                    % Search for dicom file
                    app.TextMessage('DICOM export ...');

                    % Assuming that MRD and Data folder share the same base-folder
                    % Example: base / MRD / 343 / 8455 /

                    % Base / data / 343 / DICOM / 8455 /
                    dcmSfolder{1} = replace(app.mrdImportFolder,"MRD","Data");
                    try
                        k = strfind(dcmSfolder{1},"Data");
                        fs = strfind(dcmSfolder{1}(k:end),filesep);
                        dcmSfolder{1}= dcmSfolder{1}(1:k+fs(3)-1);
                        dcmSfolder{1} = insertAfter(dcmSfolder{1},k+fs(2)-1,strcat("DICOM",filesep));
                        dcmIndx{1} = 2;
                    catch
                        dcmSfolder{1} = app.mrdImportFolder;
                    end

                    % Base / data / 343 / DICOM / 8455 / 1 /
                    dcmSfolder{2} = strcat(dcmSfolder{1},'1',filesep);
                    dcmIndx{2} = 3;

                    % Base / data / 343 / DICOM / 8455 / 2 /
                    dcmSfolder{3} = strcat(dcmSfolder{1},'2',filesep);
                    dcmIndx{3} = 3;

                    % Base / data / 343 / DICOM / 8455 / 3 /
                    dcmSfolder{4} = strcat(dcmSfolder{1},'3',filesep);
                    dcmIndx{4} = 3;

                    % Check if one of the folders contains dicom files
                    dcmExist = false;
                    for i=1:length(dcmSfolder)
                        if ~isempty(dir(strcat(dcmSfolder{i},"*.dcm")))
                            dcmFolder = dcmSfolder{i};
                            app.TextMessage(strcat("Found DICOM information in ",dcmFolder));
                            filesepIdx = strfind(dcmFolder, filesep);
                            app.dicomExportFolder = dcmFolder(1:filesepIdx(end-dcmIndx{i}));
                            dcmExist = true;
                            break;
                        end
                    end

                    if dcmExist

                        % Dicom export using Dicom information
                        try
                            % Check if folder is actually writable
                            mkdir(strcat(app.dicomExportFolder,filesep,"testfolder"));
                            rmdir(strcat(app.dicomExportFolder,filesep,"testfolder"));
                        catch
                            app.T2mappUIFigure.Visible = 'off';
                            app.dicomExportFolder = uigetdir(app.dicomExportFolder,'DICOM export folder ...');
                            if ~app.closeRequested
                                app.T2mappUIFigure.Visible = 'on';
                            end
                            drawnow;
                            if ~ischar(app.dicomExportFolder)
                                ME = MException('T2mapp:userCancel','User cancel ...');
                                throw(ME);
                            end
                        end

                        app.TextMessage('Export with DICOM information ...');
                        app.TextMessage(strcat("DICOM export folder = ",app.dicomExportFolder," ..."));
                        app.exportFolder = t2mapp.io.exportDicomT2dcm(app.td, app.dicomExportFolder, dcmFolder);

                    else

                        % Dicom export without dicom information
                        app.T2mappUIFigure.Visible = 'off';
                        app.dicomExportFolder = uigetdir(app.dicomExportFolder,'DICOM export folder ...');
                        if ~app.closeRequested
                            app.T2mappUIFigure.Visible = 'on';
                        end
                        drawnow;
                        if ~ischar(app.dicomExportFolder)
                            ME = MException('T2mapp:userCancel','User cancel ...');
                            throw(ME);
                        end
                        app.exportFolder = strcat(app.dicomExportFolder,filesep,"DICOM",filesep);
                        app.TextMessage(strcat("DICOM export folder = ",app.exportFolder," ..."));
                        app.exportFolder = t2mapp.io.exportDicomT2(app.td, app.exportFolder, AppReporter(app));
                        app.TextMessage('WARNING: Export without DICOM information ...');

                    end

                    % NIFTI export
                    try
                        t2mapp.io.exportNiftiFcn(app.td, app.dicomExportFolder, app.exportFolder, AppReporter(app));
                    catch ME
                        app.TextMessage(ME.message);
                        app.TextMessage("ERROR: NIfti export failed ...");
                    end

                    % Success
                    app.TextMessage("DICOM export ready ...");
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetMapButtons(1);
                    app.SetLights([2,2,2,2,2,1,2]);
                    app.SetButtons([1,1,1,1,1,1,1,1]);
                    app.SetStatus(0);

                catch ME

                    % Failure
                    % The window is shown again in case the failure came while it was hidden
                    % behind the folder dialog, and the export folder is kept
                    if ~app.closeRequested
                        app.T2mappUIFigure.Visible = 'on';
                    end
                    app.dicomExportFolder = previousFolder;
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetMapButtons(1);
                    app.SetButtons([1,1,1,1,1,1,1,1]);

                    if strcmpi(ME.identifier, 'T2mapp:userCancel')
                        % Cancelling the folder dialog is not a failure
                        app.TextMessage('DICOM export cancelled ...');
                        app.Lamp6.Color = previousLamp;
                        app.SetStatus(0);
                    else
                        app.TextMessage(ME.message);
                        app.TextMessage('ERROR: Something went wrong during Dicom export ...');
                        app.SetLights([2,2,2,2,2,0,2]);
                        app.SetStatus(2);
                    end

                end

            end

        end

        % Button pushed function: GIFButton
        function ExportGIFbuttonPushed(app, event)

            if app.validFitFlag

                % What a cancelled dialog or a failure puts back: the export folder, which
                % the settings may have set, and the lamp of this step
                previousFolder = app.gifExportFolder;
                previousLamp = app.Lamp7.Color;

                % Indicate busy
                app.SetLights([2,2,2,2,2,2,3]);
                app.SetButtons([0,0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.SetMapButtons(0);
                app.SetStatus(3);

                app.TextMessage('GIF export ...');

                try

                    try
                        % Make the \data\scan_number\folder
                        gifOutputFolder = replace(app.mrdImportFolder, "MRD", "Data");
                        k = strfind(gifOutputFolder,"Data");
                        fs = strfind(gifOutputFolder(k:end),filesep);
                        gifOutputFolder = gifOutputFolder(1:k+fs(2)-1);
                        % Check if folder is actually writable
                        mkdir(strcat(gifOutputFolder,filesep,"testfolder"));
                        rmdir(strcat(gifOutputFolder,filesep,"testfolder"));
                    catch
                        app.T2mappUIFigure.Visible = 'off';
                        app.gifExportFolder = uigetdir(app.gifExportFolder,'GIF export folder ...');
                        if ~app.closeRequested
                            app.T2mappUIFigure.Visible = 'on';
                        end
                        if ~ischar(app.gifExportFolder)
                            ME = MException('T2mapp:userCancel','User cancel ...');
                            throw(ME);
                        end
                        gifOutputFolder = app.gifExportFolder;
                    end
                    gifOutputFolder = strcat(gifOutputFolder,filesep,"GIF",filesep);

                    % Export to animated gif
                    app.TextMessage(strcat("GIF export folder = ",gifOutputFolder," ..."));
                    app.ExportGifsFcn(gifOutputFolder);

                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetMapButtons(1);
                    app.SetLights([2,2,2,2,2,2,1]);
                    app.SetButtons([1,1,1,1,1,1,1,1]);
                    app.SetStatus(0);

                catch ME

                    % The window is shown again in case the failure came while it was hidden
                    % behind the folder dialog, and the export folder is kept
                    if ~app.closeRequested
                        app.T2mappUIFigure.Visible = 'on';
                    end
                    app.gifExportFolder = previousFolder;
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetMapButtons(1);
                    app.SetButtons([1,1,1,1,1,1,1,1]);

                    if strcmpi(ME.identifier, 'T2mapp:userCancel')
                        % Cancelling the folder dialog is not a failure
                        app.TextMessage('GIF export cancelled ...');
                        app.Lamp7.Color = previousLamp;
                        app.SetStatus(0);
                    else
                        app.TextMessage(ME.message);
                        app.TextMessage('ERROR: Something went wrong during GIF export ...');
                        app.SetLights([2,2,2,2,2,2,0]);
                        app.SetStatus(2);
                    end

                end

            end

        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)

            % The log is closed, the path and folder restored, and the app
            % deleted in one place, which the window's close box uses too

            app.CloseAppFcn;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.T2mappUIFigure = uifigure('Visible', 'off');
            app.T2mappUIFigure.Name = 'T2mapp';
            app.T2mappUIFigure.Icon = fullfile(pathToMLAPP, 'T2icon.png');
            app.T2mappUIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @T2MappUIFigureWindowScrollWheel, true);
            app.Tsub2subsupsupmappingLabel = uilabel(app.T2mappUIFigure);
            app.Tsub2subsupsupmappingLabel.Interpreter = 'html';
            app.Tsub2subsupsupmappingLabel.Text = 'T<sub>2</sub><sup>⁽*⁾</sup> mapping';
            app.CreditsLabel = uilabel(app.T2mappUIFigure);
            app.CreditsLabel.Tooltip = {'g.j.strijkers@amsterdamumc.nl'};
            app.CreditsLabel.Text = 'Gustav Strijkers, Amsterdam UMC, Version X.X.X';
            app.TulpLogo = uiimage(app.T2mappUIFigure);
            app.MessageBox = uitextarea(app.T2mappUIFigure);
            app.MessageBox.Editable = 'off';
            app.MessageBox.Tooltip = {'Messages'};
            app.MessageBox.Value = {'Messages ...'};
            app.BackgroundBox = uitextarea(app.T2mappUIFigure);
            app.BackgroundBox.Editable = 'off';
            app.BackgroundBox.Tooltip = {'App status'};
            app.StatusImage = uiimage(app.T2mappUIFigure);
            app.StatusLabel = uilabel(app.T2mappUIFigure);
            app.StatusLabel.Text = 'STATUS';
            app.MRSImage = uiimage(app.T2mappUIFigure);
            app.TabGroup1 = uitabgroup(app.T2mappUIFigure);
            app.DashboardTab = uitab(app.TabGroup1);
            app.DashboardTab.Title = 'Dashboard';
            app.LoadMRDfileButton = uibutton(app.DashboardTab, 'push');
            app.LoadMRDfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMRDfileButtonPushed, true);
            app.LoadMRDfileButton.Enable = 'off';
            app.LoadMRDfileButton.Tooltip = {'Load MRD multi-echo or multi-gradient echo data'};
            app.LoadMRDfileButton.Text = 'Load MRD';
            app.RecoimagesButton = uibutton(app.DashboardTab, 'push');
            app.RecoimagesButton.ButtonPushedFcn = createCallbackFcn(app, @RecoImagesButtonPushed, true);
            app.RecoimagesButton.Enable = 'off';
            app.RecoimagesButton.Tooltip = {'Reconstruct the images, check CS reco for compressed-sensing reconstruction'};
            app.RecoimagesButton.Text = 'Reco images';
            app.RegisterButton = uibutton(app.DashboardTab, 'push');
            app.RegisterButton.ButtonPushedFcn = createCallbackFcn(app, @RegisterButtonPushed, true);
            app.RegisterButton.Enable = 'off';
            app.RegisterButton.Tooltip = {'Registration of images to 1st echo image'};
            app.RegisterButton.Text = 'Register';
            app.MaskButton = uibutton(app.DashboardTab, 'push');
            app.MaskButton.ButtonPushedFcn = createCallbackFcn(app, @MaskButtonPushed, true);
            app.MaskButton.Enable = 'off';
            app.MaskButton.Tooltip = {'Mask image to prevent fitting of background pixels'};
            app.MaskButton.Text = 'Mask';
            app.FitT2Button = uibutton(app.DashboardTab, 'push');
            app.FitT2Button.ButtonPushedFcn = createCallbackFcn(app, @FitT2ButtonPushed, true);
            app.FitT2Button.Enable = 'off';
            app.FitT2Button.Tooltip = {'T2 or T2* fitting of the data. Check model-based for model-based fitting with Bart. Check water-fat for T2* based water-fat imaging'};
            app.FitT2Button.Text = 'Fit T2';
            app.DCMNIFTIButton = uibutton(app.DashboardTab, 'push');
            app.DCMNIFTIButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDCMNIFTIButtonPushed, true);
            app.DCMNIFTIButton.Enable = 'off';
            app.DCMNIFTIButton.Tooltip = {'Export to DICOM'};
            app.DCMNIFTIButton.Text = 'DCM / NIFTI';
            app.GIFButton = uibutton(app.DashboardTab, 'push');
            app.GIFButton.ButtonPushedFcn = createCallbackFcn(app, @ExportGIFbuttonPushed, true);
            app.GIFButton.Enable = 'off';
            app.GIFButton.Tooltip = {'Export to animated GIF'};
            app.GIFButton.Text = 'GIF';
            app.ExitButton = uibutton(app.DashboardTab, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Enable = 'off';
            app.ExitButton.Tooltip = {'Exit'};
            app.ExitButton.Text = 'Exit';
            app.Lamp1 = uilamp(app.DashboardTab);
            app.Lamp2 = uilamp(app.DashboardTab);
            app.Lamp3 = uilamp(app.DashboardTab);
            app.Lamp4 = uilamp(app.DashboardTab);
            app.Lamp5 = uilamp(app.DashboardTab);
            app.Lamp6 = uilamp(app.DashboardTab);
            app.Lamp7 = uilamp(app.DashboardTab);
            app.FitProgressLabel = uilabel(app.DashboardTab);
            app.FitProgressLabel.Text = 'Progress';
            app.FitProgressGauge = uigauge(app.DashboardTab, 'linear');
            app.FitProgressGauge.MajorTicks = [0 25 50 75 100];
            app.FitAbortButton = uibutton(app.DashboardTab, 'push');
            app.FitAbortButton.ButtonPushedFcn = createCallbackFcn(app, @FitAbortButtonPushed, true);
            app.FitAbortButton.Enable = 'off';
            app.FitAbortButton.Tooltip = {'Abort fitting'};
            app.FitAbortButton.Text = 'Abort';
            app.EstimatedFitTimeViewField = uieditfield(app.DashboardTab, 'text');
            app.EstimatedFitTimeViewField.Editable = 'off';
            app.RegProgressLabel = uilabel(app.DashboardTab);
            app.RegProgressLabel.Text = 'Progress';
            app.RegProgressGauge = uigauge(app.DashboardTab, 'linear');
            app.RegProgressGauge.MajorTicks = [0 25 50 75 100];
            app.RegAbortButton = uibutton(app.DashboardTab, 'push');
            app.RegAbortButton.ButtonPushedFcn = createCallbackFcn(app, @RegAbortButtonPushed, true);
            app.RegAbortButton.Enable = 'off';
            app.RegAbortButton.Tooltip = {'Abort registration'};
            app.RegAbortButton.Text = 'Abort';
            app.RegistrationDropDown = uidropdown(app.DashboardTab);
            app.RegistrationDropDown.Items = {'Translation', 'Rigid', 'Affine', 'B-Spline'};
            app.RegistrationDropDown.Enable = 'off';
            app.RegistrationDropDown.Tooltip = {'Image registration transformation type'};
            app.RegistrationDropDown.Value = 'Translation';
            app.EstimatedRegTimeViewField = uieditfield(app.DashboardTab, 'text');
            app.EstimatedRegTimeViewField.Editable = 'off';
            app.WaterFatCheckBoxLabel = uilabel(app.DashboardTab);
            app.WaterFatCheckBoxLabel.Enable = 'off';
            app.WaterFatCheckBoxLabel.Tooltip = {'Check for T2*-based water-fat separation'};
            app.WaterFatCheckBoxLabel.Text = 'water/fat';
            app.WaterFatCheckBox = uicheckbox(app.DashboardTab);
            app.WaterFatCheckBox.Tooltip = {'Check for T2*-based water-fat separation'};
            app.WaterFatCheckBox.Enable = 'off';
            app.WaterFatCheckBox.Text = '';
            app.MobaCheckBoxLabel = uilabel(app.DashboardTab);
            app.MobaCheckBoxLabel.Enable = 'off';
            app.MobaCheckBoxLabel.Tooltip = {'Check for model-based T2 or T2* fitting with Bart'};
            app.MobaCheckBoxLabel.Text = 'model-based';
            app.MobaCheckBox = uicheckbox(app.DashboardTab);
            app.MobaCheckBox.Tooltip = {'Check for model-based T2 or T2* fitting with Bart'};
            app.MobaCheckBox.Enable = 'off';
            app.MobaCheckBox.Text = '';
            app.CSrecoCheckBoxLabel = uilabel(app.DashboardTab);
            app.CSrecoCheckBoxLabel.Enable = 'off';
            app.CSrecoCheckBoxLabel.Tooltip = {'Check for compressed-sensing reconstruction with Bart'};
            app.CSrecoCheckBoxLabel.Text = 'CS reco';
            app.CSrecoCheckBox = uicheckbox(app.DashboardTab);
            app.CSrecoCheckBox.Tooltip = {'Check for compressed-sensing reconstruction with Bart'};
            app.CSrecoCheckBox.Enable = 'off';
            app.CSrecoCheckBox.Text = '';
            app.TabGroup3 = uitabgroup(app.T2mappUIFigure);
            app.T2mapTab = uitab(app.TabGroup3);
            app.T2mapTab.Title = 'T2-map';
            app.T2MapFig = uiaxes(app.T2mapTab);
            app.T2MapFig.XTick = [];
            app.T2MapFig.YTick = [];
            app.T2logPlot = uiaxes(app.T2mapTab);
            xlabel(app.T2logPlot, 'TE')
            ylabel(app.T2logPlot, 'log ( Signal )')
            app.T2linearPlot = uiaxes(app.T2mapTab);
            xlabel(app.T2linearPlot, 'TE')
            ylabel(app.T2linearPlot, 'Signal')
            app.T2MapLabel = uilabel(app.T2mapTab);
            app.T2MapLabel.Text = 'T2 map';
            app.T2ImageSelectionPanel = uipanel(app.T2mapTab);
            app.T2ImageSelectionPanel.Title = 'Image selection';
            app.T2MapSlicesEditField = uieditfield(app.T2ImageSelectionPanel, 'numeric');
            app.T2MapSlicesEditField.Limits = [1 Inf];
            app.T2MapSlicesEditField.ValueDisplayFormat = '%.0f';
            app.T2MapSlicesEditField.ValueChangedFcn = createCallbackFcn(app, @T2MapSlicesEditFieldValueChanged, true);
            app.T2MapSlicesEditField.Tooltip = {'Slice'};
            app.T2MapSlicesEditField.Value = 1;
            app.T2MapSlicesSlider = uislider(app.T2ImageSelectionPanel);
            app.T2MapSlicesSlider.Limits = [1 200];
            app.T2MapSlicesSlider.MajorTicks = [];
            app.T2MapSlicesSlider.ValueChangedFcn = createCallbackFcn(app, @T2MapSlicesSliderValueChanged, true);
            app.T2MapSlicesSlider.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.T2MapSlicesSlider.Tooltip = {'Slice'};
            app.T2MapSlicesSlider.Value = 1;
            app.T2MapDynamicsEditField = uieditfield(app.T2ImageSelectionPanel, 'numeric');
            app.T2MapDynamicsEditField.Limits = [1 Inf];
            app.T2MapDynamicsEditField.ValueDisplayFormat = '%.0f';
            app.T2MapDynamicsEditField.ValueChangedFcn = createCallbackFcn(app, @T2MapDynamicsEditFieldValueChanged, true);
            app.T2MapDynamicsEditField.Tooltip = {'Dynamic'};
            app.T2MapDynamicsEditField.Value = 1;
            app.T2MapDynamicsSlider = uislider(app.T2ImageSelectionPanel);
            app.T2MapDynamicsSlider.Limits = [1 200];
            app.T2MapDynamicsSlider.MajorTicks = [];
            app.T2MapDynamicsSlider.ValueChangedFcn = createCallbackFcn(app, @T2MapDynamicsSliderValueChanged, true);
            app.T2MapDynamicsSlider.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.T2MapDynamicsSlider.Tooltip = {'Dynamic'};
            app.T2MapDynamicsSlider.Value = 1;
            app.T2MapSlicesEditFieldLabel = uilabel(app.T2ImageSelectionPanel);
            app.T2MapSlicesEditFieldLabel.Text = 'slice';
            app.T2MapDynamicsEditFieldLabel = uilabel(app.T2ImageSelectionPanel);
            app.T2MapDynamicsEditFieldLabel.Text = 'dynamic';
            app.T2ColormapButtonGroup = uibuttongroup(app.T2mapTab);
            app.T2ColormapButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @T2ColormapButtonGroup1SelectionChanged, true);
            app.T2ColormapButtonGroup.Title = 'Color map';
            app.grayButton1 = uiradiobutton(app.T2ColormapButtonGroup);
            app.grayButton1.Text = 'gray';
            app.grayButton1.Interpreter = 'html';
            app.autumnButton1 = uiradiobutton(app.T2ColormapButtonGroup);
            app.autumnButton1.Text = 'autumn';
            app.autumnButton1.Interpreter = 'html';
            app.winterButton1 = uiradiobutton(app.T2ColormapButtonGroup);
            app.winterButton1.Text = 'winter';
            app.winterButton1.Interpreter = 'html';
            app.parulaButton1 = uiradiobutton(app.T2ColormapButtonGroup);
            app.parulaButton1.Text = 'parula';
            app.parulaButton1.Interpreter = 'html';
            app.naviaButton1 = uiradiobutton(app.T2ColormapButtonGroup);
            app.naviaButton1.Text = 'navia';
            app.naviaButton1.Interpreter = 'html';
            app.naviaButton1.Value = true;
            app.T2ScaleMaxLabel = uilabel(app.T2ColormapButtonGroup);
            app.T2ScaleMaxLabel.Text = 'Max';
            app.T2ScaleEditField = uieditfield(app.T2ColormapButtonGroup, 'numeric');
            app.T2ScaleEditField.Limits = [1 Inf];
            app.T2ScaleEditField.ValueChangedFcn = createCallbackFcn(app, @T2ScaleEditFieldValueChanged, true);
            app.T2ScaleEditField.Tooltip = {'Scale max'};
            app.T2ScaleEditField.Value = 1;
            app.T2ScaleMinLabel = uilabel(app.T2ColormapButtonGroup);
            app.T2ScaleMinLabel.Text = 'Min';
            app.T2ScaleMinEditField = uieditfield(app.T2ColormapButtonGroup, 'numeric');
            app.T2ScaleMinEditField.Limits = [0 Inf];
            app.T2ScaleMinEditField.ValueChangedFcn = createCallbackFcn(app, @T2ScaleMinEditFieldValueChanged, true);
            app.T2ScaleMinEditField.Tooltip = {'Scale min'};
            app.T2ScaleAutoButton = uibutton(app.T2ColormapButtonGroup, 'push');
            app.T2ScaleAutoButton.ButtonPushedFcn = createCallbackFcn(app, @T2ScaleAutoButtonPushed, true);
            app.T2ScaleAutoButton.Tooltip = {'Auto scale'};
            app.T2ScaleAutoButton.Text = 'Auto';
            app.T2Image = uiimage(app.T2mapTab);
            app.T2Image.ImageClickedFcn = createCallbackFcn(app, @T2ImageClicked, true);
            app.T2XEditFieldLabel = uilabel(app.T2mapTab);
            app.T2XEditFieldLabel.Text = 'X';
            app.T2XEditField = uieditfield(app.T2mapTab, 'numeric');
            app.T2XEditField.ValueDisplayFormat = '%.0f';
            app.T2XEditField.Editable = 'off';
            app.T2XEditField.Tooltip = {'X-coordinate'};
            app.T2YEditFieldLabel = uilabel(app.T2mapTab);
            app.T2YEditFieldLabel.Text = 'Y';
            app.T2YEditField = uieditfield(app.T2mapTab, 'numeric');
            app.T2YEditField.ValueDisplayFormat = '%.0f';
            app.T2YEditField.Editable = 'off';
            app.T2YEditField.Tooltip = {'Y-coordinate'};
            app.T2ROIbutton = uibutton(app.T2mapTab, 'push');
            app.T2ROIbutton.ButtonPushedFcn = createCallbackFcn(app, @ROIbuttonButtonPushed, true);
            app.T2ROIbutton.Tooltip = {'Cross-hair or ROI drawing'};
            app.T2ROIbutton.Text = 'ROI';
            app.T2ROIEraseButton = uibutton(app.T2mapTab, 'push');
            app.T2ROIEraseButton.ButtonPushedFcn = createCallbackFcn(app, @EraseButtonPushed, true);
            app.T2ROIEraseButton.Visible = 'off';
            app.T2ROIEraseButton.Tooltip = {'Erase ROI'};
            app.T2ROIEraseButton.Text = 'Erase';
            app.T2ViewField = uieditfield(app.T2mapTab, 'text');
            app.T2ViewField.Editable = 'off';
            app.T2ViewField.Tooltip = {'T2 value'};
            app.T2ViewFieldLabel = uilabel(app.T2mapTab);
            app.T2ViewFieldLabel.Text = 'T2 (ms)';
            app.T2ROIViewField = uieditfield(app.T2mapTab, 'text');
            app.T2ROIViewField.Editable = 'off';
            app.T2ROIViewField.Visible = 'off';
            app.T2ROIViewField.Tooltip = {'ROI-averaged T2 value'};
            app.T2PixelValueLabel = uilabel(app.T2mapTab);
            app.T2PixelValueLabel.Text = 'pixel value';
            app.T2ROIViewFieldLabel = uilabel(app.T2mapTab);
            app.T2ROIViewFieldLabel.Visible = 'off';
            app.T2ROIViewFieldLabel.Text = 'ROI averaged';
            app.T2ExportRoiDataButton = uibutton(app.T2mapTab, 'push');
            app.T2ExportRoiDataButton.ButtonPushedFcn = createCallbackFcn(app, @T2ExportRoiDataButtonPushed, true);
            app.T2ExportRoiDataButton.Tooltip = {'Export T2 as function of TE in ROI to CSV-file'};
            app.T2ExportRoiDataButton.Text = 'Export';
            app.T2BrushEditFieldLabel = uilabel(app.T2mapTab);
            app.T2BrushEditFieldLabel.Visible = 'off';
            app.T2BrushEditFieldLabel.Text = 'brush';
            app.T2BrushEditField = uieditfield(app.T2mapTab, 'numeric');
            app.T2BrushEditField.Limits = [1 5];
            app.T2BrushEditField.ValueDisplayFormat = '%.0f';
            app.T2BrushEditField.ValueChangedFcn = createCallbackFcn(app, @T2BrushEditFieldValueChanged, true);
            app.T2BrushEditField.Visible = 'off';
            app.T2BrushEditField.Tooltip = {'ROI brush size'};
            app.T2BrushEditField.Value = 2;
            app.LogLabel = uilabel(app.T2mapTab);
            app.LogLabel.Text = 'Log';
            app.LinearLabel = uilabel(app.T2mapTab);
            app.LinearLabel.Text = 'Linear';
            app.M0R2Tab = uitab(app.TabGroup3);
            app.M0R2Tab.Title = 'M0 & R^2';
            app.M0MapFig = uiaxes(app.M0R2Tab);
            app.M0MapFig.XTick = [];
            app.M0MapFig.YTick = [];
            app.R2MapFig = uiaxes(app.M0R2Tab);
            app.R2MapFig.XTick = [];
            app.R2MapFig.YTick = [];
            app.M0MapLabel = uilabel(app.M0R2Tab);
            app.M0MapLabel.Text = 'M0 map';
            app.R2MapLabel = uilabel(app.M0R2Tab);
            app.R2MapLabel.Text = 'R^2 map';
            app.M0ImageSelectionPanel = uipanel(app.M0R2Tab);
            app.M0ImageSelectionPanel.Title = 'Image selection';
            app.M0MapSlicesEditField = uieditfield(app.M0ImageSelectionPanel, 'numeric');
            app.M0MapSlicesEditField.Limits = [1 Inf];
            app.M0MapSlicesEditField.ValueDisplayFormat = '%.0f';
            app.M0MapSlicesEditField.ValueChangedFcn = createCallbackFcn(app, @M0MapSlicesEditFieldValueChanged, true);
            app.M0MapSlicesEditField.Tooltip = {'Slice'};
            app.M0MapSlicesEditField.Value = 1;
            app.M0MapSlicesSlider = uislider(app.M0ImageSelectionPanel);
            app.M0MapSlicesSlider.Limits = [1 200];
            app.M0MapSlicesSlider.MajorTicks = [];
            app.M0MapSlicesSlider.ValueChangedFcn = createCallbackFcn(app, @M0MapSlicesSliderValueChanged, true);
            app.M0MapSlicesSlider.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.M0MapSlicesSlider.Tooltip = {'Slice'};
            app.M0MapSlicesSlider.Value = 1;
            app.M0MapDynamicsEditField = uieditfield(app.M0ImageSelectionPanel, 'numeric');
            app.M0MapDynamicsEditField.Limits = [1 Inf];
            app.M0MapDynamicsEditField.ValueDisplayFormat = '%.0f';
            app.M0MapDynamicsEditField.ValueChangedFcn = createCallbackFcn(app, @M0MapDynamicsEditFieldValueChanged, true);
            app.M0MapDynamicsEditField.Tooltip = {'Dynamic'};
            app.M0MapDynamicsEditField.Value = 1;
            app.M0MapDynamicsSlider = uislider(app.M0ImageSelectionPanel);
            app.M0MapDynamicsSlider.Limits = [1 200];
            app.M0MapDynamicsSlider.MajorTicks = [];
            app.M0MapDynamicsSlider.ValueChangedFcn = createCallbackFcn(app, @M0MapDynamicsSliderValueChanged, true);
            app.M0MapDynamicsSlider.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.M0MapDynamicsSlider.Tooltip = {'Dynamic'};
            app.M0MapDynamicsSlider.Value = 1;
            app.M0MapSlicesEditFieldLabel = uilabel(app.M0ImageSelectionPanel);
            app.M0MapSlicesEditFieldLabel.Text = 'slice';
            app.M0MapDynamicsEditFieldLabel = uilabel(app.M0ImageSelectionPanel);
            app.M0MapDynamicsEditFieldLabel.Text = 'dynamic';
            app.M0ColorMapButtonGroup = uibuttongroup(app.M0R2Tab);
            app.M0ColorMapButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @M0ColorMapButtonGroupSelectionChanged, true);
            app.M0ColorMapButtonGroup.Title = 'Color map';
            app.grayButton2 = uiradiobutton(app.M0ColorMapButtonGroup);
            app.grayButton2.Text = 'gray';
            app.grayButton2.Interpreter = 'html';
            app.grayButton2.Value = true;
            app.autumnButton2 = uiradiobutton(app.M0ColorMapButtonGroup);
            app.autumnButton2.Text = 'autumn';
            app.autumnButton2.Interpreter = 'html';
            app.winterButton2 = uiradiobutton(app.M0ColorMapButtonGroup);
            app.winterButton2.Text = 'winter';
            app.winterButton2.Interpreter = 'html';
            app.parulaButton2 = uiradiobutton(app.M0ColorMapButtonGroup);
            app.parulaButton2.Text = 'parula';
            app.parulaButton2.Interpreter = 'html';
            app.naviaButton2 = uiradiobutton(app.M0ColorMapButtonGroup);
            app.naviaButton2.Text = 'navia';
            app.naviaButton2.Interpreter = 'html';
            app.R2ColorMapButtonGroup = uibuttongroup(app.M0R2Tab);
            app.R2ColorMapButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @R2ColorMapButtonGroupSelectionChanged, true);
            app.R2ColorMapButtonGroup.Title = 'Color map';
            app.grayButton3 = uiradiobutton(app.R2ColorMapButtonGroup);
            app.grayButton3.Text = 'gray';
            app.grayButton3.Interpreter = 'html';
            app.autumnButton3 = uiradiobutton(app.R2ColorMapButtonGroup);
            app.autumnButton3.Text = 'autumn';
            app.autumnButton3.Interpreter = 'html';
            app.winterButton3 = uiradiobutton(app.R2ColorMapButtonGroup);
            app.winterButton3.Text = 'winter';
            app.winterButton3.Interpreter = 'html';
            app.parulaButton3 = uiradiobutton(app.R2ColorMapButtonGroup);
            app.parulaButton3.Text = 'parula';
            app.parulaButton3.Interpreter = 'html';
            app.parulaButton3.Value = true;
            app.naviaButton3 = uiradiobutton(app.R2ColorMapButtonGroup);
            app.naviaButton3.Text = 'navia';
            app.naviaButton3.Interpreter = 'html';
            app.M0Image = uiimage(app.M0R2Tab);
            app.M0Image.ImageClickedFcn = createCallbackFcn(app, @T2ImageClicked, true);
            app.R2Image = uiimage(app.M0R2Tab);
            app.M0ROIButton = uibutton(app.M0R2Tab, 'push');
            app.M0ROIButton.ButtonPushedFcn = createCallbackFcn(app, @ROIbuttonButtonPushed, true);
            app.M0ROIButton.Tooltip = {'Cross-hair or ROI drawing'};
            app.M0ROIButton.Text = 'ROI';
            app.M0ROIEraseButton = uibutton(app.M0R2Tab, 'push');
            app.M0ROIEraseButton.ButtonPushedFcn = createCallbackFcn(app, @EraseButtonPushed, true);
            app.M0ROIEraseButton.Visible = 'off';
            app.M0ROIEraseButton.Tooltip = {'Erase ROI'};
            app.M0ROIEraseButton.Text = 'Erase';
            app.M0BrushEditFieldLabel = uilabel(app.M0R2Tab);
            app.M0BrushEditFieldLabel.Visible = 'off';
            app.M0BrushEditFieldLabel.Text = 'brush';
            app.M0BrushEditField = uieditfield(app.M0R2Tab, 'numeric');
            app.M0BrushEditField.Limits = [1 5];
            app.M0BrushEditField.ValueDisplayFormat = '%.0f';
            app.M0BrushEditField.ValueChangedFcn = createCallbackFcn(app, @M0BrushEditFieldValueChanged, true);
            app.M0BrushEditField.Visible = 'off';
            app.M0BrushEditField.Tooltip = {'ROI brush size'};
            app.M0BrushEditField.Value = 2;
            app.WaterFatTab = uitab(app.TabGroup3);
            app.WaterFatTab.Title = 'Water/Fat';
            app.WaterMapFig = uiaxes(app.WaterFatTab);
            app.WaterMapFig.XTick = [];
            app.WaterMapFig.YTick = [];
            app.FatMapFig = uiaxes(app.WaterFatTab);
            app.FatMapFig.XTick = [];
            app.FatMapFig.YTick = [];
            app.WaterMapLabel = uilabel(app.WaterFatTab);
            app.WaterMapLabel.Text = 'Water';
            app.FatMapLabel = uilabel(app.WaterFatTab);
            app.FatMapLabel.Text = 'Fat';
            app.WaterFatImageSelectionPanel = uipanel(app.WaterFatTab);
            app.WaterFatImageSelectionPanel.Title = 'Image selection';
            app.WaterFatMapSlicesEditField = uieditfield(app.WaterFatImageSelectionPanel, 'numeric');
            app.WaterFatMapSlicesEditField.Limits = [1 Inf];
            app.WaterFatMapSlicesEditField.ValueDisplayFormat = '%.0f';
            app.WaterFatMapSlicesEditField.ValueChangedFcn = createCallbackFcn(app, @WFmapSlicesEditFieldValueChanged, true);
            app.WaterFatMapSlicesEditField.Tooltip = {'Slice'};
            app.WaterFatMapSlicesEditField.Value = 1;
            app.WaterFatMapSlicesSlider = uislider(app.WaterFatImageSelectionPanel);
            app.WaterFatMapSlicesSlider.Limits = [1 200];
            app.WaterFatMapSlicesSlider.MajorTicks = [];
            app.WaterFatMapSlicesSlider.ValueChangedFcn = createCallbackFcn(app, @WFmapSlicesSliderValueChange, true);
            app.WaterFatMapSlicesSlider.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.WaterFatMapSlicesSlider.Tooltip = {'Slice'};
            app.WaterFatMapSlicesSlider.Value = 1;
            app.WaterFatMapDynamicsEditField = uieditfield(app.WaterFatImageSelectionPanel, 'numeric');
            app.WaterFatMapDynamicsEditField.Limits = [1 Inf];
            app.WaterFatMapDynamicsEditField.ValueDisplayFormat = '%.0f';
            app.WaterFatMapDynamicsEditField.ValueChangedFcn = createCallbackFcn(app, @WFmapDynamicsEditFieldValueChanged, true);
            app.WaterFatMapDynamicsEditField.Tooltip = {'Dynamic'};
            app.WaterFatMapDynamicsEditField.Value = 1;
            app.WaterFatMapDynamicsSlider = uislider(app.WaterFatImageSelectionPanel);
            app.WaterFatMapDynamicsSlider.Limits = [1 200];
            app.WaterFatMapDynamicsSlider.MajorTicks = [];
            app.WaterFatMapDynamicsSlider.ValueChangedFcn = createCallbackFcn(app, @WFmapDynamicsSliderValueChanged, true);
            app.WaterFatMapDynamicsSlider.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.WaterFatMapDynamicsSlider.Tooltip = {'Dynamic'};
            app.WaterFatMapDynamicsSlider.Value = 1;
            app.WaterFatMapSlicesEditFieldLabel = uilabel(app.WaterFatImageSelectionPanel);
            app.WaterFatMapSlicesEditFieldLabel.Text = 'slice';
            app.WaterFatMapDynamicsEditFieldLabel = uilabel(app.WaterFatImageSelectionPanel);
            app.WaterFatMapDynamicsEditFieldLabel.Text = 'dynamic';
            app.WaterColorMapButtonGroup = uibuttongroup(app.WaterFatTab);
            app.WaterColorMapButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @WaterColorMapButtonGroupSelectionChanged, true);
            app.WaterColorMapButtonGroup.Title = 'Color map';
            app.grayButton4 = uiradiobutton(app.WaterColorMapButtonGroup);
            app.grayButton4.Text = 'gray';
            app.grayButton4.Interpreter = 'html';
            app.grayButton4.Value = true;
            app.autumnButton4 = uiradiobutton(app.WaterColorMapButtonGroup);
            app.autumnButton4.Text = 'autumn';
            app.autumnButton4.Interpreter = 'html';
            app.winterButton4 = uiradiobutton(app.WaterColorMapButtonGroup);
            app.winterButton4.Text = 'winter';
            app.winterButton4.Interpreter = 'html';
            app.parulaButton4 = uiradiobutton(app.WaterColorMapButtonGroup);
            app.parulaButton4.Text = 'parula';
            app.parulaButton4.Interpreter = 'html';
            app.naviaButton4 = uiradiobutton(app.WaterColorMapButtonGroup);
            app.naviaButton4.Text = 'navia';
            app.naviaButton4.Interpreter = 'html';
            app.FatColorMapButtonGroup = uibuttongroup(app.WaterFatTab);
            app.FatColorMapButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FatColorMapButtonGroupSelectionChanged, true);
            app.FatColorMapButtonGroup.Title = 'Color map';
            app.grayButton5 = uiradiobutton(app.FatColorMapButtonGroup);
            app.grayButton5.Text = 'gray';
            app.grayButton5.Interpreter = 'html';
            app.grayButton5.Value = true;
            app.autumnButton5 = uiradiobutton(app.FatColorMapButtonGroup);
            app.autumnButton5.Text = 'autumn';
            app.autumnButton5.Interpreter = 'html';
            app.winterButton5 = uiradiobutton(app.FatColorMapButtonGroup);
            app.winterButton5.Text = 'winter';
            app.winterButton5.Interpreter = 'html';
            app.parulaButton5 = uiradiobutton(app.FatColorMapButtonGroup);
            app.parulaButton5.Text = 'parula';
            app.parulaButton5.Interpreter = 'html';
            app.naviaButton5 = uiradiobutton(app.FatColorMapButtonGroup);
            app.naviaButton5.Text = 'navia';
            app.naviaButton5.Interpreter = 'html';
            app.FatImage = uiimage(app.WaterFatTab);
            app.WaterImage = uiimage(app.WaterFatTab);
            app.WaterImage.ImageClickedFcn = createCallbackFcn(app, @T2ImageClicked, true);
            app.TabGroup2 = uitabgroup(app.T2mappUIFigure);
            app.RawImagesTab = uitab(app.TabGroup2);
            app.RawImagesTab.Title = 'Raw images';
            app.RawDataFig = uiaxes(app.RawImagesTab);
            app.RawDataFig.XTick = [];
            app.RawDataFig.YTick = [];
            app.RawImageMaskSettingsPanel = uipanel(app.RawImagesTab);
            app.RawImageMaskSettingsPanel.Title = 'Mask settings';
            app.ThresholdSlider = uislider(app.RawImageMaskSettingsPanel);
            app.ThresholdSlider.Limits = [0 16384];
            app.ThresholdSlider.MajorTicks = [];
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderValueChanged, true);
            app.ThresholdSlider.MinorTicks = [0 1024 2048 3072 4096 5120 6144 7168 8192 9216 10240 11264 12288 13312 14336 15360 16384];
            app.ThresholdSlider.Tooltip = {'Mask threshold value'};
            app.ErodeButton = uibutton(app.RawImageMaskSettingsPanel, 'push');
            app.ErodeButton.ButtonPushedFcn = createCallbackFcn(app, @ErodeButtonPushed, true);
            app.ErodeButton.Tooltip = {'Mask erosion'};
            app.ErodeButton.Text = 'Erode';
            app.DilateButton = uibutton(app.RawImageMaskSettingsPanel, 'push');
            app.DilateButton.ButtonPushedFcn = createCallbackFcn(app, @DilateButtonPushed, true);
            app.DilateButton.Tooltip = {'Mask dilation'};
            app.DilateButton.Text = 'Dilate';
            app.CloseButton = uibutton(app.RawImageMaskSettingsPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Tooltip = {'Mask closing'};
            app.CloseButton.Text = 'Close';
            app.OpenButton = uibutton(app.RawImageMaskSettingsPanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Tooltip = {'Mask opening'};
            app.OpenButton.Text = 'Open';
            app.ThresholdEditFieldLabel = uilabel(app.RawImageMaskSettingsPanel);
            app.ThresholdEditFieldLabel.Text = 'threshold';
            app.ThresholdEditField = uieditfield(app.RawImageMaskSettingsPanel, 'numeric');
            app.ThresholdEditField.Limits = [0 Inf];
            app.ThresholdEditField.ValueDisplayFormat = '%.0f';
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ThresholdEditField.Tooltip = {'Mask threshold value'};
            app.RawImageLabel = uilabel(app.RawImagesTab);
            app.RawImageLabel.Text = 'Image';
            app.RawImageSelectionPanel = uipanel(app.RawImagesTab);
            app.RawImageSelectionPanel.Title = 'Image selection';
            app.SlicesEditField = uieditfield(app.RawImageSelectionPanel, 'numeric');
            app.SlicesEditField.Limits = [1 Inf];
            app.SlicesEditField.ValueDisplayFormat = '%.0f';
            app.SlicesEditField.ValueChangedFcn = createCallbackFcn(app, @ImageSelectionEditFieldValueChanged, true);
            app.SlicesEditField.Tooltip = {'Slice'};
            app.SlicesEditField.Value = 1;
            app.EchoesEditField = uieditfield(app.RawImageSelectionPanel, 'numeric');
            app.EchoesEditField.Limits = [1 Inf];
            app.EchoesEditField.ValueDisplayFormat = '%.0f';
            app.EchoesEditField.ValueChangedFcn = createCallbackFcn(app, @ImageSelectionEditFieldValueChanged, true);
            app.EchoesEditField.Tooltip = {'Echo'};
            app.EchoesEditField.Value = 1;
            app.DynamicsEditField = uieditfield(app.RawImageSelectionPanel, 'numeric');
            app.DynamicsEditField.Limits = [1 Inf];
            app.DynamicsEditField.ValueDisplayFormat = '%.0f';
            app.DynamicsEditField.ValueChangedFcn = createCallbackFcn(app, @ImageSelectionEditFieldValueChanged, true);
            app.DynamicsEditField.Tooltip = {'Dynamic'};
            app.DynamicsEditField.Value = 1;
            app.SlicesSlider = uislider(app.RawImageSelectionPanel);
            app.SlicesSlider.Limits = [1 200];
            app.SlicesSlider.MajorTicks = [];
            app.SlicesSlider.ValueChangedFcn = createCallbackFcn(app, @ImageSelectionSliderValueChanged, true);
            app.SlicesSlider.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.SlicesSlider.Tooltip = {'Slice'};
            app.SlicesSlider.Value = 1;
            app.EchoesSlider = uislider(app.RawImageSelectionPanel);
            app.EchoesSlider.Limits = [1 200];
            app.EchoesSlider.MajorTicks = [];
            app.EchoesSlider.ValueChangedFcn = createCallbackFcn(app, @ImageSelectionSliderValueChanged, true);
            app.EchoesSlider.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.EchoesSlider.Tooltip = {'Echo'};
            app.EchoesSlider.Value = 1;
            app.DynamicsSlider = uislider(app.RawImageSelectionPanel);
            app.DynamicsSlider.Limits = [1 200];
            app.DynamicsSlider.MajorTicks = [];
            app.DynamicsSlider.ValueChangedFcn = createCallbackFcn(app, @ImageSelectionSliderValueChanged, true);
            app.DynamicsSlider.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.DynamicsSlider.Tooltip = {'Dynamic'};
            app.DynamicsSlider.Value = 1;
            app.SlicesEditFieldLabel = uilabel(app.RawImageSelectionPanel);
            app.SlicesEditFieldLabel.Text = 'slice';
            app.EchoesEditFieldLabel = uilabel(app.RawImageSelectionPanel);
            app.EchoesEditFieldLabel.Text = 'echo';
            app.DynamicsEditFieldLabel = uilabel(app.RawImageSelectionPanel);
            app.DynamicsEditFieldLabel.Text = 'dynamic';
            app.RawImage = uiimage(app.RawImagesTab);
            app.WindowSlider = uislider(app.RawImagesTab);
            app.WindowSlider.Limits = [0 32768];
            app.WindowSlider.MajorTicks = [];
            app.WindowSlider.ValueChangedFcn = createCallbackFcn(app, @WindowSliderValueChanged, true);
            app.WindowSlider.MinorTicks = [0 2048 4096 6144 8192 10240 12288 14336 16384 18432 20480 22528 24576 26624 28672 30720 32768];
            app.WindowSlider.Tooltip = {'Window'};
            app.WindowSlider.Value = 16384;
            app.LevelSlider = uislider(app.RawImagesTab);
            app.LevelSlider.Limits = [0 32768];
            app.LevelSlider.MajorTicks = [];
            app.LevelSlider.ValueChangedFcn = createCallbackFcn(app, @LevelSliderValueChanged, true);
            app.LevelSlider.MinorTicks = [0 2048 4096 6144 8192 10240 12288 14336 16384 18432 20480 22528 24576 26624 28672 30720 32768];
            app.LevelSlider.Tooltip = {'Level'};
            app.LevelSlider.Value = 16384;
            app.AutoLWButton = uibutton(app.RawImagesTab, 'state');
            app.AutoLWButton.ValueChangedFcn = createCallbackFcn(app, @AutoLWButtonValueChanged, true);
            app.AutoLWButton.Tooltip = {'Automatic window/level setting'};
            app.AutoLWButton.Text = 'Auto';
            app.AutoLWButton.Value = true;
            app.WindowEditFieldLabel = uilabel(app.RawImagesTab);
            app.WindowEditFieldLabel.Text = 'W';
            app.WindowEditField = uieditfield(app.RawImagesTab, 'numeric');
            app.WindowEditField.Limits = [0 Inf];
            app.WindowEditField.ValueDisplayFormat = '%.0f';
            app.WindowEditField.ValueChangedFcn = createCallbackFcn(app, @WindowEditFieldValueChanged, true);
            app.WindowEditField.Tooltip = {'Window'};
            app.LevelEditFieldLabel = uilabel(app.RawImagesTab);
            app.LevelEditFieldLabel.Text = 'L';
            app.LevelEditField = uieditfield(app.RawImagesTab, 'numeric');
            app.LevelEditField.Limits = [0 Inf];
            app.LevelEditField.ValueDisplayFormat = '%.0f';
            app.LevelEditField.ValueChangedFcn = createCallbackFcn(app, @LevelEditFieldValueChanged, true);
            app.LevelEditField.Tooltip = {'Level'};
            app.RecoParametersTab = uitab(app.TabGroup2);
            app.RecoParametersTab.Title = 'Reco parameters';
            app.FilteringPanel = uipanel(app.RecoParametersTab);
            app.FilteringPanel.Title = 'Filtering';
            app.DeNoiseCheckBoxLabel = uilabel(app.FilteringPanel);
            app.DeNoiseCheckBoxLabel.Text = 'PCA de-noise';
            app.DeNoiseCheckBox = uicheckbox(app.FilteringPanel);
            app.DeNoiseCheckBox.Tooltip = {'PCA denoising'};
            app.DeNoiseCheckBox.Enable = 'off';
            app.DeNoiseCheckBox.Text = '';
            app.DeNoiseWindowEditField = uieditfield(app.FilteringPanel, 'numeric');
            app.DeNoiseWindowEditField.Limits = [2 64];
            app.DeNoiseWindowEditField.RoundFractionalValues = 'on';
            app.DeNoiseWindowEditField.ValueDisplayFormat = '%.0f';
            app.DeNoiseWindowEditField.Enable = 'off';
            app.DeNoiseWindowEditField.Tooltip = {'PCA denoising window size (pixels)'};
            app.DeNoiseWindowEditField.Value = 8;
            app.DeRingCheckBoxLabel = uilabel(app.FilteringPanel);
            app.DeRingCheckBoxLabel.Text = 'de-ring';
            app.DeRingCheckBox = uicheckbox(app.FilteringPanel);
            app.DeRingCheckBox.Tooltip = {'De-ringing filter'};
            app.DeRingCheckBox.Enable = 'off';
            app.DeRingCheckBox.Text = '';
            app.CSregularizationPanel = uipanel(app.RecoParametersTab);
            app.CSregularizationPanel.Title = 'CS regularization';
            app.WVxyzEditField = uieditfield(app.CSregularizationPanel, 'numeric');
            app.WVxyzEditField.Limits = [0 20];
            app.WVxyzEditField.ValueDisplayFormat = '%.4f';
            app.WVxyzEditField.Enable = 'off';
            app.WVxyzEditField.Tooltip = {'Wavelet L1 regularization in spatial domain'};
            app.WVxyzEditField.Value = 0.001;
            app.TVxyzEditField = uieditfield(app.CSregularizationPanel, 'numeric');
            app.TVxyzEditField.Limits = [0 20];
            app.TVxyzEditField.ValueDisplayFormat = '%.4f';
            app.TVxyzEditField.Enable = 'off';
            app.TVxyzEditField.Tooltip = {'Total Variation regularization in spatial domain'};
            app.TVxyzEditField.Value = 0.001;
            app.LLRxyzEditField = uieditfield(app.CSregularizationPanel, 'numeric');
            app.LLRxyzEditField.Limits = [0 20];
            app.LLRxyzEditField.ValueDisplayFormat = '%.4f';
            app.LLRxyzEditField.Enable = 'off';
            app.LLRxyzEditField.Tooltip = {'Locally Low-Rank in spatial domain'};
            app.LLRxyzEditField.Value = 0.001;
            app.TVteEditField = uieditfield(app.CSregularizationPanel, 'numeric');
            app.TVteEditField.Limits = [0 20];
            app.TVteEditField.ValueDisplayFormat = '%.4f';
            app.TVteEditField.Enable = 'off';
            app.TVteEditField.Tooltip = {'Total Variation regularization in TE dimension'};
            app.TVdynEditField = uieditfield(app.CSregularizationPanel, 'numeric');
            app.TVdynEditField.Limits = [0 20];
            app.TVdynEditField.ValueDisplayFormat = '%.4f';
            app.TVdynEditField.Enable = 'off';
            app.TVdynEditField.Tooltip = {'Total Variation regularization in dynamic dimension'};
            app.WVxyzEditFieldLabel = uilabel(app.CSregularizationPanel);
            app.WVxyzEditFieldLabel.Tooltip = {'Wavelet L1-norm regularization in the spatial dimensions'};
            app.WVxyzEditFieldLabel.Text = 'WVxyz';
            app.TVxyzEditFieldLabel = uilabel(app.CSregularizationPanel);
            app.TVxyzEditFieldLabel.Tooltip = {'Total-variation regularization in the spatial dimensions'};
            app.TVxyzEditFieldLabel.Text = 'TVxyz';
            app.LLRxyzEditFieldLabel = uilabel(app.CSregularizationPanel);
            app.LLRxyzEditFieldLabel.Tooltip = {'Locally Low-Rank in spatial domain'};
            app.LLRxyzEditFieldLabel.Text = 'LLRxyz';
            app.TVteEditFieldLabel = uilabel(app.CSregularizationPanel);
            app.TVteEditFieldLabel.Tooltip = {'Total Variation regularization in TE dimension'};
            app.TVteEditFieldLabel.Text = 'TVte';
            app.TVdynEditFieldLabel = uilabel(app.CSregularizationPanel);
            app.TVdynEditFieldLabel.Tooltip = {'Total-variation regularization in the dynamic dimension'};
            app.TVdynEditFieldLabel.Text = 'TVdyn';
            app.FitparametersTab = uitab(app.TabGroup2);
            app.FitparametersTab.Title = 'Fit parameters';
            app.EchoSelectionPanel = uipanel(app.FitparametersTab);
            app.EchoSelectionPanel.Tooltip = {'Echo selection for fitting'};
            app.EchoSelectionPanel.Title = 'Echo selection';
            app.CheckBox_1 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_1.Text = '1';
            app.CheckBox_2 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_2.Text = '2';
            app.CheckBox_2.Value = true;
            app.CheckBox_3 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_3.Text = '3';
            app.CheckBox_3.Value = true;
            app.CheckBox_4 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_4.Text = '4';
            app.CheckBox_4.Value = true;
            app.CheckBox_5 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_5.Text = '5';
            app.CheckBox_5.Value = true;
            app.CheckBox_6 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_6.Text = '6';
            app.CheckBox_6.Value = true;
            app.CheckBox_7 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_7.Text = '7';
            app.CheckBox_7.Value = true;
            app.CheckBox_8 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_8.Text = '8';
            app.CheckBox_8.Value = true;
            app.CheckBox_9 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_9.Text = '9';
            app.CheckBox_9.Value = true;
            app.CheckBox_10 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_10.Text = '10';
            app.CheckBox_10.Value = true;
            app.CheckBox_11 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_11.Text = '11';
            app.CheckBox_11.Value = true;
            app.CheckBox_12 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_12.Text = '12';
            app.CheckBox_12.Value = true;
            app.CheckBox_13 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_13.Text = '13';
            app.CheckBox_13.Value = true;
            app.CheckBox_14 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_14.Text = '14';
            app.CheckBox_14.Value = true;
            app.CheckBox_15 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_15.Text = '15';
            app.CheckBox_15.Value = true;
            app.CheckBox_16 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_16.Text = '16';
            app.CheckBox_16.Value = true;
            app.CheckBox_17 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_17.Text = '17';
            app.CheckBox_17.Value = true;
            app.CheckBox_18 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_18.Text = '18';
            app.CheckBox_18.Value = true;
            app.CheckBox_19 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_19.Text = '19';
            app.CheckBox_19.Value = true;
            app.CheckBox_20 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_20.Text = '20';
            app.CheckBox_20.Value = true;
            app.CheckBox_21 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_21.Text = '21';
            app.CheckBox_21.Value = true;
            app.CheckBox_22 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_22.Text = '22';
            app.CheckBox_22.Value = true;
            app.CheckBox_23 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_23.Text = '23';
            app.CheckBox_23.Value = true;
            app.CheckBox_24 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_24.Text = '24';
            app.CheckBox_24.Value = true;
            app.CheckBox_25 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_25.Text = '25';
            app.CheckBox_25.Value = true;
            app.CheckBox_26 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_26.Text = '26';
            app.CheckBox_26.Value = true;
            app.CheckBox_27 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_27.Text = '27';
            app.CheckBox_27.Value = true;
            app.CheckBox_28 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_28.Text = '28';
            app.CheckBox_28.Value = true;
            app.CheckBox_29 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_29.Text = '29';
            app.CheckBox_29.Value = true;
            app.CheckBox_30 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_30.Text = '30';
            app.CheckBox_30.Value = true;
            app.CheckBox_31 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_31.Text = '31';
            app.CheckBox_31.Value = true;
            app.CheckBox_32 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_32.Text = '32';
            app.CheckBox_32.Value = true;
            app.CheckBox_33 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_33.Text = '33';
            app.CheckBox_33.Value = true;
            app.CheckBox_34 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_34.Text = '34';
            app.CheckBox_34.Value = true;
            app.CheckBox_35 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_35.Text = '35';
            app.CheckBox_35.Value = true;
            app.CheckBox_36 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_36.Text = '36';
            app.CheckBox_36.Value = true;
            app.CheckBox_37 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_37.Text = '37';
            app.CheckBox_37.Value = true;
            app.CheckBox_38 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_38.Text = '38';
            app.CheckBox_38.Value = true;
            app.CheckBox_39 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_39.Text = '39';
            app.CheckBox_39.Value = true;
            app.CheckBox_40 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_40.Text = '40';
            app.CheckBox_40.Value = true;
            app.CheckBox_41 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_41.Text = '41';
            app.CheckBox_41.Value = true;
            app.CheckBox_42 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_42.Text = '42';
            app.CheckBox_42.Value = true;
            app.CheckBox_43 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_43.Text = '43';
            app.CheckBox_43.Value = true;
            app.CheckBox_44 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_44.Text = '44';
            app.CheckBox_44.Value = true;
            app.CheckBox_45 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_45.Text = '45';
            app.CheckBox_45.Value = true;
            app.CheckBox_46 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_46.Text = '46';
            app.CheckBox_46.Value = true;
            app.CheckBox_47 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_47.Text = '47';
            app.CheckBox_47.Value = true;
            app.CheckBox_48 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_48.Text = '48';
            app.CheckBox_48.Value = true;
            app.CheckBox_49 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_49.Text = '49';
            app.CheckBox_49.Value = true;
            app.CheckBox_50 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_50.Text = '50';
            app.CheckBox_50.Value = true;
            app.CheckBox_51 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_51.Text = '51';
            app.CheckBox_51.Value = true;
            app.CheckBox_52 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_52.Text = '52';
            app.CheckBox_52.Value = true;
            app.CheckBox_53 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_53.Text = '53';
            app.CheckBox_53.Value = true;
            app.CheckBox_54 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_54.Text = '54';
            app.CheckBox_54.Value = true;
            app.CheckBox_55 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_55.Text = '55';
            app.CheckBox_55.Value = true;
            app.CheckBox_56 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_56.Text = '56';
            app.CheckBox_56.Value = true;
            app.CheckBox_57 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_57.Text = '57';
            app.CheckBox_57.Value = true;
            app.CheckBox_58 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_58.Text = '58';
            app.CheckBox_58.Value = true;
            app.CheckBox_59 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_59.Text = '59';
            app.CheckBox_59.Value = true;
            app.CheckBox_60 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_60.Text = '60';
            app.CheckBox_60.Value = true;
            app.CheckBox_61 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_61.Text = '61';
            app.CheckBox_61.Value = true;
            app.CheckBox_62 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_62.Text = '62';
            app.CheckBox_62.Value = true;
            app.CheckBox_63 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_63.Text = '63';
            app.CheckBox_63.Value = true;
            app.CheckBox_64 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_64.Text = '64';
            app.CheckBox_64.Value = true;
            app.SliceSelectionPanel = uipanel(app.FitparametersTab);
            app.SliceSelectionPanel.Title = 'Slice selection';
            app.FirstSliceEditFieldLabel = uilabel(app.SliceSelectionPanel);
            app.FirstSliceEditFieldLabel.Text = 'first slice';
            app.FirstSliceEditField = uieditfield(app.SliceSelectionPanel, 'numeric');
            app.FirstSliceEditField.Limits = [1 200];
            app.FirstSliceEditField.ValueChangedFcn = createCallbackFcn(app, @SliceRangeEditFieldValueChanged, true);
            app.FirstSliceEditField.Enable = 'off';
            app.FirstSliceEditField.Tooltip = {'First slice for fitting'};
            app.FirstSliceEditField.Value = 1;
            app.LastSliceEditFieldLabel = uilabel(app.SliceSelectionPanel);
            app.LastSliceEditFieldLabel.Text = 'last slice';
            app.LastSliceEditField = uieditfield(app.SliceSelectionPanel, 'numeric');
            app.LastSliceEditField.Limits = [1 200];
            app.LastSliceEditField.ValueChangedFcn = createCallbackFcn(app, @SliceRangeEditFieldValueChanged, true);
            app.LastSliceEditField.Enable = 'off';
            app.LastSliceEditField.Tooltip = {'Last slice for fitting'};
            app.LastSliceEditField.Value = 1;
            app.GoodnessOfFitPanel = uipanel(app.FitparametersTab);
            app.GoodnessOfFitPanel.Title = 'Goodness of fit';
            app.RsquareEditFieldLabel = uilabel(app.GoodnessOfFitPanel);
            app.RsquareEditFieldLabel.Text = 'min R-square';
            app.RsquareEditField = uieditfield(app.GoodnessOfFitPanel, 'numeric');
            app.RsquareEditField.Limits = [0 1];
            app.RsquareEditField.ValueDisplayFormat = '%.2f';
            app.RsquareEditField.Enable = 'off';
            app.RsquareEditField.Tooltip = {'R-square cut-off value'};
            app.RsquareEditField.Value = 0.8;
            app.DynamicsSelectionPanel = uipanel(app.FitparametersTab);
            app.DynamicsSelectionPanel.Title = 'Dynamics selection';
            app.FirstDynamicEditFieldLabel = uilabel(app.DynamicsSelectionPanel);
            app.FirstDynamicEditFieldLabel.Text = 'first dynamic';
            app.FirstDynamicEditField = uieditfield(app.DynamicsSelectionPanel, 'numeric');
            app.FirstDynamicEditField.Limits = [1 500];
            app.FirstDynamicEditField.ValueDisplayFormat = '%.0f';
            app.FirstDynamicEditField.ValueChangedFcn = createCallbackFcn(app, @DynamicRangeEditFieldValueChanged, true);
            app.FirstDynamicEditField.Enable = 'off';
            app.FirstDynamicEditField.Tooltip = {'First dynamic for fitting'};
            app.FirstDynamicEditField.Value = 1;
            app.LastDynamicEditFieldLabel = uilabel(app.DynamicsSelectionPanel);
            app.LastDynamicEditFieldLabel.Text = 'last dynamic';
            app.LastDynamicEditField = uieditfield(app.DynamicsSelectionPanel, 'numeric');
            app.LastDynamicEditField.Limits = [1 500];
            app.LastDynamicEditField.ValueDisplayFormat = '%.0f';
            app.LastDynamicEditField.ValueChangedFcn = createCallbackFcn(app, @DynamicRangeEditFieldValueChanged, true);
            app.LastDynamicEditField.Enable = 'off';
            app.LastDynamicEditField.Tooltip = {'Last dynamic for fitting'};
            app.LastDynamicEditField.Value = 1;
            app.AppSettingsTab = uitab(app.TabGroup2);
            app.AppSettingsTab.Title = 'App settings';
            app.SoundsPanel = uipanel(app.AppSettingsTab);
            app.SoundsPanel.Title = 'Sounds';
            app.AutoSoundsCheckBox = uicheckbox(app.SoundsPanel);
            app.AutoSoundsCheckBox.Tooltip = {'Warning and error sounds on or off'};
            app.AutoSoundsCheckBox.Text = '';
            app.AutoSoundsCheckBox.Value = true;
            app.AutoSoundsCheckBoxLabel = uilabel(app.SoundsPanel);
            app.AutoSoundsCheckBoxLabel.Tooltip = {'Warning and error sounds on or off'};
            app.AutoSoundsCheckBoxLabel.Text = 'sounds on/off';
            app.ColorsPanel = uipanel(app.AppSettingsTab);
            app.ColorsPanel.Title = 'Colors';
            app.ColorsButtonGroup = uibuttongroup(app.ColorsPanel);
            app.ColorsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ColorsButtonGroupSelectionChanged, true);
            app.ColorsButtonGroup.Tooltip = {'App color scheme selection'};
            app.ClassicButton = uiradiobutton(app.ColorsButtonGroup);
            app.ClassicButton.Text = 'classic';
            app.ClassicButton.Interpreter = 'html';
            app.BlackWhiteButton = uiradiobutton(app.ColorsButtonGroup);
            app.BlackWhiteButton.Text = 'black/white';
            app.BlackWhiteButton.Interpreter = 'html';
            app.BlackWhiteButton.Value = true;
            app.TabGroup4 = uitabgroup(app.T2mappUIFigure);
            app.SequenceParametersTab = uitab(app.TabGroup4);
            app.SequenceParametersTab.Title = 'Sequence parameters';
            app.DataFileViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.DataFileViewFieldLabel.Text = 'datafile';
            app.DataFileViewField = uieditfield(app.SequenceParametersTab, 'text');
            app.DataFileViewField.Editable = 'off';
            app.DataFileViewField.Tooltip = {'Data file name'};
            app.TRViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.TRViewFieldLabel.Text = 'TR (ms)';
            app.TRViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.TRViewField.Limits = [0 Inf];
            app.TRViewField.Editable = 'off';
            app.TRViewField.Tooltip = {'Repetition time'};
            app.TE0EditFieldLabel = uilabel(app.SequenceParametersTab);
            app.TE0EditFieldLabel.Text = 'TE0 (ms)';
            app.TE0EditField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.TE0EditField.Limits = [0 Inf];
            app.TE0EditField.ValueChangedFcn = createCallbackFcn(app, @TEViewFieldValueChanged, true);
            app.TE0EditField.Tooltip = {'First echo time'};
            app.ESPEditFieldLabel = uilabel(app.SequenceParametersTab);
            app.ESPEditFieldLabel.Text = 'ESP (ms)';
            app.ESPEditField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.ESPEditField.Limits = [0 Inf];
            app.ESPEditField.ValueChangedFcn = createCallbackFcn(app, @TEViewFieldValueChanged, true);
            app.ESPEditField.Tooltip = {'Echo spacing'};
            app.EchoesViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.EchoesViewFieldLabel.Text = '# echoes';
            app.EchoesViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.EchoesViewField.Limits = [0 Inf];
            app.EchoesViewField.Editable = 'off';
            app.EchoesViewField.Tooltip = {'Number of echoes'};
            app.DynamicsViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.DynamicsViewFieldLabel.Text = '# dynamics';
            app.DynamicsViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.DynamicsViewField.Limits = [0 Inf];
            app.DynamicsViewField.Editable = 'off';
            app.DynamicsViewField.Tooltip = {'Numer of dynamics/repetitions'};
            app.AspectRatioViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.AspectRatioViewFieldLabel.Text = 'aspect ratio';
            app.AspectRatioViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.AspectRatioViewField.Limits = [0.25 4];
            app.AspectRatioViewField.ValueDisplayFormat = '%.2f';
            app.AspectRatioViewField.Editable = 'off';
            app.AspectRatioViewField.Tooltip = {'Image aspect ratio'};
            app.AspectRatioViewField.Value = 1;
            app.FOVViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.FOVViewFieldLabel.Text = 'FOV (mm)';
            app.FOVViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.FOVViewField.Limits = [0 Inf];
            app.FOVViewField.Editable = 'off';
            app.FOVViewField.Tooltip = {'Field of view'};
            app.DimxViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.DimxViewFieldLabel.Text = 'dimx';
            app.DimxViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.DimxViewField.Limits = [0 Inf];
            app.DimxViewField.ValueDisplayFormat = '%.0f';
            app.DimxViewField.Editable = 'off';
            app.DimxViewField.Tooltip = {'Readout matrix size'};
            app.DimyViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.DimyViewFieldLabel.Text = 'dimy';
            app.DimyViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.DimyViewField.Limits = [0 Inf];
            app.DimyViewField.ValueDisplayFormat = '%.0f';
            app.DimyViewField.Editable = 'off';
            app.DimyViewField.Tooltip = {'Phase-encoding matrix size'};
            app.SlicesViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.SlicesViewFieldLabel.Text = '# slices';
            app.SlicesViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.SlicesViewField.Limits = [0 Inf];
            app.SlicesViewField.ValueDisplayFormat = '%.0f';
            app.SlicesViewField.Editable = 'off';
            app.SlicesViewField.Tooltip = {'Numer of slices / 2nd phase-encoding matrix size'};
            app.SLTViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.SLTViewFieldLabel.Text = 'slice thickness (mm)';
            app.SLTViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.SLTViewField.Limits = [0 Inf];
            app.SLTViewField.Editable = 'off';
            app.SLTViewField.Tooltip = {'Slice thickness'};
            app.Hyperlink = uihyperlink(app.T2mappUIFigure);
            app.Hyperlink.URL = 'https://github.com/Moby1971/';
            app.Hyperlink.Text = 'Github';
            % Show the figure after all components are created
            app.T2mappUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = T2mapp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.T2mappUIFigure)

                % Execute the startup function
                runStartupFcn(app, @StartupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.T2mappUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.T2mappUIFigure)
        end
    end
end